classdef ViraApp_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViraEPCUIFigure                 matlab.ui.Figure
        ViraEPCLoginPanel               matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Button_5                        matlab.ui.control.Button
        NewPassLabel                    matlab.ui.control.Label
        PasswordLabel                   matlab.ui.control.Label
        NewPassEditField                matlab.ui.control.EditField
        NoticeLabel                     matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        VisibleCheckBox                 matlab.ui.control.CheckBox
        PasswordEditField               matlab.ui.control.EditField
        UsernameEditField_2             matlab.ui.control.EditField
        UsernameEditField_2Label        matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        Switch                          matlab.ui.control.Switch
        UserLabel                       matlab.ui.control.Label
        VIRAEPCLabel                    matlab.ui.control.Label
        GridLayout18                    matlab.ui.container.GridLayout
        ChTimeLabel                     matlab.ui.control.Label
        DTimeLabel                      matlab.ui.control.Label
        ITimeLabel                      matlab.ui.control.Label
        BTimeLabel                      matlab.ui.control.Label
        LTimeLabel                      matlab.ui.control.Label
        NYTimeLabel                     matlab.ui.control.Label
        CATimeLabel                     matlab.ui.control.Label
        CaliforniaLabel                 matlab.ui.control.Label
        BeijingLabel                    matlab.ui.control.Label
        DubaiLabel                      matlab.ui.control.Label
        IstanbulLabel                   matlab.ui.control.Label
        BerlinLabel                     matlab.ui.control.Label
        LondonLabel                     matlab.ui.control.Label
        NewYorkLabel                    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        WelcomeLabel                    matlab.ui.control.Label
        Entry_InquiryTab                matlab.ui.container.Tab
        InqGrid                         matlab.ui.container.GridLayout
        AddLineButton_2                 matlab.ui.control.Button
        GridLayout19                    matlab.ui.container.GridLayout
        DesEditField                    matlab.ui.control.EditField
        DesEditFieldLabel               matlab.ui.control.Label
        BrandEditField                  matlab.ui.control.EditField
        BrandEditFieldLabel             matlab.ui.control.Label
        QTYEditField_2                  matlab.ui.control.NumericEditField
        QTYEditField_2Label             matlab.ui.control.Label
        PNEditField                     matlab.ui.control.EditField
        PNEditFieldLabel                matlab.ui.control.Label
        CInqID                          matlab.ui.control.DropDown
        AutoGegerateCheckBox            matlab.ui.control.CheckBox
        OpenInqItemsButton              matlab.ui.control.Button
        InqID_DD                        matlab.ui.control.DropDown
        OpenAttachmentButton            matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ShowAllButton                   matlab.ui.control.Button
        NameTagEditField                matlab.ui.control.EditField
        NameTagEditFieldLabel           matlab.ui.control.Label
        InqItemTable                    matlab.ui.control.Table
        PleaseCheckthePreviewCheckBox   matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        AttachButtonInq                 matlab.ui.control.Button
        AttachmentEditField_Inq         matlab.ui.control.EditField
        AttachmentEditField_5Label      matlab.ui.control.Label
        ReadInquiryItemfileButton       matlab.ui.control.Button
        Date_Inq                        matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        CustomerDropDown_Inq            matlab.ui.control.DropDown
        CustomerDropDownLabel           matlab.ui.control.Label
        InquiryTable                    matlab.ui.control.Table
        Notif_Inq                       matlab.ui.control.EditField
        InquiryItemFilePath             matlab.ui.control.EditField
        Issue_PITab                     matlab.ui.container.Tab
        GridOpenInq                     matlab.ui.container.GridLayout
        ZeroLinesCheckBox               matlab.ui.control.CheckBox
        QTYEditField                    matlab.ui.control.NumericEditField
        QTYEditFieldLabel               matlab.ui.control.Label
        AddLineButton                   matlab.ui.control.Button
        ValueEditField                  matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
        LineNameEditField               matlab.ui.control.EditField
        LineNameEditFieldLabel          matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        InqID_DDInq                     matlab.ui.control.DropDown
        OpenInqItemsButton_2            matlab.ui.control.Button
        OpenAttachmentButton_2          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ShowallButton                   matlab.ui.control.Button
        ValidityDatePicker              matlab.ui.control.DatePicker
        ValidityDatePickerLabel         matlab.ui.control.Label
        ReCalcTotalButton               matlab.ui.control.Button
        OpenInqCurrncyDropDown          matlab.ui.control.DropDown
        CurrencyDropDownLabel           matlab.ui.control.Label
        OpenInqTotal                    matlab.ui.control.NumericEditField
        TotalEditFieldLabel             matlab.ui.control.Label
        IncotermsEditField              matlab.ui.control.EditField
        IncotermsEditFieldLabel         matlab.ui.control.Label
        ImportPQlistButton              matlab.ui.control.Button
        OpenInqIDDropDown               matlab.ui.control.DropDown
        OpenInqIDDropDownLabel          matlab.ui.control.Label
        LeadtimeWeeksEditField          matlab.ui.control.NumericEditField
        LeadtimeWeeksEditFieldLabel     matlab.ui.control.Label
        IssuePIButton                   matlab.ui.control.Button
        PaymenttermsDropDown            matlab.ui.control.DropDown
        PaymenttermsDropDownLabel       matlab.ui.control.Label
        CompanyDropDown                 matlab.ui.control.DropDown
        CompanyDropDownLabel            matlab.ui.control.Label
        IssueDateDatePicker             matlab.ui.control.DatePicker
        IssueDateDatePickerLabel        matlab.ui.control.Label
        OpenInq_PQTable                 matlab.ui.control.Table
        CloseButton                     matlab.ui.control.Button
        Notif_OpenInq                   matlab.ui.control.EditField
        OpenInqTable                    matlab.ui.control.Table
        OpenInquiryQTY                  matlab.ui.control.Table
        CargoTab                        matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        ViraPurchaseTab                 matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        UploadBSButton                  matlab.ui.control.Button
        BankSlipLabel                   matlab.ui.control.Label
        PaymentDateDatePicker           matlab.ui.control.DatePicker
        PaymentDateDatePickerLabel      matlab.ui.control.Label
        PurchaseUploadSupplierPILabel   matlab.ui.control.Label
        SelecttheSourceDropDown         matlab.ui.control.DropDown
        SelecttheSourceDropDownLabel    matlab.ui.control.Label
        ViraPurchaseUITable             matlab.ui.control.Table
        UploadButton                    matlab.ui.control.Button
        DeterminethePIIDDropDown        matlab.ui.control.DropDown
        SelecttheInqIDLabel             matlab.ui.control.Label
        OverviewViraPurchaseUITable     matlab.ui.control.Table
        UpdateandPackingTab             matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        SupplierPLLabel                 matlab.ui.control.Label
        PackingListLabel                matlab.ui.control.Label
        Extract_PLButton                matlab.ui.control.Button
        WarehouseDataLabel              matlab.ui.control.Label
        AddWdataButton                  matlab.ui.control.Button
        PackingListUITable              matlab.ui.control.Table
        Upload_SPLButton                matlab.ui.control.Button
        TrackingNumberEditField         matlab.ui.control.EditField
        TrackingNumberEditFieldLabel    matlab.ui.control.Label
        ArrivedDateDatePicker           matlab.ui.control.DatePicker
        ArrivedDateDatePickerLabel      matlab.ui.control.Label
        UpdateSelecttheSourceDropDown   matlab.ui.control.DropDown
        SelecttheSourceDropDown_2Label  matlab.ui.control.Label
        UpdateSelectthePIIDDropDown     matlab.ui.control.DropDown
        SelectthePIIDLabel              matlab.ui.control.Label
        DetailPackingUITable            matlab.ui.control.Table
        Issue_CITab                     matlab.ui.container.Tab
        IssueInvoice                    matlab.ui.container.GridLayout
        AddLineButton_3                 matlab.ui.control.Button
        GridLayout20                    matlab.ui.container.GridLayout
        QTYEditField_3                  matlab.ui.control.NumericEditField
        QTYEditFieldLabel_2             matlab.ui.control.Label
        ValueEditField_2                matlab.ui.control.NumericEditField
        ValueEditFieldLabel_2           matlab.ui.control.Label
        LineNameEditField_2             matlab.ui.control.EditField
        LineNameEditFieldLabel_2        matlab.ui.control.Label
        NewInLine                       matlab.ui.control.Table
        TotalApprovedPILabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PITableLabel                    matlab.ui.control.Label
        NoteEditField                   matlab.ui.control.EditField
        NoteLabel                       matlab.ui.control.Label
        OpenAppPI_2                     matlab.ui.control.Button
        IssueInvoceButton               matlab.ui.control.Button
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel_2           matlab.ui.control.Label
        InItemTable                     matlab.ui.control.Table
        DelalltoI                       matlab.ui.control.Button
        DelonetoI                       matlab.ui.control.Button
        ResetInitem                     matlab.ui.control.Button
        addalltoI                       matlab.ui.control.Button
        addonetoI                       matlab.ui.control.Button
        PIItemTable                     matlab.ui.control.Table
        PIIDDD                          matlab.ui.control.DropDown
        AppPIExcel                      matlab.ui.control.Button
        OpenAppPI                       matlab.ui.control.Button
        PIID2DD                         matlab.ui.control.DropDown
        IssueInvoiceNotif               matlab.ui.control.EditField
        AppPITable                      matlab.ui.control.Table
        PIIDfix                         matlab.ui.control.NumericEditField
        UpdateTab                       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        UpdateSubTabs                   matlab.ui.container.TabGroup
        Update_Open_PITab               matlab.ui.container.Tab
        OpenPIGrid                      matlab.ui.container.GridLayout
        Button_6                        matlab.ui.control.Button
        OpenPIItemButton                matlab.ui.control.Button
        OpenPIButton_3                  matlab.ui.control.Button
        IDOpenPIDD                      matlab.ui.control.DropDown
        ResetButton_3                   matlab.ui.control.Button
        OpenPIShowallButton             matlab.ui.control.Button
        AttachPOButton                  matlab.ui.control.Button
        ApprovedDateDatePicker          matlab.ui.control.DatePicker
        ApprovedDateDatePickerLabel     matlab.ui.control.Label
        ApprovedByCustomerButton        matlab.ui.control.Button
        SelectPIIDDropDown              matlab.ui.control.DropDown
        SelectPIIDDropDownLabel         matlab.ui.control.Label
        OpenPICloseButton               matlab.ui.control.Button
        OpenPINotif                     matlab.ui.control.EditField
        OpenPITable                     matlab.ui.control.Table
        ApPath                          matlab.ui.control.EditField
        Update_Open_CITab               matlab.ui.container.Tab
        OpenCIGrid                      matlab.ui.container.GridLayout
        OpenPICIButton                  matlab.ui.control.Button
        OCIExcel                        matlab.ui.control.Button
        OpenCIButton_3                  matlab.ui.control.Button
        IDOpenCIDD                      matlab.ui.control.DropDown
        AttachCIButton                  matlab.ui.control.Button
        ApprovedCIDatePicker            matlab.ui.control.DatePicker
        ApprovedDateDatePicker_2Label   matlab.ui.control.Label
        ApprovedCIByCustomerButton      matlab.ui.control.Button
        SelectCIIDDropDown              matlab.ui.control.DropDown
        SelectCIIDDropDownLabel         matlab.ui.control.Label
        OpenCINotif                     matlab.ui.control.EditField
        OpenCITable                     matlab.ui.control.Table
        ApCIPath                        matlab.ui.control.EditField
        ReportTab                       matlab.ui.container.Tab
        ReportGrid                      matlab.ui.container.GridLayout
        ReportT                         matlab.ui.control.Table
        TotalCIValueLabel               matlab.ui.control.Label
        OpenCIButton_2                  matlab.ui.control.Button
        CuDDRe                          matlab.ui.control.DropDown
        ReportTablebasedonInquiryLabel  matlab.ui.control.Label
        TotalPIValueLabel               matlab.ui.control.Label
        SelectStatesLabel               matlab.ui.control.Label
        DeliveredButton                 matlab.ui.control.StateButton
        PartialDeliveredButton          matlab.ui.control.StateButton
        PartialCIIssuedButton           matlab.ui.control.StateButton
        CIIssuedButton                  matlab.ui.control.StateButton
        PIApprovedButton                matlab.ui.control.StateButton
        PIIssuedButton                  matlab.ui.control.StateButton
        PIDeclinedButton                matlab.ui.control.StateButton
        OpenButton                      matlab.ui.control.StateButton
        OpenPODButton                   matlab.ui.control.Button
        OpenPIButton_2                  matlab.ui.control.Button
        OpenPOButton                    matlab.ui.control.Button
        FilterReport                    matlab.ui.control.Button
        CompaniesLabel                  matlab.ui.control.Label
        OpenInqItemsButton_3            matlab.ui.control.Button
        InqIDDDReport                   matlab.ui.control.DropDown
        Excel_Report                    matlab.ui.control.Button
        ShowAllReport                   matlab.ui.control.Button
        Notif_Report                    matlab.ui.control.EditField
        Delete_InquiryTab               matlab.ui.container.Tab
        DeleteINq                       matlab.ui.container.GridLayout
        IDDel                           matlab.ui.control.EditField
        IDEditFieldLabel                matlab.ui.control.Label
        NameTagDel                      matlab.ui.control.EditField
        NameTagEditField_2Label         matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        InquiryTable_2                  matlab.ui.control.Table
        NotifDel                        matlab.ui.control.EditField
        FinancialTab                    matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        Financial_PaymentTab            matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        CustomerPaymentTableLabel       matlab.ui.control.Label
        Notif_Fin                       matlab.ui.control.EditField
        AddButton                       matlab.ui.control.Button
        AttachButtonFin                 matlab.ui.control.Button
        Excel_Fin                       matlab.ui.control.Button
        NoteFin                         matlab.ui.control.EditField
        NoteEditField_2Label            matlab.ui.control.Label
        ValueFin                        matlab.ui.control.NumericEditField
        ValueEditField_2Label           matlab.ui.control.Label
        DateFin                         matlab.ui.control.DatePicker
        DateDatePicker_2Label           matlab.ui.control.Label
        CurrencyDD                      matlab.ui.control.DropDown
        CurrencyDropDownLabel_2         matlab.ui.control.Label
        CustomerDD                      matlab.ui.control.DropDown
        CustomerDropDownLabel_2         matlab.ui.control.Label
        AttachFin                       matlab.ui.control.EditField
        PaymentT                        matlab.ui.control.Table
        Financial_ReportTab             matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        Excel_FinR_2                    matlab.ui.control.Button
        EuroLabel                       matlab.ui.control.Label
        EuroT                           matlab.ui.control.Table
        FilterButton                    matlab.ui.control.Button
        Excel_FinR                      matlab.ui.control.Button
        USDLabel                        matlab.ui.control.Label
        USDT                            matlab.ui.control.Table
        CustomerDDFinR                  matlab.ui.control.DropDown
        CustomerDropDownLabel_3         matlab.ui.control.Label
        Notif_FinR                      matlab.ui.control.EditField
        SetupTab                        matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        Setup_CustomerTab               matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SelectFileButton                matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        AddnewCustomerButton            matlab.ui.control.Button
        AttachmentEditFieldLabel        matlab.ui.control.Label
        AttachmentEditField             matlab.ui.control.EditField
        CorresponderCellPhoneEditField  matlab.ui.control.EditField
        CorresponderCellPhoneEditFieldLabel  matlab.ui.control.Label
        CorresponderEditField           matlab.ui.control.EditField
        CorresponderEditFieldLabel      matlab.ui.control.Label
        AcManagerDropDown               matlab.ui.control.DropDown
        AcManagerDropDownLabel          matlab.ui.control.Label
        TelEditField                    matlab.ui.control.EditField
        TelEditFieldLabel               matlab.ui.control.Label
        WebsiteEditField                matlab.ui.control.EditField
        WebsiteEditFieldLabel           matlab.ui.control.Label
        AddressEditField                matlab.ui.control.EditField
        AddressEditFieldLabel           matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Setup_OperatorTab               matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        ChangePasswordButton            matlab.ui.control.Button
        NewPasswordEditField            matlab.ui.control.EditField
        NewPasswordEditFieldLabel       matlab.ui.control.Label
        UserDropDown                    matlab.ui.control.DropDown
        UserDropDownLabel               matlab.ui.control.Label
        UserdefaultpasswordusernameLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        AddnewOperatorButton            matlab.ui.control.Button
        CellPhoneEditField              matlab.ui.control.EditField
        CellPhoneEditFieldLabel         matlab.ui.control.Label
        NameEditField_2                 matlab.ui.control.EditField
        NameEditField_2Label            matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        Setup_CompanyTab                matlab.ui.container.Tab
        GridLayout4_3                   matlab.ui.container.GridLayout
        SelectFileButton_2              matlab.ui.control.Button
        WebsiteEditField_2              matlab.ui.control.EditField
        WebsiteEditField_2Label         matlab.ui.control.Label
        TelEditField_2                  matlab.ui.control.EditField
        TelEditField_2Label             matlab.ui.control.Label
        AddressEditField_2              matlab.ui.control.EditField
        AddressEditField_2Label         matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        AddnewCompanyButton             matlab.ui.control.Button
        AttachmentEditField_2Label      matlab.ui.control.Label
        AttachmentEditField_2           matlab.ui.control.EditField
        NameEditField_3                 matlab.ui.control.EditField
        NameEditField_3Label            matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        Setup_SupplierTab               matlab.ui.container.Tab
        GridLayout4_4                   matlab.ui.container.GridLayout
        SelectFileButton_3              matlab.ui.control.Button
        WebsiteEditField_3              matlab.ui.control.EditField
        WebsiteEditField_3Label         matlab.ui.control.Label
        TelEditField_3                  matlab.ui.control.EditField
        TelEditField_3Label             matlab.ui.control.Label
        AddressEditField_3              matlab.ui.control.EditField
        AddressEditField_3Label         matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        AddnewSupplierButton            matlab.ui.control.Button
        AttachmentEditField_3Label      matlab.ui.control.Label
        AttachmentEditField_3           matlab.ui.control.EditField
        NameEditField_4                 matlab.ui.control.EditField
        NameEditField_4Label            matlab.ui.control.Label
        UItableSupplier                 matlab.ui.control.Table
        Setup_Access_ControlTab         matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        NotifLabel                      matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        ACCTable                        matlab.ui.control.Table
        Setup_User_SettingTab           matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        VisibleCheckBox_2               matlab.ui.control.CheckBox
        NewPasswordEditField_2          matlab.ui.control.EditField
        NewPasswordEditField_2Label     matlab.ui.control.Label
        CurrentPasswordEditField        matlab.ui.control.EditField
        CurrentPasswordEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ChangeButton                    matlab.ui.control.Button
        Setup_OtherTab                  matlab.ui.container.Tab
        OtherGrid                       matlab.ui.container.GridLayout
        AddNewCurr                      matlab.ui.control.Button
        AddNewPayT                      matlab.ui.control.Button
        NewCurrency                     matlab.ui.control.EditField
        NewPT                           matlab.ui.control.EditField
        Ctable                          matlab.ui.control.Table
        PTtable                         matlab.ui.control.Table
        OtherNotif                      matlab.ui.control.EditField
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

            NewPasswordHidden = '';     % Store the actual new password
            PasswordHidden = '';
            GlobalTimeTimer % Timer object to update California time
    end


    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.NewPassEditField.Visible = false;
            app.NewPassLabel.Visible = false;
            app.Button_5.Visible = false;
            % Hide all tabs initially
            app.TabGroup.Visible = true;
            % Hide login panel initially
            app.ViraEPCLoginPanel.Visible = false;
            % Set initial state of switch
            app.Switch.Value = 'Log out';
            app.VisibleCheckBox.Value = false; % Unchecked by default
            app.PasswordEditField.FontColor = [1, 1, 1]; % Password hidden (white text)
            app.VisibleCheckBox_2.Value = false; % Unchecked by default
            app.NewPasswordEditField_2.FontColor = [1, 1, 1]; % Password hidden (white text) 
            %app.DarkMoodButton.Value = false; % Default to light mode
            %DarkMoodButtonValueChanged(app, []); % Apply light mode colors

            % Create and configure the timer
            app.GlobalTimeTimer = timer(...
                'ExecutionMode', 'fixedRate', ... % Update at regular intervals
                'Period', 1, ... % Update every second
                'TimerFcn', @(~,~) updateGlobalTimes(app)); % Callback function for the timer
        
            % Start the timer
            start(app.GlobalTimeTimer);
            function updateGlobalTimes(app)
                % Update each label with the corresponding time zone
                app.CATimeLabel.Text = char(datetime('now', 'TimeZone', 'America/Los_Angeles', 'Format', 'hh:mm a'));
                app.NYTimeLabel.Text = char(datetime('now', 'TimeZone', 'America/New_York', 'Format', 'hh:mm a'));
                app.LTimeLabel.Text = char(datetime('now', 'TimeZone', 'Europe/London', 'Format', 'hh:mm a'));
                app.BTimeLabel.Text = char(datetime('now', 'TimeZone', 'Europe/Berlin', 'Format', 'hh:mm a'));
                app.ITimeLabel.Text = char(datetime('now', 'TimeZone', 'Europe/Istanbul', 'Format', 'hh:mm a'));
                app.DTimeLabel.Text = char(datetime('now', 'TimeZone', 'Asia/Dubai', 'Format', 'hh:mm a'));
                app.ChTimeLabel.Text = char(datetime('now', 'TimeZone', 'Asia/Shanghai', 'Format', 'hh:mm a'));
            end


        end

        % Callback function: Entry_InquiryTab, ResetButton
        function InquiryTabButtonDown(app, event)
%% Startup Inquiry
%Some Item Set in the app start up

            %read db\Customer.csv         
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cu = readtable('db\Customer.csv',opts);

            %read db\Operator.csv
            opts2 = detectImportOptions('db\Operator.csv');
            opts2 = setvartype(opts2,{'CellPhone'},'char');
            Op = readtable('db\Operator.csv',opts2);     

            Inq = readtable('db\Inquiry.csv');

            %Filer Close
            rows=(Inq.State~="Close");
            Inq=Inq(rows,:);

            app.InquiryTable.Data = Inq ;
            app.InquiryTable.ColumnName = Inq.Properties.VariableNames;

            ddown=app.InquiryTable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.InqID_DD.Items=ddown;


            %Combo Box Customer in Inquiry Tab

            ddown2 = Cu.Name;
            ddown2=["Select";ddown2];            
            app.CustomerDropDown_Inq.Items=ddown2;
            
            %Obtain missing ID in db
            Inq = readtable('db\Inquiry.csv');         
            sample=Inq.InqID;
            full=(1:max(Inq.InqID))';
            idx = ismember(full, sample);
            CID=full(~idx);
            app.CInqID.Items=['InqID';string(CID)];

%Reset UI
            app.CInqID.Value='InqID';            
            app.CustomerDropDown_Inq.Value = 'Select';
            %app.OperatorDropDown_Inq.Value ='Select';
            app.Date_Inq.Value = NaT;
            app.NameTagEditField.Value = '';
            app.InquiryItemFilePath.Value = '';
            app.AttachmentEditField_Inq.Value = '';
            app.PleaseCheckthePreviewCheckBox.Value = 0 ;
            app.InqID_DD.Value='ID';
            %clear Inq Table
            app.InqItemTable.Data='';                        
            app.CInqID.Enable="off";
            app.AutoGegerateCheckBox.Value=1;
            app.PNEditField.Value="";
            app.QTYEditField_2.Value=0;
            app.BrandEditField.Value="";
            app.DesEditField.Value="";

        end

        % Button pushed function: Button
        function ExcelInq(app, event)
%% Export to excel Inq Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.InquiryTable.Data,fullpathname);
                winopen(fullpathname);
            
            end
       

        end

        % Button pushed function: OpenInqItemsButton
        function OpenInqItemsButtonPushed(app, event)
%% Open Inq Item Files Inq tab
            if app.InqID_DD.Value == "ID"
            
            app.Notif_Inq.Value = "Please Select Inquiry ID!";
            app.Notif_Inq.FontColor= "Red";                
            
            else
            
            Inq=readtable("db\Inquiry.csv");
            row=(Inq.InqID==str2double(app.InqID_DD.Value));
            temp=string(Inq.InqItem(row,:));
            winopen(temp);

            app.Notif_Inq.Value = "Done!";
            app.Notif_Inq.FontColor= "Black"; 

            end
        end

        % Button pushed function: OpenAttachmentButton
        function OpenAttachmentButtonPushed(app, event)
%% Open Attachment Inq tab
            if app.InqID_DD.Value == "ID"
            
            app.Notif_Inq.Value = "Please Select Inquiry ID!";
            app.Notif_Inq.FontColor= "Red";                
            
            else
            
            Inq=readtable("db\Inquiry.csv");
            row=(Inq.InqID==str2double(app.InqID_DD.Value));
            temp=string(Inq.Attachment(row,:));
            winopen(temp);

            app.Notif_Inq.Value = "Done!";
            app.Notif_Inq.FontColor= "Black"; 

            end       
        end

        % Button pushed function: ReadInquiryItemfileButton
        function ReadInquiryItemfileButtonPushed(app, event)
            [file2,path2] = uigetfile('*.*',"openfile") ;
            InqItemFile=strcat(path2,file2);
            
            if InqItemFile ~= 0
            
            opts = detectImportOptions(InqItemFile);
            opts = setvartype(opts,{'PartNumber'},'char');   
            InqIt=readtable(InqItemFile,opts) ;
            app.InqItemTable.Data = InqIt ;
            app.InquiryItemFilePath.Value = InqItemFile;
            end
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';            

        end

        % Button pushed function: AttachButtonInq
        function AttachButtonInqPushed(app, event)
%% select attachment add tab:Inquiry
            [file,path] = uigetfile('*.*',"openfile") ;
            fullpathname=strcat(path,file);
            app.AttachmentEditField_Inq.Value=fullpathname;
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
          progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Saving...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);


                %% Save new Inquiry
                % Set table read format
                    Inq = readtable('db\Inquiry.csv');
                % Check necessary field
                    if string(app.CustomerDropDown_Inq.Value) == "Select" 
                        app.Notif_Inq.Value = "Please select Customer!";
                        app.Notif_Inq.FontColor= "Red";
                        

                    elseif isnat(app.Date_Inq.Value) == 1
                        app.Notif_Inq.Value = "Please select Date!";
                        app.Notif_Inq.FontColor= "Red";
        
                    elseif isempty(app.InquiryItemFilePath.Value) == 1
                        app.Notif_Inq.Value = "Please select Inquiry Itam File!";
                        app.Notif_Inq.FontColor= "Red";
                    
                    elseif app.PleaseCheckthePreviewCheckBox.Value == 0
                        app.Notif_Inq.Value = "Please Review Inquiry Item File Type and Click on Checkbox!";
                        app.Notif_Inq.FontColor= "Red";                
                    
                    else
                        %Specify InqID
                        if app.AutoGegerateCheckBox.Value==0
                            NInqID=str2double(app.CInqID.Value);
                        else
                            NInqID=Inq.InqID(end)+1;
                        end
        
                        %opts5 = detectImportOptions('db\Inquiry.csv');
                        %opts5 = setvartype(opts5,{'Tel'},'char');
                        
        
                        %Create Inquiry Item standard file Path
                        
                        Filename= strcat('.\Files\',string(NInqID),'-InquiryItems.csv');
                        app.InquiryItemFilePath.Value = Filename;
        
                        AFilename="empty";
                        %Copy Attachment to standard Location
                        if app.AttachmentEditField_Inq.Value ~=-0
                            [filepath,name,ext] = fileparts(char(app.AttachmentEditField_Inq.Value)); %#ok<ASGLU>
                            AFilename= strcat('.\Files\',string(NInqID),'-Attachment',ext);
                            copyfile(app.AttachmentEditField_Inq.Value,AFilename);
                        end
                        
        
                    %Add New Inquiry
                        NewInq={Inq{end,1}+1 ...
                        app.CustomerDropDown_Inq.Value ...
                        app.UsernameEditField_2.Value ...
                        app.Date_Inq.Value ...
                        app.NameTagEditField.Value ...
                        app.InquiryItemFilePath.Value ...
                        "Open" ...
                        AFilename
                        };
                    Inq = [Inq;NewInq];
        
                    Inq=sortrows(Inq,'InqID');
        
                    writetable(Inq,'db\Inquiry.csv');
        
                    rows=(Inq.State~="Close");
                    Inq=Inq(rows,:);
                    app.InquiryTable.Data= Inq;
        
                    %write Inquiry Item standard file
                    %app.InqItemTable.Data.Properties.VariableNames = {'#','Part Number','Quantity', 'Brand', 'Description'};
        
                    temp=app.InqItemTable.Data;
                    temp.Properties.VariableNames=app.InqItemTable.ColumnName;
                    % Ensure elements are strings (if necessary)
                    temp.("Part Number") = string(temp.("Part Number"));
                    temp.Brand = string(temp.Brand);
                    temp.Description = string(temp.Description);

        
                    writetable(temp,Filename);
                    
                    %text
                    app.Notif_Inq.Value = "Done!";
                    app.Notif_Inq.FontColor= "black";
                    
                    %%clear all field
                    app.CustomerDropDown_Inq.Value = 'Select';
        %             app.OperatorDropDown_Inq.Value ='Select';
                    app.Date_Inq.Value = NaT;
                    app.NameTagEditField.Value = '';
                    app.InquiryItemFilePath.Value = '';
                    app.AttachmentEditField_Inq.Value= '';
                    app.PleaseCheckthePreviewCheckBox.Value= 0 ;
        
                    %clear Inq Table
                    app.InqItemTable.Data='';
        
        
                    end
          close(progressDlg);
        end

        % Callback function: Issue_PITab, ResetButton_2
        function OpenInquiryTabButtonDown(app, event)
%% Startup Open Inquiry
            Inq = readtable('db\Inquiry.csv');
            PT = readtable('db\PaymentTerms.csv','format','auto');
            Co = readtable('db\Company.csv');
            Cur= readtable('db\Currency.csv');
            %Filter State=Open 
            Orows=(Inq.State=="Open");
            Orows=Inq(Orows,:);

            %Calculate Days
            PDays=days(date-Orows.Date);
            %Insert Column 
            OpenInq=addvars(Orows,PDays,'after','Date');
            %OpenInq Table UI
            app.OpenInqTable.Data = OpenInq ;
            app.OpenInqTable.ColumnName = OpenInq.Properties.VariableNames;
%% Combo Box InqId in OpenInqiury Tab

            ddown3=app.OpenInqTable.Data(:,1);
            ddown3=["ID";table2array(ddown3)];
            app.InqID_DDInq.Items=ddown3;
            app.OpenInqIDDropDown.Items=ddown3;

            ddown4=PT(:,1);
            ddown4=["Select";string(table2array(ddown4))];
            app.PaymenttermsDropDown.Items=ddown4;

            ddown5=Co(:,2);
            ddown5=["Select";string(table2array(ddown5))];
            app.CompanyDropDown.Items=ddown5;

            ddown6=Cur(:,1);
            ddown6=["Select";string(table2array(ddown6))];
            app.OpenInqCurrncyDropDown.Items=ddown6;

            app.Notif_OpenInq.Value='Ready!';
            app.Notif_OpenInq.FontColor='Black';

            %% Reset UI

            app.IssueDateDatePicker.Value = NaT ;
            app.ValidityDatePicker.Value = NaT ;
            app.IncotermsEditField.Value = '';
            app.LeadtimeWeeksEditField.Value= 0;

            app.OpenInqIDDropDown.Value = 'ID';
            app.OpenInqCurrncyDropDown.Value= 'Select';
            app.CompanyDropDown.Value= 'Select';
            app.PaymenttermsDropDown.Value= 'Select';

            app.LineNameEditField.Value='';
            app.ValueEditField.Value=0;
            app.QTYEditField.Value=0;
            app.OpenInqTotal.Value=0;

            %clear Tables
            app.OpenInq_PQTable.Data='';
            app.OpenInquiryQTY.Data='';


        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
%% Show all for Open Inquiry Tab
            Inq = readtable('db\Inquiry.csv');

            %Calculate Days
            PDays=days(date-Inq.Date);
            %Insert Column 
            Inq=addvars(Inq,PDays,'after','Date');
            %OpenInq Table UI
            app.OpenInqTable.Data = Inq ;
            app.OpenInqTable.ColumnName = Inq.Properties.VariableNames;
%% Combo Box InqId in OpenInqiury Tab

            ddown3=app.OpenInqTable.Data(:,1);
            ddown3=["ID";table2array(ddown3)];
            app.InqID_DDInq.Items=ddown3;
            app.OpenInqIDDropDown.Items=ddown3;

            app.Notif_OpenInq.Value='Ready!';
            app.Notif_OpenInq.FontColor='Black';


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
%% Check select in combo box
            Inq=readtable('db\Inquiry.csv');

            if string(app.InqID_DDInq.Value) == "ID"
                %Set Notif
                app.Notif_OpenInq.Value = "Please Select Inquiry ID";
                app.Notif_OpenInq.FontColor= "Red";


            else
                %Change a cell value with an ID from Drop Down Selection
                DSelect=str2double(app.InqID_DDInq.Value);
                rows=(Inq.InqID==DSelect);
                Inq(rows,:).State={'Close'};
                %Save
                writetable(Inq,'db\Inquiry.csv');
                
                %Update Filtered Table
                Orows=(Inq.State=="Open");
                Orows=Inq(Orows,:);
                               

                %Update Table UI
                    %Calculate Days
                PDays=days(date-Orows.Date);
                    %Insert Column 
                OpenInq=addvars(Orows,PDays,'after','Date');
                    %OpenInq Table UI
                app.OpenInqTable.Data = OpenInq ;

                %Update Combo two Box
                ddown3=app.OpenInqTable.Data(:,1);
                ddown3=["ID";table2array(ddown3)];
                app.InqID_DDInq.Items=ddown3; 
                app.OpenInqIDDropDown.Items=ddown3;
                
                %Set Notif 
                app.Notif_OpenInq.Value = "Done!";
                app.Notif_OpenInq.FontColor= "Black";
            end
        end

        % Button pushed function: ImportPQlistButton
        function ImportPQlistButtonPushed(app, event)
            
            %File Path
            [file2,path2] = uigetfile('*.*',"openfile") ;
            PQFile=strcat(path2,file2);
            
            %Read Price and Quantity form PQ list
            if PQFile~=0
            PQ = readtable(PQFile);
            Sizetemp=size(app.OpenInq_PQTable.Data);
            %Temp table
            Temp=array2table(zeros(Sizetemp(1),7));
            Temp.Var1 = app.OpenInq_PQTable.Data.Var1;
            Temp.Var2 = app.OpenInq_PQTable.Data.Var2;
            Temp.Var3 = (string(PQ.PartNumber)==string(app.OpenInq_PQTable.Data.Var2));
            Temp.Var4 = PQ.Q;
            Temp.Var5 = (PQ.Q - app.OpenInquiryQTY.Data);
            Temp.Var6 = PQ.P;
            Temp.Var7 = PQ.Q .* PQ.P;
            
            %Update UI
            app.OpenInq_PQTable.Data=Temp;
            %Calculate Total Value
            app.OpenInqTotal.Value= dot(app.OpenInq_PQTable.Data.Var4,app.OpenInq_PQTable.Data.Var6);
            end
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';            


        end

        % Button pushed function: ReCalcTotalButton
        function ReCalcTotalButtonPushed(app, event)
%% Update line Total and Total
            %update total
            app.OpenInqTotal.Value= dot(app.OpenInq_PQTable.Data.Var4,app.OpenInq_PQTable.Data.Var6);
            
            %update line total
            %Temp table
            Sizetemp=size(app.OpenInq_PQTable.Data);
            Temp=array2table(zeros(Sizetemp(1),7));
            Temp.Var1 = app.OpenInq_PQTable.Data.Var1;
            Temp.Var2 = app.OpenInq_PQTable.Data.Var2;
            Temp.Var3 = app.OpenInq_PQTable.Data.Var3;
            Temp.Var4 = app.OpenInq_PQTable.Data.Var4;
            Temp.Var5 = app.OpenInq_PQTable.Data.Var5;
            Temp.Var6 = app.OpenInq_PQTable.Data.Var6;
            Temp.Var7 = app.OpenInq_PQTable.Data.Var4 .* app.OpenInq_PQTable.Data.Var6;
            %Update UI
            app.OpenInq_PQTable.Data=Temp;
        end

        % Button pushed function: IssuePIButton
        function IssuePIButtonPushed(app, event)

        progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Issuing...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);

%% Issue PI
        %Set Notifs
        if app.OpenInqIDDropDown.Value =="ID"
            app.Notif_OpenInq.Value = "Please Select Inquiry ID";
            app.Notif_OpenInq.FontColor= "Red";

        elseif isempty(app.OpenInq_PQTable.Data) == 1
            app.Notif_OpenInq.Value = "Please Import Inquiry Item!";
            app.Notif_OpenInq.FontColor= "Red";

        elseif isnat(app.IssueDateDatePicker.Value) == 1
            app.Notif_OpenInq.Value = "Please Select Issue Date!";
            app.Notif_OpenInq.FontColor= "Red";

        elseif isnat(app.ValidityDatePicker.Value) == 1
            app.Notif_OpenInq.Value = "Please Select Validity Date!";
            app.Notif_OpenInq.FontColor= "Red";
            
        elseif string(app.OpenInqCurrncyDropDown.Value) == "Select"
            app.Notif_OpenInq.Value = "Please Select Currency!";
            app.Notif_OpenInq.FontColor= "Red";   

        elseif string(app.CompanyDropDown.Value) == "Select"
            app.Notif_OpenInq.Value = "Please Select Company!";
            app.Notif_OpenInq.FontColor= "Red";

        elseif isempty(app.IncotermsEditField.Value) == 1
            app.Notif_OpenInq.Value = "Please Specify Incoterms!";
            app.Notif_OpenInq.FontColor= "Red";

        elseif string(app.PaymenttermsDropDown.Value) == "Select"
            app.Notif_OpenInq.Value = "Please Select Payment Terms!";
            app.Notif_OpenInq.FontColor= "Red";

        elseif app.LeadtimeWeeksEditField.Value == 0
            app.Notif_OpenInq.Value = "Please Specify Lead Time (weeks)!";
            app.Notif_OpenInq.FontColor= "Red"; 


        else


            %Update PI Table
            PI = readtable('db\PI.csv');
           

    %if app.RevisedPIInqIDEditField.Value=0
    %Inq_ID=
            %Create PI Item standard file Path
            Piend=num2str(PI{end,1}+1);
            InqID=num2str(app.OpenInqIDDropDown.Value);
            Filename= ['.\Files\' InqID '-PI-Items-' Piend '.csv'];
            %PIFilename=['.\Files\' InqID '-PI-' Piend '.xlsx'];
            PIFilename = fullfile(pwd, 'Files', [InqID '-PI-' Piend '.xlsx']);

   
             tempID=str2double(app.OpenInqIDDropDown.Value);

            %Add New PI
                NewPI={PI{end,1}+1 ...
                tempID ...
                app.IssueDateDatePicker.Value ...
                app.ValidityDatePicker.Value ...
                dot(app.OpenInq_PQTable.Data.Var4,app.OpenInq_PQTable.Data.Var6) ...
                app.OpenInqCurrncyDropDown.Value ...
                app.CompanyDropDown.Value ...
                app.IncotermsEditField.Value ...
                app.PaymenttermsDropDown.Value ...
                app.LeadtimeWeeksEditField.Value ...
                "Open" ...
                Filename ...
                PIFilename ...
                NaT ...
                '' ...
                };
            
            PI = [PI;NewPI];
            writetable(PI,'db\PI.csv');

            %write PI Item standard file
            app.OpenInq_PQTable.Data.Var7= ...
            app.OpenInq_PQTable.Data.Var4.* ...
            app.OpenInq_PQTable.Data.Var6;

            app.OpenInq_PQTable.Data.Properties.VariableNames = app.OpenInq_PQTable.ColumnName;
            writetable(app.OpenInq_PQTable.Data,Filename);
            
            %Update Inquiry State
            Inq = readtable('db\Inquiry.csv');
            
            Select=str2double(app.OpenInqIDDropDown.Value);
            rows=(Inq.InqID==Select);
            Inq(rows,:).State={'PI Issued'};
            
            %Save Inquiry Table
            writetable(Inq,'db\Inquiry.csv');
            
            %Create PI
            copyfile('db\Golden Template.xlsx',PIFilename);
            %fileattrib(PIFilename, '-w'); % Remove read-only attribute
            pause(1); % Pauses execution for 1 second
            %% If Golden
            %Collect Customer name and Address
            InqID = str2double(app.OpenInqIDDropDown.Value);
            rows=(Inq.InqID==InqID);
            Customer = Inq(rows,:).Customer;
            
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cus = readtable('db\Customer.csv',opts);

            Crows=(string(Cus.Name)==string(Customer));

            CusAdd = Cus(Crows,:).Address;
            CusTel = Cus(Crows,:).Tel;
            %CusCorName = Cus(Crows,:).Corresponder

            %Remove Zero Line
            %Temp table
             Sizetemp=size(app.OpenInq_PQTable.Data);
             Temp = array2table(zeros(Sizetemp(1),6));
             Temp.Var1 = app.OpenInq_PQTable.Data.Cline;
             Temp.Var3 = app.OpenInq_PQTable.Data.PN;
             Temp.Var4 = app.OpenInq_PQTable.Data.Quantity;
             Temp.Var5 = app.OpenInq_PQTable.Data.Price;
             Temp.Var6 = app.OpenInq_PQTable.Data.Quantity .* app.OpenInq_PQTable.Data.Price;
            

                if app.ZeroLinesCheckBox.Value == 0
                    Zrows = (Temp.Var6 == 0);
                    Temp(Zrows, :) = [];
                end
                 Sizetemp2=size(Temp);
                 Temp.Var2=(1:Sizetemp2(1))';
                try
                    % Open Excel using ActiveX
                    excelApp = actxserver('Excel.Application');
                    excelApp.Visible = false;  % Keep Excel hidden during operations
                    workbook = excelApp.Workbooks.Open(PIFilename);
                    sheet = workbook.Sheets.Item('Sheet1');
                    
                    % Write total price and merge cells (E14:F15)
                    totalAmount = dot(app.OpenInq_PQTable.Data.Quantity, app.OpenInq_PQTable.Data.Price);
                    sheet.Range('E14:F15').Merge();
                    sheet.Range('E14').Value = totalAmount;
                    sheet.Range('E14').HorizontalAlignment = -4108; % Center alignment
                    
                    % Convert the table to a cell array
                    TempCellData = table2cell(Temp);
                    
                    % Define the starting row
                    startRow = 18;
                    
                    % Define the range for the table data
                    numRows = size(TempCellData, 1);
                    numCols = size(TempCellData, 2);
                    
                    % Define the range to match the size of the TempCellData
                    endRow = startRow + numRows - 1;
                    endCol = char('A' + numCols - 1); % Convert column number to letter (e.g., 6 -> 'F')
                    
                    % Create the range
                    range = sheet.Range([char('A') num2str(startRow), ':', endCol, num2str(endRow)]);
                    
                    % Write the data to the range
                    range.Value = TempCellData;
                    % Write table data (all rows) starting from A18

                    % Write customer details
                    sheet.Range('A9').Value = Customer;
                    sheet.Range('A10').Value = CusAdd;
                    sheet.Range('A11').Value = CusTel;
                
                    % Write PI ID (temp)
                    temp = strcat(string(year(date) - 2000), ...
                                  string(PI.InqID(end)), ...
                                  '-', string(PI.PIID(end)));
                    sheet.Range('F4').Value = temp;
                
                    % Write PI date and validity date
                    sheet.Range('F5').Value = char(PI.Date(end));
                    sheet.Range('F6').Value = char(PI.ValidityDate(end));
                
                    % Write lead time
                    LeadTime = strcat(string(PI.LeadTime(end)), ' Weeks');
                    sheet.Range('F7').Value = LeadTime;
                
                    % Write company name
                    sheet.Range('A2').Value = PI.Company(end);
                
                    % Write payment terms
                    PT = strcat('PAYMENT TERMS: ', PI.PaymentTerms(end));
                    sheet.Range('A13').Value = PT;
                
                    % Write Incoterms
                    INC = strcat('INCOTERMS: ', PI.Incoterms(end));
                    sheet.Range('A14').Value = INC;
                
                    % Write currency
                    CUR = strcat('CURRENCY: ', PI.Currency(end));
                    sheet.Range('A15').Value = CUR;
                
                    % Auto-fit column width for the entire sheet
                    sheet.Columns.AutoFit();
                
                    % Save and close the workbook
                    workbook.Save();
                    workbook.Close(false);
                    excelApp.Quit();
                
                    % Release ActiveX server
                    delete(excelApp);
                
                    % Open the updated PI file
                    winopen(PIFilename);
                
                    % Update notification text and color
                    app.Notif_OpenInq.Value = "Done!";
                    app.Notif_OpenInq.FontColor = "black";
                
                    % Reset UI
%                    app.OpenInquiryTabButtonDown;
                
                catch ME
                    % Error handling
                    disp('An error occurred while writing to the Excel file:');
                    disp(ME.message);
                
                    % Update notification for error
                    app.Notif_OpenInq.Value = "An error occurred. Please check the file.";
                    app.Notif_OpenInq.FontColor = "Red";
                
                    % Release ActiveX server if error occurs
                    if exist('excelApp', 'var')
                        excelApp.Quit();
                        delete(excelApp);
                    end
                end
                app.ZeroLinesCheckBox.Value = 0;
%% Reset UI

            app.OpenInquiryTabButtonDown;
           
        end
        close(progressDlg);
        end

        % Button pushed function: ResetButton_3
        function OpenPITabButtonDown(app, event)
%% Startup Open PI
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');


            %Get Customer name

            s=size(PI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            PI=addvars(PI,Customer,'after','InqID');
            PI=addvars(PI,Name_Tag,'after','Customer');

            %Total 
            PI.Total=round(PI.Total,2);
            temp=PI.Total;
            PI.Total=string(PI.Total);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                PI.Total(i)=char(jf.format(temp(i)));
            end


            %Filter State=Open 
            Orows=(PI.State=="Open");
            OpenPI=PI(Orows,:);

            %Calculate Days
            PDays=days(date-OpenPI.Date);
            Valid=days(OpenPI.ValidityDate-date);
            
            vrows=(Valid<0);
            Valid=string(Valid);
            Valid(vrows,:)={'Expired'};
            
            
            %Insert Column 
            OpenPI=addvars(OpenPI,PDays,'after','Date');
            OpenPI=addvars(OpenPI,Valid,'after','ValidityDate');
            OpenPI=removevars(OpenPI,'State');
            
            
            %OpenInq Table UI
            app.OpenPITable.Data = OpenPI ;
            app.OpenPITable.ColumnName = OpenPI.Properties.VariableNames;


 %% Combo Box PIID in Open PI Tab
 
            ddown=app.OpenPITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.SelectPIIDDropDown.Items=ddown;
            app.IDOpenPIDD.Items=ddown;


            app.OpenPINotif.Value='Ready!';
            app.OpenPINotif.FontColor='Black';
 
%Reset
            app.SelectPIIDDropDown.Value = 'ID';
            app.IDOpenPIDD.Value = 'ID';

            app.ApprovedDateDatePicker.Value = NaT;
            app.ApPath.Value = '';
            
        end

        % Button pushed function: OpenPIShowallButton
        function OpenPIShowallButtonPushed(app, event)
%% Update UI for show all (open PI tab)
            PI = readtable('db\PI.csv');

            %Get Customer Name
            Inq = readtable('db\Inquiry.csv');
            s=size(PI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            PI=addvars(PI,Customer,'after','InqID');
            PI=addvars(PI,Name_Tag,'after','Customer');
            
            %Calculate Days
            PDays=days(date-PI.Date);
            Valid=days(PI.ValidityDate-date);
            
            %Define Expired
            vrows=(Valid<0);
            Valid=string(Valid);
            Valid(vrows,:)={'Expired'};
            
            
            %Insert Column 
            PI=addvars(PI,PDays,'after','Date');
            PI=addvars(PI,Valid,'after','ValidityDate');
           
            %Total 
            PI.Total=round(PI.Total,2);
            temp=PI.Total;
            PI.Total=string(PI.Total);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                PI.Total(i)=char(jf.format(temp(i)));
            end

            %OpenInq Table UI
            app.OpenPITable.Data = PI ;
            app.OpenPITable.ColumnName = PI.Properties.VariableNames;


 %% Combo Box InqId in OpenInqiury Tab
 
            ddown=app.OpenPITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.SelectPIIDDropDown.Items=ddown;
            app.IDOpenPIDD.Items=ddown;

            app.OpenPINotif.Value='Ready!';
            app.OpenPINotif.FontColor='Black';            
        end

        % Button pushed function: OpenPICloseButton
        function OpenPICloseButtonPushed(app, event)
%% Check select in combo box
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');

            if string(app.SelectPIIDDropDown.Value) == "ID"
                %Set Notif
                app.OpenPINotif.Value = "Please Select PI ID";
                app.OpenPINotif.FontColor= "Red";


            else
              
                %Update PI State
                PIID = str2double(app.SelectPIIDDropDown.Value) ;            
                PIrows = (PI.PIID==PIID);
                %Close Other PI with same InqID
                InqID = PI.InqID(PIrows,:);
                InqIDrows = (PI.InqID==InqID);
                PI.State(InqIDrows,:)={'Close'};
                
                %Update approved PI state
                PI.State(PIrows,:)={'Declined'};
                PI.ApprovedDate(PIrows,:)=app.ApprovedDateDatePicker.Value;
     
                %Update Inquiry State
                Inquiryrows=(Inq.InqID==InqID);
                Inq(Inquiryrows,:).State={'PI Declined'};

                writetable(PI,'db\PI.csv');
                writetable(Inq,'db\Inquiry.csv');


                app.OpenPITabButtonDown;
            end            
        end

        % Button pushed function: AttachPOButton
        function AttachPOButtonPushed(app, event)
            [file2,path2] = uigetfile('*.*',"openfile") ;
            ApPathtemp = strcat(path2,file2);
            
            if ApPathtemp ~= 0
                app.ApPath.Value= ApPathtemp ;
                app.OpenPINotif.Value = "File Attached, Please Click on 'Approved by Customer'!";
                app.OpenPINotif.FontColor= "Black";
            end
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';            
        end

        % Button pushed function: ApprovedByCustomerButton
        function ApprovedByCustomerButtonPushed(app, event)
        % Check necessary field
            if string(app.SelectPIIDDropDown.Value) == "ID" 
                app.OpenPINotif.Value = "Please select PI ID!";
                app.OpenPINotif.FontColor= "Red";
                
            elseif isnat(app.ApprovedDateDatePicker.Value) == 1
                app.OpenPINotif.Value = "Please select Date!";
                app.OpenPINotif.FontColor= "Red";
                
            elseif isempty(app.ApPath.Value) == 1
                app.OpenPINotif.Value = "Please Attach Customer PO!";
                app.OpenPINotif.FontColor= "Red";

            else
                PI = readtable('db\PI.csv');
                Inq = readtable('db\Inquiry.csv');
                %Update PI State
                PIID = str2double(app.SelectPIIDDropDown.Value) ;            
                PIrows = (PI.PIID==PIID);
                %Close Other PI with same InqID
                InqID = PI.InqID(PIrows,:);
                InqIDrows = (PI.InqID==InqID);
                PI.State(InqIDrows,:)={'Close'};
                
                %Update approved PI state
                PI.State(PIrows,:)={'Approved'};
                
     
                %Update Inquiry State
                Inquiryrows=(Inq.InqID==InqID);
                Inq(Inquiryrows,:).State={'PI Approved'};
                         
                %Copy PO to standard Location
                [filepath,name,ext] = fileparts(char(app.ApPath.Value)); %#ok<ASGLU>
                InqIDname=num2str(InqID);
                PIname=num2str(PIID);
                Filename= ['.\Files\' InqIDname '-PO-' PIname ext]; 
                copyfile(app.ApPath.Value,Filename);

                %Save Date and PO Path to db\PI.csv
                PI.ApprovedDate(PIrows,:)=app.ApprovedDateDatePicker.Value;
                PI.POPath(PIrows,:)={Filename};

                %Save
                writetable(PI,'db\PI.csv');
                writetable(Inq,'db\Inquiry.csv');

                
%% Update UI
              app.OpenPITabButtonDown;
            end
        end

        % Button pushed function: ShowAllButton
        function ShowAllButtonPushed(app, event)
            Inq=readtable('db\Inquiry.csv');
            app.InquiryTable.Data= Inq;
            app.InquiryTable.ColumnName=Inq.Properties.VariableNames;

            ddown=app.InquiryTable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.InqID_DD.Items=ddown;

        end

        % Button pushed function: Button_2
        function OpenInqExcel(app, event)
%% Export to excel Inq Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.OpenInqTable.Data,fullpathname);
                winopen(fullpathname);
            
            end
       
        end

        % Button pushed function: OpenInqItemsButton_2
        function OpenInqItemsOpen(app, event)
%% Open Attachment OpenInq tab
            if app.InqID_DDInq.Value == "ID"
            
            app.Notif_Inq.Value = "Please Select Inquiry ID!";
            app.Notif_Inq.FontColor= "Red";                
            
            else
            
            Inq=readtable("db\Inquiry.csv");
            row=(Inq.InqID==str2double(app.InqID_DDInq.Value));
            temp=string(Inq.InqItem(row,:));
            winopen(temp);

            app.Notif_Inq.Value = "Done!";
            app.Notif_Inq.FontColor= "Black"; 

            end       
        end

        % Button pushed function: OpenAttachmentButton_2
        function OpenAttachmentOpen(app, event)
%% Open Attachment OpenInq tab
            if app.InqID_DDInq.Value == "ID"
            
            app.Notif_Inq.Value = "Please Select Inquiry ID!";
            app.Notif_Inq.FontColor= "Red";                
            
            else
            
            Inq=readtable("db\Inquiry.csv");
            row=(Inq.InqID==str2double(app.InqID_DDInq.Value));
            temp=string(Inq.Attachment(row,:));
            winopen(temp);

            app.Notif_Inq.Value = "Done!";
            app.Notif_Inq.FontColor= "Black"; 

            end                   
        end

        % Button pushed function: OpenPIButton_3
        function OpenPIButtonPushed(app, event)
%% Open PI in OpenPI tab

            if app.IDOpenPIDD.Value == "ID"
            
            app.OpenPINotif.Value = "Please Select PI ID!";
            app.OpenPINotif.FontColor= "Red";                
            
            else
            
            PI=readtable("db\PI.csv");
            row=(PI.PIID==str2double(app.IDOpenPIDD.Value));
            temp=string(PI.PIPath(row,:));
            winopen(temp);

            app.OpenPINotif.Value = "Done!";
            app.OpenPINotif.FontColor= "Black"; 

            app.OpenPITabButtonDown

            end       

        end

        % Button pushed function: OpenPIItemButton
        function OpenPIItemButtonPushed(app, event)
%% Open PI in OpenPI tab

            if app.IDOpenPIDD.Value == "ID"
            
            app.OpenPINotif.Value = "Please Select PI ID!";
            app.OpenPINotif.FontColor= "Red";                
            
            else
            
            PI=readtable("db\PI.csv");
            row=(PI.PIID==str2double(app.IDOpenPIDD.Value));
            temp=string(PI.ItemPath(row,:));
            winopen(temp);

            app.OpenPINotif.Value = "Done!";
            app.OpenPINotif.FontColor= "Black"; 

            app.OpenPITabButtonDown

            end       
            
        end

        % Button pushed function: AddLineButton
        function AddLineButtonPushed(app, event)
            if isempty(app.LineNameEditField.Value) ~= 1  ...
               && app.ValueEditField.Value ~= 0 ...
               && app.QTYEditField.Value ~=0
                
               app.OpenInq_PQTable.Data.Var1=string(app.OpenInq_PQTable.Data.Var1) ;
               app.OpenInq_PQTable.Data.Var2=string(app.OpenInq_PQTable.Data.Var2) ;

               NL={'***'...
                    app.LineNameEditField.Value...
                    false ...
                    app.QTYEditField.Value...
                    0 ...
                    app.ValueEditField.Value...
                    app.QTYEditField.Value.*app.ValueEditField.Value};

                app.OpenInq_PQTable.Data=[app.OpenInq_PQTable.Data;NL];

                %Calc Total
                app.OpenInqTotal.Value= dot(app.OpenInq_PQTable.Data.Var4,app.OpenInq_PQTable.Data.Var6);
                %Reset Add Line UI
                app.LineNameEditField.Value='';
                app.ValueEditField.Value=0;
                app.QTYEditField.Value=0;
            else
                app.Notif_OpenInq.Value="Please fill all fields correctly!";
                app.Notif_OpenInq.FontColor="red";
            end
            
        end

        % Button pushed function: Button_6
        function Button_3Pushed(app, event)
%% Export to excel Inq Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.OpenPITable.Data,fullpathname);
                winopen(fullpathname);
            
            end
        end

        % Button down function: Issue_CITab
        function Issue_CITabButtonDown(app, event)
%% Startup Invoice Issue
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');

            
            %Get Customer name

            s=size(PI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            PI=addvars(PI,Customer,'after','InqID');
            PI=addvars(PI,Name_Tag,'after','Customer');

            %Filter State=Open 
            %Orows=(PI.State=="Approved");
            Orows=logical((PI.State=="Approved")+(PI.State=="Partial CI Issued")+(PI.State=="Partial Delivered"));
            AppPI=PI(Orows,:);

            %Total 
            jf=java.text.DecimalFormat;
            AppPI.Total=round(AppPI.Total,2);
            Tot=char(jf.format(sum(AppPI.Total)));
            %app.TotalApprovedPITextArea.Value=Tot;
            app.Label.Text=Tot;
            temp=AppPI.Total;
            AppPI.Total=string(AppPI.Total);
            
            s=size(AppPI);
            for i=1:s(1)
                AppPI.Total(i)=char(jf.format(temp(i)));
            end

            %Calculate Days
            PDays=days(date-AppPI.ApprovedDate);
            LeadTime=AppPI.LeadTime*7;
        

            %Check Delay
            Delay=PDays-LeadTime;
            
            Drows=(Delay>0);
            NDrows=(Delay<=0);

            Delay=string(Delay);
            Delay(Drows,:)={'Delayed'};
            Delay(NDrows,:)={''};
            
            %Insert Column 
            AppPI=addvars(AppPI,PDays,'after','Date');
            AppPI=removevars(AppPI,'LeadTime');
            AppPI=addvars(AppPI,LeadTime,'after','Date');
            
            %Change Column Position
            AppPI=movevars(AppPI,{'Company','Date','ValidityDate'},'After','State');
            AppPI=movevars(AppPI,{'Total','ApprovedDate','LeadTime'},'After','Name_Tag');
            AppPI=movevars(AppPI,'State','After','Currency');
            AppPI=addvars(AppPI,Delay,'after','ApprovedDate');


            %AppPI Table UI
            app.AppPITable.Data = AppPI ;
            app.AppPITable.ColumnName = AppPI.Properties.VariableNames;


 %% Combo Box PIID in Issue Invoice Tab
 
            ddown=app.AppPITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.PIIDDD.Items=ddown;
            app.PIID2DD.Items=ddown;


            app.IssueInvoiceNotif.Value='Ready!';
            app.IssueInvoiceNotif.FontColor='Black';
 
%Reset
            app.PIIDDD.Value = 'ID';
            app.PIID2DD.Value = 'ID';            
            app.DateDatePicker.Value = NaT;
            app.PIItemTable.Data=[];
            app.InItemTable.Data=[];
            app.LineNameEditField_2.Value="";
            app.ValueEditField_2.Value=0;
            app.QTYEditField_3.Value=0;
            app.NewInLine.Data=[];


            app.IssueInvoiceNotif.Value='Ready';
            app.IssueInvoiceNotif.FontColor='Black';

        end

        % Button pushed function: addonetoI
        function addonetoIButtonPushed(app, event)
            if isempty(app.PIItemTable.Data)~=1
            %Select selected line
            Srows=(app.PIItemTable.Data.Select==true);
            temp=app.PIItemTable.Data(Srows,:);

            %Correct select column position
            temp=movevars(temp,'Select','Before','Cline');
           

            %add selected row to Invoice item table
            app.InItemTable.Data=[app.InItemTable.Data;temp];
            
            %Correct logical
            s=size(app.InItemTable.Data);
            Select=false(s(1),1);
            app.InItemTable.Data.Select=Select;
            
            %sort table
            app.InItemTable.Data= sortrows(app.InItemTable.Data,'Cline');
            
            %delete added line from origin
            app.PIItemTable.Data(Srows,:)=[];

            end
        end

        % Button pushed function: addalltoI
        function addalltoIButtonPushed(app, event)
            if isempty(app.PIItemTable.Data)~=1
            %Select all line 
            s=size(app.PIItemTable.Data);
            app.PIItemTable.Data.Select=true(s(1),1);
            %Select selected line
            Srows=(app.PIItemTable.Data.Select==true);
            temp=app.PIItemTable.Data(Srows,:);

            %Correct select column position
            temp=movevars(temp,'Select','Before','Cline');
           

            %add selected row to Invoice item table
            app.InItemTable.Data=[app.InItemTable.Data;temp];
            
            %Correct logical
            s=size(app.InItemTable.Data);
            Select=false(s(1),1);
            app.InItemTable.Data.Select=Select;
            
            %sort table
            app.InItemTable.Data= sortrows(app.InItemTable.Data,'Cline');
            
            %delete added line from origin
            app.PIItemTable.Data(Srows,:)=[];
            end
        end

        % Button pushed function: ResetInitem
        function ResetInitemButtonPushed(app, event)
             if  string(app.PIIDDD.Value) ~= "ID"
                %File Path
                PIID=str2double(app.PIIDDD.Value);
                temp=app.AppPITable.Data((app.AppPITable.Data.PIID==PIID),:);
                
                opts = detectImportOptions(char(temp.ItemPath));
                opts = setvartype(opts,{'PN'},'char');
                temp = readtable(char(temp.ItemPath),opts);     
                
                %Delete zero total Line
                rows=(temp.Total==0);
                temp(rows,:)=[];
                
                %Delete Extra Variables and Select Logical Column
                temp=removevars(temp,{'PNC','DiffQuantity','Total'});
                s=size(temp);
                Select=false(s(1),1);
                temp=addvars(temp,Select,'After','Price');

                app.InItemTable.Data=[];
    
                app.PIItemTable.Data=temp;
                app.PIItemTable.ColumnName = temp.Properties.VariableNames;  
    
            end
            
        end

        % Button pushed function: DelonetoI
        function DelonetoIButtonPushed(app, event)
            %Select selected line
            if isempty(app.InItemTable.Data)~=1
            Srows=(app.InItemTable.Data.Select==true);
            temp=app.InItemTable.Data(Srows,:);

            %Correct select column position
            temp=movevars(temp,'Select','Before','Cline');
           

            %add selected row to Invoice item table
            app.PIItemTable.Data=[app.PIItemTable.Data;temp];
            
            %Correct logical
            s=size(app.PIItemTable.Data);
            Select=false(s(1),1);
            app.PIItemTable.Data.Select=Select;
            
            %sort table
            app.PIItemTable.Data= sortrows(app.PIItemTable.Data,'Cline');
            
            %delete added line from origin
            app.InItemTable.Data(Srows,:)=[];
            end
            
        end

        % Button pushed function: DelalltoI
        function DelalltoIButtonPushed(app, event)
            %Select all line 
            if isempty(app.InItemTable.Data)~=1
            s=size(app.InItemTable.Data);
            app.InItemTable.Data.Select=true(s(1),1);

            %Select selected line
            Srows=(app.InItemTable.Data.Select==true);
            temp=app.InItemTable.Data(Srows,:);

            %Correct select column position
            temp=movevars(temp,'Select','Before','Cline');
           

            %add selected row to Invoice item table
            app.PIItemTable.Data=[app.PIItemTable.Data;temp];
            
            %Correct logical
            s=size(app.PIItemTable.Data);
            Select=false(s(1),1);
            app.PIItemTable.Data.Select=Select;
            
            %sort table
            app.PIItemTable.Data= sortrows(app.PIItemTable.Data,'Cline');
            
            %delete added line from origin
            app.InItemTable.Data(Srows,:)=[];
            end
        end

        % Button pushed function: IssueInvoceButton
        function IssueInvoceButtonPushed(app, event)

           progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Issuing...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);

            if isnat(app.DateDatePicker.Value)==1
                app.IssueInvoiceNotif.Value='Please Select Invoice Date!';
                app.IssueInvoiceNotif.FontColor='Red';            
            elseif isempty(app.InItemTable.Data)==1
                app.IssueInvoiceNotif.Value='Please Add Item to Invoice!';
                app.IssueInvoiceNotif.FontColor='Red';
            else 

                CI=readtable('db\CI.csv');
                PI=readtable('db\PI.csv');
                Inq = readtable('db\Inquiry.csv');
                
                CIend=num2str(CI{end,1}+1);
                PIID=app.PIIDfix.Value;

                %Find InqID
                InqID=num2str(PI.InqID(PI.PIID==PIID));
                PIID=num2str(PIID);

                Filename= ['.\Files\' InqID '-CI-Items-' CIend '-PI-' PIID '.csv'];
                %IFilename=['.\Files\' InqID '-CI-' CIend '-PI-' PIID '.xlsx'];
                IFilename = fullfile(pwd, 'Files', [InqID '-CI-' CIend '-PI-' PIID '.xlsx']);
   
                
                PIID=str2double(PIID);
                CIend=str2double(CIend);
                InqID=str2double(InqID);
                
                % Calculate newLineValue safely
                if isempty(app.NewInLine.Data)
                    newLineValue = 0;
                else
                    newLineValue = dot(app.NewInLine.Data.Var3, app.NewInLine.Data.Var4);
                end
                
                % Calculate tableValue safely
                if isempty(app.InItemTable.Data)
                    tableValue = 0;
                else
                    tableValue = dot(app.InItemTable.Data.Quantity, app.InItemTable.Data.Price);
                end
                
                % Use these values in NewCI
                NewCI = {CIend ...
                         PIID ...
                         app.DateDatePicker.Value ...
                         app.NoteEditField.Value ...
                         newLineValue + tableValue ...  % Safe addition
                         IFilename ...
                         Filename ...
                         "Open" ...
                         NaT ...
                         '' ...
                         };

            CI = [CI;NewCI];
            writetable(CI,'db\CI.csv');

%write CI Item standard file

            writetable(app.InItemTable.Data,Filename);

%% Check for complete or Partial CI

%Load PI item to a temp File

                %PIID=str2double(app.PIIDDD.Value);
                temp1=app.AppPITable.Data((app.AppPITable.Data.PIID==PIID),:);
                
                opts = detectImportOptions(char(temp1.ItemPath));
                opts = setvartype(opts,{'PN'},'char');
                temp = readtable(char(temp1.ItemPath),opts);     
                      
%Compare CI item with PI item
                PIitem=temp;
                PIitem(PIitem.Total<=0,:)=[];

                %Residual=PIitem.Quantity;
                %PIitem=addvars(PIitem,Residual);
                CIitem=app.InItemTable.Data;

                %Based on Items (Part Number)
                % s=size(CIitem);
                % for i=1:s(1)
                % PIitem.Quantity(string(PIitem.PN)==string(CIitem.PN(i)))=...
                %     PIitem.Quantity(string(PIitem.PN)==string(CIitem.PN(i)))-CIitem.Quantity(i);
                % end
                %
                %Based on Cline
                s = size(CIitem);
                for i = 1:s(1)
                    % Find the row in PIitem where both PN and Cline match the CI item
                    matchIdx = (string(PIitem.PN) == string(CIitem.PN(i))) & (PIitem.Cline == CIitem.Cline(i));
                    
                    % Ensure exactly one match is found
                    if sum(matchIdx) == 1
                        PIitem.Quantity(matchIdx) = PIitem.Quantity(matchIdx) - CIitem.Quantity(i);
                    end
                end
                if sum(PIitem.Quantity)<=0
                %Update Inquiry and PI State
                    
                    Inq.State(Inq.InqID==InqID,:)={'CI Issued'};
                    PI.State(PI.PIID==PIID,:)={'CI Issued'};
                    CI.State(end)={'Complete'};
                %Save Tables
                    writetable(Inq,'db\Inquiry.csv');
                    writetable(PI,'db\PI.csv');
                    writetable(CI,'db\CI.csv');
                    CItype='Complete';
                else
                %Update Inquiry and PI State
                    
                    Inq.State(Inq.InqID==InqID,:)={'Partial CI Issued'};
                    PI.State(PI.PIID==PIID,:)={'Partial CI Issued'};
                    CI.State(end)={'Partial'};
                %Save Inquiry Table
                    writetable(Inq,'db\Inquiry.csv');
                    writetable(PI,'db\PI.csv');
                    writetable(CI,'db\CI.csv');                    
                    CItype='Partial';
                end

%Update PI Item standard file
                    writetable(PIitem,char(temp1.ItemPath));

%Create PI
            copyfile('db\Golden Template-CI.xlsx',IFilename);

            pause(1); % Pauses execution for 1 second            
            %% If Golden
            %Collect Customer name and Address
            rows=(Inq.InqID==InqID);
            Customer = Inq(rows,:).Customer;
            
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cus = readtable('db\Customer.csv',opts);

            Crows=(string(Cus.Name)==string(Customer));

            CusAdd = Cus(Crows,:).Address;
            CusTel = Cus(Crows,:).Tel;
            %CusCorName = Cus(Crows,:).Corresponder

            %Remove Zero Line
                %Temp table
                 Sizetemp=size(app.InItemTable.Data);
                 Temp = array2table(zeros(Sizetemp(1),6));
                 Temp.Var1 = app.InItemTable.Data.Cline;
                 Temp.Var3 = app.InItemTable.Data.PN;
                 Temp.Var4 = app.InItemTable.Data.Quantity;
                 Temp.Var5 = app.InItemTable.Data.Price;
                 Temp.Var6 = app.InItemTable.Data.Quantity .* app.InItemTable.Data.Price;

                 % Remove zero-value lines (if needed)
%                  Zrows = (Temp.Price == 0);
%                  Temp(Zrows, :) = [];
                 Zrows=(Temp.Var5==0);
                 Temp(Zrows,:)=[];
                 Sizetemp2=size(Temp);
                 Temp.Var2=(1:Sizetemp2(1))';
                 
                 
                 Temp.Var1=string(Temp.Var1);
                 Temp.Var2=string(Temp.Var2);
%                  %Add Extra line to the CI
                % Add Extra line to CI only if newline exists
                newline = app.NewInLine.Data;
                if ~isempty(newline)
                    snewline = size(newline);
                    newline = addvars(newline, zeros(snewline(1),1), 'before','Var1');
                    newline = array2table(table2array(newline));
                    for i = 1:snewline(1)
                        newline.Var1(i) = "***";
                    end
                    Temp = [Temp; newline];  % Append newline only if it exists
                end                 


                 try
                    % Open Excel using ActiveX
                    excelApp = actxserver('Excel.Application');
                    excelApp.Visible = false;  % Keep Excel hidden during operations
                    workbook = excelApp.Workbooks.Open(IFilename);
                    sheet = workbook.Sheets.Item('Sheet1');
                    

                    totalAmount = newLineValue + tableValue;
                    sheet.Range('E14:F15').Merge();
                    sheet.Range('E14').Value = totalAmount;
                    sheet.Range('E14').HorizontalAlignment = -4108; % Center alignment
                    
                    % Convert the table to a cell array
                    TempCellData = table2cell(Temp);
                    
                    % Define the starting row
                    startRow = 18;
                    
                    % Define the range for the table data
                    numRows = size(TempCellData, 1);
                    numCols = size(TempCellData, 2);
                    
                    % Define the range to match the size of the TempCellData
                    endRow = startRow + numRows - 1;
                    endCol = char('A' + numCols - 1);
                    
                    % Create the range
                    range = sheet.Range([char('A') num2str(startRow), ':', endCol, num2str(endRow)]);
                    
                    % Write the data to the range
                    range.Value = TempCellData;
                    
                    % Write customer details
                    sheet.Range('A9').Value = Customer;
                    sheet.Range('A10').Value = CusAdd;
                    sheet.Range('A11').Value = CusTel;
                    
                    % Write CI ID (temp)
                    temp = strcat(string(year(date) - 2000), ...
                                  string(CI.PIID(end)), ...
                                  '-', string(CI.IID(end)));
                    sheet.Range('F4').Value = temp;
                    
                    % Write CI date and additional temp value
                    sheet.Range('F5').Value = char(CI.Date(end));
                    temp2 = strcat(string(year(date) - 2000), string(InqID), '-', string(PIID));
                    sheet.Range('F6').Value = temp2;
                    
                    % Write CI type
                    sheet.Range('F7').Value = CItype;
                    
                    % Write company name
                    sheet.Range('A2').Value = PI.Company(PI.PIID == PIID);
                    
                    % Write payment terms
                    PT = strcat('PAYMENT TERMS: ', PI.PaymentTerms(PI.PIID == PIID));
                    sheet.Range('A13').Value = PT;
                    
                    % Write Incoterms
                    INC = strcat('INCOTERMS: ', PI.Incoterms(PI.PIID == PIID));
                    sheet.Range('A14').Value = INC;
                    
                    % Write currency
                    CUR = strcat('CURRENCY: ', PI.Currency(PI.PIID == PIID));
                    sheet.Range('A15').Value = CUR;
                    
                    % Auto-fit column width for the entire sheet
                    sheet.Columns.AutoFit();
                    
                    % Save and close the workbook
                    workbook.Save();
                    workbook.Close(false);
                    excelApp.Quit();
                    
                    % Release ActiveX server
                    delete(excelApp);
                    
                    % Open the updated CI file
                    winopen(IFilename);                
                    % Update notification text and color
                    app.IssueInvoiceNotif.Value = "Done!";
                    app.IssueInvoiceNotif.FontColor = "black";
                    
                 catch ME
                    % Error handling
                    disp('An error occurred while writing to the Excel file:');
                    disp(ME.message);
                    
                    % Update notification for error
                    app.IssueInvoiceNotif.Value = "An error occurred. Please check the file.";
                    app.IssueInvoiceNotif.FontColor = "Red";

                 end


%% Reset UI
            % Clear the new lines after generating the invoice
           % app.NewInLine.Data = [];
            app.Issue_CITabButtonDown;
         
            end
         close(progressDlg);

        end

        % Button pushed function: AttachCIButton
        function AttachCIButtonPushed(app, event)
            [file2,path2] = uigetfile('*.*',"openfile") ;
            ApPathtemp = strcat(path2,file2);
            
            if ApPathtemp ~= 0
                app.ApCIPath.Value= ApPathtemp ;
                app.OpenCINotif.Value = "File Attached, Please Click on 'Approved by Customer'!";
                app.OpenCINotif.FontColor= "Black";
            end
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';
        end

        % Button pushed function: ApprovedCIByCustomerButton
        function ApprovedCIByCustomerButtonPushed(app, event)
% Check necessary field
            if string(app.SelectCIIDDropDown.Value) == "ID" 
                app.OpenCINotif.Value = "Please select CI ID!";
                app.OpenCINotif.FontColor= "Red";
                
            elseif isnat(app.ApprovedCIDatePicker.Value) == 1
                app.OpenCINotif.Value = "Please select Date!";
                app.OpenCINotif.FontColor= "Red";
                
            elseif isempty(app.ApCIPath.Value) == 1
                app.OpenCINotif.Value = "Please Attach Customer POD!";
                app.OpenCINotif.FontColor= "Red";

            else
                opts = detectImportOptions('db\CI.csv');
                opts = setvartype(opts,{'Note','DeliveryApproval'},'char');
                CI = readtable('db\CI.csv',opts);
                PI = readtable('db\PI.csv');
                Inq = readtable('db\Inquiry.csv');
                
                %Update PI State
                CIID = str2double(app.SelectCIIDDropDown.Value) ;            
                CIrows=(CI.IID==CIID);
                PIrows=(PI.PIID==CI.PIID(CIrows));
                Inqrows=(Inq.InqID==PI.InqID(PIrows));

                InqID=Inq.InqID(Inqrows);
                PIID=PI.PIID(PIrows);

%% next Update CI, Update PI, Update Inq
                
                if string(CI.State(CIrows))== "Complete" 
                   CI.State(CIrows)={'Delivered'};
                   PI.State(PIrows)={'Delivered'};
                   Inq.State(Inqrows)={'Delivered'};

                elseif string(CI.State(CIrows))== "Partial" 
                   CI.State(CIrows)={'Partial Delivered'};
                   PI.State(PIrows)={'Partial Delivered'};
                   Inq.State(Inqrows)={'Partial Delivered'};                    
                end
                         
                %Copy POD to standard Location
                [filepath,name,ext] = fileparts(char(app.ApCIPath.Value)); %#ok<ASGLU>
                InqIDname=num2str(InqID);
                PIname=num2str(PIID);
                CIname=num2str(CIID);
                Filename= ['.\Files\' InqIDname '-POD-' CIname '-PI-' PIname ext]; 
                copyfile(app.ApCIPath.Value,Filename);

                %Save Date and PO Path to db\PI.csv
                CI.DeliveryDate(CIrows,:)=app.ApprovedCIDatePicker.Value;
                CI.DeliveryApproval(CIrows,:)={Filename};

                %Save
                writetable(CI,'db\CI.csv');
                writetable(PI,'db\PI.csv');
                writetable(Inq,'db\Inquiry.csv');


%% Update UI
              app.OpenCITabButtonDown;
            end

           
        end

        % Button down function: Delete_InquiryTab
        function Delete_InquiryTabButtonDown(app, event)
            Inq=readtable('db\Inquiry.csv');
            app.InquiryTable_2.Data=Inq;
            app.InquiryTable_2.ColumnName = Inq.Properties.VariableNames;

            app.NotifDel.Value='Ready! Please Type ID and Also Name/Tag';
            app.NotifDel.FontColor='Black';

            app.IDDel.Value='';
            app.NameTagDel.Value='';


        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            ID=str2double(app.IDDel.Value);
            Name=app.NameTagDel.Value;
            
            if isempty(ID)==1
                app.NotifDel.Value='Please Type ID!';
                app.NotifDel.FontColor='Red';
            elseif isempty(Name)==1
                app.NotifDel.Value='Please Type Name/Tag!';
                app.NotifDel.FontColor='Red';
            elseif isempty(Name)==0 && isempty(ID)==0
                Inq=readtable('db\Inquiry.csv');
                PI=readtable('db\PI.csv');
                CI=readtable('db\CI.csv');
                    if string(Inq.Name_Tag(Inq.InqID==ID))==string(Name)
                        temp=PI.PIID(PI.InqID==ID,:);
                        Inq(Inq.InqID==ID,:)=[];
                        PI(PI.InqID==ID,:)=[];
                            for i=1:length(temp)
                            CI(CI.PIID==temp(i),:)=[];
                            end
                        writetable(CI,'db\CI.csv');
                        writetable(PI,'db\PI.csv');
                        writetable(Inq,'db\Inquiry.csv');
                        app.Delete_InquiryTabButtonDown
                    end

            end

        end

        % Button pushed function: OpenAppPI_2
        function OpenAppPI_2ButtonPushed(app, event)
%% Open PO in Issue Invoice tab

            if app.PIID2DD.Value == "ID"
            
            app.IssueInvoiceNotif.Value = "Please Select PI ID!";
            app.IssueInvoiceNotif.FontColor= "Red";                
            
            else
            
            PI=readtable("db\PI.csv");
            row=(PI.PIID==str2double(app.PIID2DD.Value));
            temp=string(PI.POPath(row,:));
            winopen(temp);

            app.IssueInvoiceNotif.Value = "Done!";
            app.IssueInvoiceNotif.FontColor= "Black"; 

            app.Issue_CITabButtonDown;

            end                               
        end

        % Button pushed function: OpenAppPI
        function OpenAppPIButtonPushed(app, event)
%% Open PI in Issue Invoice tab

            if app.PIID2DD.Value == "ID"
            
            app.IssueInvoiceNotif.Value = "Please Select PI ID!";
            app.IssueInvoiceNotif.FontColor= "Red";                
            
            else
            
            PI=readtable("db\PI.csv");
            row=(PI.PIID==str2double(app.PIID2DD.Value));
            temp=string(PI.PIPath(row,:));
            winopen(temp);

            app.IssueInvoiceNotif.Value = "Done!";
            app.IssueInvoiceNotif.FontColor= "Black"; 

            app.Issue_CITabButtonDown;

            end                   
        end

        % Button pushed function: AppPIExcel
        function AppPIExcelButtonPushed(app, event)
%% Export to excel Issue Invoice Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.AppPITable.Data,fullpathname);
                winopen(fullpathname);
            
            end            
        end

        % Button pushed function: OpenCIButton_3
        function OpenCIButtonPushed(app, event)
%% Open CI in Open CI tab

            if app.IDOpenCIDD.Value == "ID"
            
            app.OpenCINotif.Value = "Please Select PI ID!";
            app.OpenCINotif.FontColor= "Red";                
            
            else
            
            CI=readtable("db\CI.csv");
            row=(CI.IID==str2double(app.IDOpenCIDD.Value));
            temp=string(CI.InvoicePath(row,:));
            winopen(temp);

            app.OpenCINotif.Value = "Done!";
            app.OpenCINotif.FontColor= "Black"; 

            app.OpenCITabButtonDown;

            end                 
        end

        % Button pushed function: OpenPICIButton
        function OpenPICIButtonPushed(app, event)
%% Open PI in Open CI tab

            if app.IDOpenCIDD.Value == "ID"
            
            app.OpenCINotif.Value = "Please Select PI ID!";
            app.OpenCINotif.FontColor= "Red";                
            
            else
            
            CI=readtable("db\CI.csv");
            PI=readtable("db\PI.csv");

            CIID=str2double(app.IDOpenCIDD.Value);          
            temp=string(PI.PIPath(PI.PIID==CI.PIID(CI.IID==CIID)));
            winopen(temp);

            app.OpenCINotif.Value = "Done!";
            app.OpenCINotif.FontColor= "Black"; 

            app.OpenCITabButtonDown;

            end                 
        end

        % Button pushed function: OCIExcel
        function OCIExcelButtonPushed(app, event)
%% Export to excel Open CI Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.OpenCITable.Data,fullpathname);
                winopen(fullpathname);
            
            end                     
        end

        % Button down function: ReportTab
        function ReportTabButtonDown(app, event)

            
            % Startup Report
            
            CI = readtable('db\CI.csv');
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');
            Cu = readtable('db\Customer.csv');
            
            app.CuDDRe.Items=["Select";Cu.Name];

%Delete Close Inq Line
            Inq(string(Inq.State)=='Close',:)=[];
            
%Create Report Table            
            s=size(Inq);
            temp=array2table(nan(s(1),7));
            temp=addvars(temp,NaT(s(1),1),'after','Var5');
            temp=addvars(temp,NaT(s(1),1),'after','Var6');
            temp=addvars(temp,NaT(s(1),1),'before','Var7');
            temp.Properties.VariableNames={'Var1','Var2','Var3','Var4','Var5','Var6','Var7','Var8','Var9','Var10'};

            
            temp.Var1=Inq.Customer;
            temp.Var2=Inq.Name_Tag;
            temp.Var3=Inq.State;
            temp.Var5=Inq.InqID;
            temp.Var6=Inq.Date;


            for i=1:s(1)
                if string(Inq.State(i))=="Open"
                    temp.Var4(i)="";
                    temp.Var7(i)="";
                    temp.Var8(i)="";
                    temp.Var9(i)="";
                    temp.Var10(i)="";
                    
                elseif string(Inq.State(i))=="PI Issued"
                    PItemp=PI(PI.InqID==Inq.InqID(i),:);
                    temp.Var4(i)=PItemp.Total(PItemp.PIID==max(PItemp.PIID));
                    temp.Var7(i)=PItemp.PIID(PItemp.PIID==max(PItemp.PIID));
                    temp.Var8(i)=PItemp.Date(PItemp.PIID==max(PItemp.PIID));
                    temp.Var9(i)="";
                    temp.Var10(i)=PItemp.LeadTime(PItemp.PIID==max(PItemp.PIID));
                else
                    PItemp=PI(PI.InqID==Inq.InqID(i),:);
                    temp.Var4(i) = PItemp.Total(find(PItemp.State~="Close", 1));
                    temp.Var7(i) = PItemp.PIID(find(PItemp.State~="Close", 1));
                    temp.Var8(i) = PItemp.Date(find(PItemp.State~="Close", 1));
                    temp.Var9(i) = PItemp.ApprovedDate(find(PItemp.State~="Close", 1));
                    temp.Var10(i) = PItemp.LeadTime(find(PItemp.State~="Close", 1));

                    % temp.Var4(i)=PItemp.Total(PItemp.State~="Close");
                    % temp.Var7(i)=PItemp.PIID(PItemp.State~="Close");
                    % temp.Var8(i)=PItemp.Date(PItemp.State~="Close");
                    % temp.Var9(i)=PItemp.ApprovedDate(PItemp.State~="Close");
                    % temp.Var10(i)=PItemp.LeadTime(PItemp.State~="Close");                
                end
            end


            %Add total Delivered CI to the report table and CI #
            temp=addvars(temp,nan(s(1),1),'after','Var4');
            temp=addvars(temp,nan(s(1),1),'after','Var5_1');

            % Filter rows based on the condition
            validStates = ["Delivered", "Partial Delivered", "CI Issued", "Partial CI Issued"];
            validRows = ismember(temp.Var3, validStates);
            
            % Extract PIIDs for valid rows
            PIIDs = temp.Var7(validRows);
            
            % Initialize TCI and CIN columns
            temp.Var5_1 = nan(height(temp), 1);
            temp.Var6_1 = nan(height(temp), 1);
            
            % Calculate TCI and CIN for each valid row
            for j = 1:numel(PIIDs)
                PIID = PIIDs(j);
                CI2 = CI(CI.PIID == PIID, :);
                
                % Sum Total and count rows for CI
                TCI = sum(CI2.Total);
                CIN = height(CI2);
                
                % Assign values to the corresponding rows in temp
                temp.Var5_1(validRows & (temp.Var7 == PIID)) = TCI;
                temp.Var6_1(validRows & (temp.Var7 == PIID)) = CIN;
            end


% Set Total PI and Total CI format

            temp.Var4=round(temp.Var4,0);
            temp.Var5_1=round(temp.Var5_1,0);
%save Total PI before change to string
            Total=temp.Var4;
            Total((isnan(Total)==1),:)=[]; %#ok<COMPNOP> 
            Total=sum(Total);
%save Total CI before change to string            
            TotalCI=temp.Var5_1;
            TotalCI((isnan(TotalCI)==1),:)=[]; %#ok<COMPNOP> 
            TotalCI=sum(TotalCI);
            
            %Correct Total PI format
            temp2=temp.Var4;
            temp.Var4=string(temp.Var4);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                if ismissing(temp.Var4(i))==1
                
                    temp.Var4(i)='---';
                else

                    temp.Var4(i)=char(jf.format(temp2(i)));
                end
            end


            %Correct Total CI format
            temp3=temp.Var5_1;
            temp.Var5_1=string(temp.Var5_1);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                if ismissing(temp.Var5_1(i))==1
                
                    temp.Var5_1(i)='---';
                else

                    temp.Var5_1(i)=char(jf.format(temp3(i)));
                end
            end
            

            % Convert columns to string
            temp.Var6_1 = string(temp.Var6_1);
            temp.Var7 = string(temp.Var7);
            temp.Var8 = string(temp.Var8);
            temp.Var9 = string(temp.Var9);
            temp.Var10 = string(temp.Var10);
            
            % Replace missing values with '---'
            missingValue = '---';
            temp.Var6_1(ismissing(temp.Var6_1)) = missingValue;
            temp.Var7(ismissing(temp.Var7)) = missingValue;
            temp.Var8(ismissing(temp.Var8)) = missingValue;
            temp.Var9(ismissing(temp.Var9)) = missingValue;
            temp.Var10(ismissing(temp.Var10)) = missingValue;


            Total=char(jf.format(Total));
            Total=strcat('Total PI Value: ', Total);

            TotalCI=char(jf.format(TotalCI));
            TotalCI=strcat('Total CI Value: ', TotalCI);

            app.ReportT.Data=temp;
            app.TotalPIValueLabel.Text=Total;
            app.TotalCIValueLabel.Text=TotalCI;

            
            %Reset Style for each report
            st1= uistyle('FontColor','Black');
            for i=1:s(1)
                    addStyle(app.ReportT,st1,'cell',[i,5]);
            end
            %Add Colour for diff CI and PI
            %set Red Style
            st = uistyle('FontColor','Red');

            for i=1:s(1)
                if string(temp.Var5_1(i))~="---"
                if string(temp.Var5_1(i))~=string(temp.Var4(i))
                    addStyle(app.ReportT,st,'cell',[i,5]);
                end
                end
            end


            app.InqIDDDReport.Items=["ID";temp.Var5];
            
            app.InqIDDDReport.Value="ID";
            app.CuDDRe.Value="Select";
            app.OpenButton.Value=false;
            app.PIDeclinedButton.Value=false;
            app.PIApprovedButton.Value=false;
            app.PIIssuedButton.Value=false;
            app.CIIssuedButton.Value=false;
            app.PartialCIIssuedButton.Value=false;
            app.PartialDeliveredButton.Value=false;        
            app.DeliveredButton.Value=false;


        end

        % Button pushed function: FilterReport
        function FilterReportButtonPushed(app, event)

        progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
            'Title', 'Filtering...', ...
            'Message', 'Please wait :)', ...
            'Indeterminate', true, ...
            'Cancelable', false);
%% Startup Report

            CI = readtable('db\CI.csv');
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');

%Delete Close Inq Line
            Inq(string(Inq.State)=='Close',:)=[];
            
%Create Report Table            
            s=size(Inq);
            temp=array2table(nan(s(1),7));
            temp=addvars(temp,NaT(s(1),1),'after','Var5');
            temp=addvars(temp,NaT(s(1),1),'after','Var6');
            temp=addvars(temp,NaT(s(1),1),'before','Var7');
            temp.Properties.VariableNames={'Var1','Var2','Var3','Var4','Var5','Var6','Var7','Var8','Var9','Var10'};     

            temp.Var1=Inq.Customer;
            temp.Var2=Inq.Name_Tag;
            temp.Var3=Inq.State;
            temp.Var5=Inq.InqID;
            temp.Var6=Inq.Date;
            
            for i=1:s(1)
                if string(Inq.State(i))=="Open"
                    temp.Var4(i)="";
                    temp.Var7(i)="";
                    temp.Var8(i)="";
                    temp.Var9(i)="";
                    temp.Var10(i)="";
                    
                elseif string(Inq.State(i))=="PI Issued"
                    PItemp=PI(PI.InqID==Inq.InqID(i),:);
                    temp.Var4(i)=PItemp.Total(PItemp.PIID==max(PItemp.PIID));
                    temp.Var7(i)=PItemp.PIID(PItemp.PIID==max(PItemp.PIID));
                    temp.Var8(i)=PItemp.Date(PItemp.PIID==max(PItemp.PIID));
                    temp.Var9(i)="";
                    temp.Var10(i)=PItemp.LeadTime(PItemp.PIID==max(PItemp.PIID));
                else
                    %Please Fix it to approved PI)
                    PItemp=PI(PI.InqID==Inq.InqID(i),:);
                    temp.Var4(i)=PItemp.Total(PItemp.State~="Close");
                    temp.Var7(i)=PItemp.PIID(PItemp.State~="Close");
                    temp.Var8(i)=PItemp.Date(PItemp.State~="Close");
                    temp.Var9(i)=PItemp.ApprovedDate(PItemp.State~="Close");
                    temp.Var10(i)=PItemp.LeadTime(PItemp.State~="Close");                   
                end
            end

            %Add total Delivered CI to the report table
            temp=addvars(temp,nan(s(1),1),'after','Var4');
            temp=addvars(temp,nan(s(1),1),'after','Var5_1');
            st=size(temp);
            for j=1:st(1)
                if string(temp.Var3(j))=="Delivered" || string(temp.Var3(j))=="Partial Delivered" || ...
                   string(temp.Var3(j))=="CI Issued" || string(temp.Var3(j))=="Partial CI Issued" 
                    
                    PIID=temp.Var7(j);
                    CI2=CI(CI.PIID==PIID,:);
                    sc=size(CI2);
                    TCI=0;
                    CIN=0;
                    for k=1:sc(1)
                        TCI=TCI+CI2.Total(k);
                        CIN=CIN+1;
                    end
                    temp.Var5_1(j)=TCI;
                    temp.Var6_1(j)=CIN;
                     
                end
                    
            end

%% Filter for selected Compnaies

%Define Selected State array

            State={'Open';'PI Declined';'PI Issued';'PI Approved';'CI Issued';'Partial CI Issued';'Partial Delivered';'Delivered'};

            if app.OpenButton.Value==false
                State(State=="Open",:)=[];
            end
            if app.PIDeclinedButton.Value==false
                State(State=="PI Declined",:)=[];
            end
            if app.PIIssuedButton.Value==false
                State(State=="PI Issued",:)=[];
            end
            if app.PIApprovedButton.Value==false
                State(State=="PI Approved",:)=[];
            end
            if app.CIIssuedButton.Value==false
                State(State=="CI Issued",:)=[];
            end
            if app.PartialCIIssuedButton.Value==false
                State(State=="Partial CI Issued",:)=[];
            end
            if app.PartialDeliveredButton.Value==false
                State(State=="Partial Delivered",:)=[];
            end
            if app.DeliveredButton.Value==false
                State(State=="Delivered",:)=[];
            end            

            if isempty(State)==1
                State={'Open';'PI Declined';'PI Issued';'PI Approved';'CI Issued';'Partial CI Issued';'Partial Delivered';'Delivered'};
            end

                %Select Inq Based on Cus DD

            if string(app.CuDDRe.Value)~="Select"
                 
                temp=temp(temp.Var1==string(app.CuDDRe.Value),:);

            end


                %Select Inq Based on State Buttons
                row=0;
                for i=1:length(State)
                    row=row+(temp.Var3==string(State(i)));
                end
                %Filter Extra Lines
                row=logical(row);
                temp=temp(row,:);
                
                %Calc Total Value
                temp.Var4=round(temp.Var4,0);
                temp.Var5_1=round(temp.Var5_1,0);


                Total=temp.Var4;
                Total((isnan(Total)==1),:)=[]; %#ok<COMPNOP> 
                Total=sum(Total);
                                

                TotalCI=temp.Var5_1;
                TotalCI((isnan(TotalCI)==1),:)=[]; %#ok<COMPNOP>
                TotalCI=sum(TotalCI);


                %Fix Totol PI and Total CI Value
                s=size(temp);
                temp2=temp.Var4;
                temp.Var4=string(temp.Var4);
                jf=java.text.DecimalFormat;
                for i=1:s(1)
                    if ismissing(temp.Var4(i))==1

                        temp.Var4(i)='---';
                    else

                        temp.Var4(i)=char(jf.format(temp2(i)));
                    end
                end

                temp3=temp.Var5_1;
                temp.Var5_1=string(temp.Var5_1);
                jf=java.text.DecimalFormat;
                for i=1:s(1)
                    if ismissing(temp.Var5_1(i))==1

                        temp.Var5_1(i)='---';
                    else

                        temp.Var5_1(i)=char(jf.format(temp3(i)));
                    end
                end


            %Correct CI#,PIID, PI-Date, A-Date, Leadtime format
            temp.Var6_1=string(temp.Var6_1);
            temp.Var7=string(temp.Var7);
            temp.Var8=string(temp.Var8);
            temp.Var9=string(temp.Var9);
            temp.Var10=string(temp.Var10);
            for i=1:s(1)
                if ismissing(temp.Var6_1(i))==1
                    temp.Var6_1(i)='---';
                end

                if ismissing(temp.Var7(i))==1
                    temp.Var7(i)='---';
                end

                if ismissing(temp.Var8(i))==1
                    temp.Var8(i)='---';
                end
                
                if ismissing(temp.Var9(i))==1
                    temp.Var9(i)='---';
                end

                if ismissing(temp.Var10(i))==1
                    temp.Var10(i)='---';
                end
            end

            Total=char(jf.format(Total));
            Total=strcat('Total PI Value: ', Total);

            TotalCI=char(jf.format(TotalCI));
            TotalCI=strcat('Total CI Value: ', TotalCI);

            app.ReportT.Data=temp;
            %Reset Style for each report
            st1= uistyle('FontColor','Black');
            for i=1:s(1)
                    addStyle(app.ReportT,st1,'cell',[i,5]);
            end

            %set Red Style
            st = uistyle('FontColor','Red');

            for i=1:s(1)
                if string(temp.Var5_1(i))~="---"
                if string(temp.Var5_1(i))~=string(temp.Var4(i))
                    addStyle(app.ReportT,st,'cell',[i,5]);
                end
                end
            end

            app.TotalPIValueLabel.Text=Total;
            app.TotalCIValueLabel.Text=TotalCI;
            app.InqIDDDReport.Items=["ID";temp.Var5];


        close(progressDlg);
        end

        % Button pushed function: ShowAllReport
        function ShowAllReportButtonPushed(app, event)
            
        progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
            'Title', 'Refreshing...', ...
            'Message', 'Please wait :)', ...
            'Indeterminate', true, ...
            'Cancelable', false);

            app.Notif_Report.Value = "ٌWait Please :)";
            app.Notif_Report.FontColor= "Black";
            pause(1);
            app.ReportTabButtonDown;
            app.Notif_Report.Value = "";
        close(progressDlg);

        end

        % Button down function: FinancialTab
        function FinancialTabButtonDown(app, event)
%% Read CSV
            Cu = readtable('db\Customer.csv');
            Pa = readtable('db\Payment.csv');
            Cur = readtable('db\Currency.csv');
            

%% UI table
            %UI.table customer tab:setup
            s=size(Pa);
            temp=Pa.Value;
            Pa.Value=string(Pa.Value);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                Pa.Value(i)=char(jf.format(temp(i)));
            end

            app.PaymentT.Data=Pa;
            app.PaymentT.ColumnName = Pa.Properties.VariableNames;


%% Combo Box

            app.CustomerDD.Items=["Select";Cu.Name];
            app.CurrencyDD.Items=["Select";Cur.Currency];

%% Resat UI

            app.NoteFin.Value='';
            app.ValueFin.Value= 0;
            app.DateFin.Value = NaT;
            app.AttachFin.Value='';
            app.CustomerDD.Value="Select"; 
            app.CurrencyDD.Value="Select"; 

        end

        % Button pushed function: AttachButtonFin
        function AttachButtonFinPushed(app, event)
            [file2,path2] = uigetfile('*.*',"openfile") ;
            ApPathtemp = strcat(path2,file2);
            
            if ApPathtemp ~= 0
                app.AttachFin.Value= ApPathtemp ;
                app.OpenPINotif.Value = "File Attached, Please Click on 'Add'!";
                app.OpenPINotif.FontColor= "Black";
            end
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
%% Save new Payment
        % Check necessary field
            if string(app.CustomerDD.Value) == "Select" 
                app.Notif_Fin.Value = "Please select Customer!";
                app.Notif_Fin.FontColor= "Red";
                
               
            elseif isnat(app.DateFin.Value) == 1
                app.Notif_Fin.Value = "Please select Date!";
                app.Notif_Fin.FontColor= "Red";

            elseif app.ValueFin.Value == 0
                app.Notif_Fin.Value = "Please Specify Payment Value!";
                app.Notif_Fin.FontColor= "Red";


            elseif string(app.CurrencyDD.Value) == "Select"
                app.Notif_Fin.Value = "Please select Currency!";
                app.Notif_Fin.FontColor= "Red";


            elseif isempty(app.AttachFin.Value) == 1
                app.Notif_Fin.Value = "Please Attach Payment Receipt!";
                app.Notif_Fin.FontColor= "Red";
            
              
            else
                Pa = readtable('db\Payment.csv');


            %Create standard file Path
            ID=num2str(Pa{end,1}+1); 
                               
            [filepath,name,ext] = fileparts(char(app.AttachFin.Value)); %#ok<ASGLU>
            Filename= ['.\Files\Fin\' ID '-' 'Payment' ext]; 
            copyfile(app.AttachFin.Value,Filename);

            %Add New 
                NewPa={Pa{end,1}+1 ...
                app.CustomerDD.Value ...
                app.DateFin.Value ...
                app.ValueFin.Value ...
                app.CurrencyDD.Value ...
                app.NoteFin.Value ...
                Filename
                };
            Pa = [Pa;NewPa];
            writetable(Pa,'db\Payment.csv');

        
            %text
            app.Notif_Fin.Value = "Done!";
            app.Notif_Fin.FontColor= "black";
            
            %Reset
            app.FinancialTabButtonDown;


            end            
        end

        % Button down function: Financial_PaymentTab
        function Financial_PaymentTabButtonDown(app, event)
            app.FinancialTabButtonDown;
        end

        % Button down function: Financial_ReportTab
        function Financial_ReportTabButtonDown(app, event)
            PI=readtable('db\PI.csv');
            Inq=readtable('db\Inquiry.csv');
            Pay=readtable('db\Payment.csv');

%% USD Table
%Withdraw
            row=logical((PI.State=="Approved")+(PI.State=="CI Issued")...
                +(PI.State=="Partial CI Issued")+(PI.State=="Partial Delivered")+(PI.State=="Delivered"));
            PI=PI(row,:);

            row=(PI.Currency=="USD");
            PI=PI(row,:);

            s=size(PI);
            temp=array2table(nan(s(1),7));
            %temp=addvars(temp,NaT(s(1),1),'after','Var2');
            %temp.Properties.VariableNames={'Var1','Var2','Var3','Var4','Var5','Var6','Var7'};   
            %temp.Var1=(1:s(1))';
            
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW>
            %x(i,1)="";
            end
           
            temp.Var2=Customer;
            temp.Var3=PI.ApprovedDate;
            temp.Var4=Name_Tag;
            temp.Var6=PI.Total;


%Deposite
            row=(Pay.Currency=="USD");
            Pay=Pay(row,:);        
            s2=size(Pay);
            temp2=array2table(nan(s2(1),7));

            temp2.Var2=Pay.Customer;
            temp2.Var3=Pay.Date;
            temp2.Var4=Pay.Note;
            temp2.Var5=Pay.Value;


            temp= [temp;temp2];
            temp=sortrows(temp,'Var3');
            s3=size(temp);
            temp.Var1=(1:s3(1))';

            if isnan(temp.Var5(1))==false
                Bal(1,1)=temp.Var5(1);
            else
                Bal(1,1)=-temp.Var6(1);
            end

            for i=2:s3(1)
                if isnan(temp.Var5(i))==false
                    Bal(i,1)=Bal(i-1,1)+temp.Var5(i);
                else
                    Bal(i,1)=Bal(i-1,1)-temp.Var6(i);
                end
            end

            temp.Var7=Bal;

            temp2=round(temp.Var5,0);
            temp3=round(temp.Var6,0);
            temp4=round(temp.Var7,0);

            temp.Var5=string(temp.Var5);
            temp.Var6=string(temp.Var6);
            temp.Var7=string(temp.Var7);


            jf=java.text.DecimalFormat;
            for i=1:s3(1)
                if ismissing(temp.Var5(i))==true
                
                    temp.Var5(i)="";
                    temp.Var6(i)=char(jf.format(temp3(i)));
                    temp.Var7(i)=char(jf.format(temp4(i)));

                else

                    temp.Var5(i)=char(jf.format(temp2(i)));
                    temp.Var6(i)="";
                    temp.Var7(i)=char(jf.format(temp4(i)));
                end
            end 

            app.USDT.Data=temp;
%% Euro Tabble

            PI=readtable('db\PI.csv');
            Inq=readtable('db\Inquiry.csv');
            Pay=readtable('db\Payment.csv');
%Withdraw

            row=logical((PI.State=="Approved")+(PI.State=="CI Issued")...
                +(PI.State=="Partial CI Issued")+(PI.State=="Partial Delivered")+(PI.State=="Delivered"));
            PI=PI(row,:);

            row=(PI.Currency=="EURO");
            PI=PI(row,:);

            if isempty(PI)==false
            s=size(PI);
            temp=array2table(nan(s(1),7));
            %temp=addvars(temp,NaT(s(1),1),'after','Var2');
            %temp.Properties.VariableNames={'Var1','Var2','Var3','Var4','Var5','Var6','Var7'};   
            %temp.Var1=(1:s(1))';
            
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:));  
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));
            %x(i,1)="";
            end
           
            temp.Var2=Customer;
            temp.Var3=PI.ApprovedDate;
            temp.Var4=Name_Tag;
            temp.Var6=PI.Total;
            end

%Deposite
            row=(Pay.Currency=="EURO");
            Pay=Pay(row,:);

            if isempty(Pay)==false
            s2=size(Pay);
            temp2=array2table(nan(s2(1),7));

            temp2.Var2=Pay.Customer;
            temp2.Var3=Pay.Date;
            temp2.Var4=Pay.Note;
            temp2.Var5=Pay.Value;


            temp= [temp;temp2];
            temp=sortrows(temp,'Var3');
            s3=size(temp);
            temp.Var1=(1:s3(1))';

            if isnan(temp.Var5(1))==false
                Bal(1,1)=temp.Var5(1);
            else
                Bal(1,1)=-temp.Var6(1);
            end

            for i=2:s3(1)
                if isnan(temp.Var5(i))==false
                    Bal(i,1)=Bal(i-1,1)+temp.Var5(i);
                else
                    Bal(i,1)=Bal(i-1,1)-temp.Var6(i);
                end
            end

            temp.Var7=Bal;

            temp2=round(temp.Var5,0);
            temp3=round(temp.Var6,0);
            temp4=round(temp.Var7,0);

            temp.Var5=string(temp.Var5);
            temp.Var6=string(temp.Var6);
            temp.Var7=string(temp.Var7);


            jf=java.text.DecimalFormat;
            for i=1:s3(1)
                if ismissing(temp.Var5(i))==true
                
                    temp.Var5(i)="";
                    temp.Var6(i)=char(jf.format(temp3(i)));
                    temp.Var7(i)=char(jf.format(temp4(i)));

                else

                    temp.Var5(i)=char(jf.format(temp2(i)));
                    temp.Var6(i)="";
                    temp.Var7(i)=char(jf.format(temp4(i)));
                end
            end 
            app.EuroT.Data=temp;
            end


            CuName=unique(temp.Var2);    
            app.CustomerDDFinR.Items=["Select";CuName];

            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if string(app.CustomerDDFinR.Value)=="Select" 

                app.Notif_FinR.Value="Please Select a Customer!";
                app.Notif_FinR.FontColor="Red";

                app.Financial_ReportTabButtonDown


            else 


%% USD
            PI=readtable('db\PI.csv');
            Inq=readtable('db\Inquiry.csv');
            Pay=readtable('db\Payment.csv');
%Withdraw
            row=logical((PI.State=="Approved")+(PI.State=="CI Issued")...
                +(PI.State=="Partial CI Issued")+(PI.State=="Partial Delivered")+(PI.State=="Delivered"));
            PI=PI(row,:);

            row=(PI.Currency=="USD");
            PI=PI(row,:);

            
            s=size(PI);
            temp=array2table(nan(s(1),7));

            
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW>
 
            end
           
            temp.Var2=Customer;
            temp.Var3=PI.ApprovedDate;
            temp.Var4=Name_Tag;
            temp.Var6=PI.Total;
            
            row=(temp.Var2==string(app.CustomerDDFinR.Value));
            temp=temp(row,:);

%Deposite
            row=(Pay.Currency=="USD");
            Pay=Pay(row,:);        
            s2=size(Pay);
            temp2=array2table(nan(s2(1),7));

            
            temp2.Var2=Pay.Customer;
            temp2.Var3=Pay.Date;
            temp2.Var4=Pay.Note;
            temp2.Var5=Pay.Value;

            row=(temp2.Var2==string(app.CustomerDDFinR.Value));
            temp2=temp2(row,:);            


            temp= [temp;temp2];

            temp=sortrows(temp,'Var3');
            s3=size(temp);
            temp.Var1=(1:s3(1))';

            if isnan(temp.Var5(1))==false
                Bal(1,1)=temp.Var5(1);
            else
                Bal(1,1)=-temp.Var6(1);
            end

            for i=2:s3(1)
                if isnan(temp.Var5(i))==false
                    Bal(i,1)=Bal(i-1,1)+temp.Var5(i);
                else
                    Bal(i,1)=Bal(i-1,1)-temp.Var6(i);
                end
            end

            temp.Var7=Bal;

            temp2=round(temp.Var5,0);
            temp3=round(temp.Var6,0);
            temp4=round(temp.Var7,0);

            temp.Var5=string(temp.Var5);
            temp.Var6=string(temp.Var6);
            temp.Var7=string(temp.Var7);


            jf=java.text.DecimalFormat;
            for i=1:s3(1)
                if ismissing(temp.Var5(i))==true
                
                    temp.Var5(i)="";
                    temp.Var6(i)=char(jf.format(temp3(i)));
                    temp.Var7(i)=char(jf.format(temp4(i)));

                else

                    temp.Var5(i)=char(jf.format(temp2(i)));
                    temp.Var6(i)="";
                    temp.Var7(i)=char(jf.format(temp4(i)));
                end
            end


            app.USDT.Data=temp;

%% EURO
            PI=readtable('db\PI.csv');
            Inq=readtable('db\Inquiry.csv');
            Pay=readtable('db\Payment.csv');
%Withdraw
            row=logical((PI.State=="Approved")+(PI.State=="CI Issued")...
                +(PI.State=="Partial CI Issued")+(PI.State=="Partial Delivered")+(PI.State=="Delivered"));
            PI=PI(row,:);

            row=(PI.Currency=="EURO");
            PI=PI(row,:);
            
            if isempty(PI)==false
            
            s=size(PI);
            temp=array2table(nan(s(1),7));

            
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));
 
            end
           
            temp.Var2=Customer;
            temp.Var3=PI.ApprovedDate;
            temp.Var4=Name_Tag;
            temp.Var6=PI.Total;
            
            row=(temp.Var2==string(app.CustomerDDFinR.Value));
            temp=temp(row,:);
            end

%Deposite
            row=(Pay.Currency=="EURO");
            Pay=Pay(row,:); 

            if isempty(Pay)==false
            s2=size(Pay);
            temp2=array2table(nan(s2(1),7));

            
            temp2.Var2=Pay.Customer;
            temp2.Var3=Pay.Date;
            temp2.Var4=Pay.Note;
            temp2.Var5=Pay.Value;

            row=(temp2.Var2==string(app.CustomerDDFinR.Value));
            temp2=temp2(row,:);            


            temp= [temp;temp2];

            temp=sortrows(temp,'Var3');
            s3=size(temp);
            temp.Var1=(1:s3(1))';

            if isnan(temp.Var5(1))==false
                Bal(1,1)=temp.Var5(1);
            else
                Bal(1,1)=-temp.Var6(1);
            end

            for i=2:s3(1)
                if isnan(temp.Var5(i))==false
                    Bal(i,1)=Bal(i-1,1)+temp.Var5(i);
                else
                    Bal(i,1)=Bal(i-1,1)-temp.Var6(i);
                end
            end

            temp.Var7=Bal;

            temp2=round(temp.Var5,0);
            temp3=round(temp.Var6,0);
            temp4=round(temp.Var7,0);

            temp.Var5=string(temp.Var5);
            temp.Var6=string(temp.Var6);
            temp.Var7=string(temp.Var7);


            jf=java.text.DecimalFormat;
            for i=1:s3(1)
                if ismissing(temp.Var5(i))==true
                
                    temp.Var5(i)="";
                    temp.Var6(i)=char(jf.format(temp3(i)));
                    temp.Var7(i)=char(jf.format(temp4(i)));

                else

                    temp.Var5(i)=char(jf.format(temp2(i)));
                    temp.Var6(i)="";
                    temp.Var7(i)=char(jf.format(temp4(i)));
                end
            end


            app.EuroT.Data=temp;
            end

            Title1=strcat(string(app.CustomerDDFinR.Value),'- USD');
            Title2=strcat(string(app.CustomerDDFinR.Value),'- EURO');
            app.USDLabel.Text=Title1;
            app.EuroLabel.Text=Title2;
       
            end
                
       
        end

        % Button pushed function: OpenInqItemsButton_3
        function OpenInqItemsButton_3Pushed(app, event)

%% Open Inq Item Files Report tab
            if app.InqIDDDReport.Value == "ID"
            
            app.Notif_Report.Value = "Please Select Inquiry ID!";
            app.Notif_Report.FontColor= "Red";                
            
            else
            
            Inq=readtable("db\Inquiry.csv");
            row=(Inq.InqID==str2double(app.InqIDDDReport.Value));
            temp=string(Inq.InqItem(row,:));
            winopen(temp);

            app.Notif_Report.Value = "Done!";
            app.Notif_Report.FontColor= "Black"; 
            end

        end

        % Button pushed function: OpenPIButton_2
        function OpenPIButton_2Pushed(app, event)
%% Open PI  Report tab
            if app.InqIDDDReport.Value == "ID"
            
                app.Notif_Report.Value = "Please Select Inquiry ID!";
                app.Notif_Report.FontColor= "Red";                
            
            else
                
                %Inq=readtable("db\Inquiry.csv");
                PI=readtable("db\PI.csv");
    
                PIID=app.ReportT.Data.Var7(app.ReportT.Data.Var5==str2double(app.InqIDDDReport.Value));
                PIID = double(PIID);  % Convert PIID to double
                row=(PI.PIID==PIID);
                temp=string(PI.PIPath(row,:));
                winopen(temp);
    
                app.Notif_Report.Value = "Done!";
                app.Notif_Report.FontColor= "Black"; 
            end
            
        end

        % Button pushed function: OpenPOButton
        function OpenPOButtonPushed(app, event)
%% Open PO  Report tab
            if app.InqIDDDReport.Value == "ID"
            
            app.Notif_Report.Value = "Please Select Inquiry ID!";
            app.Notif_Report.FontColor= "Red";                
            
            else
            
            %Inq=readtable("db\Inquiry.csv");
            PI=readtable("db\PI.csv");

            PIID=app.ReportT.Data.Var7(app.ReportT.Data.Var5==str2double(app.InqIDDDReport.Value));
            PIID = double(PIID);  % Convert PIID to double
            row=(PI.PIID==PIID);
            temp=string(PI.POPath(row,:));
            winopen(temp);

            app.Notif_Report.Value = "Done!";
            app.Notif_Report.FontColor= "Black"; 
            end            
        end

        % Button pushed function: OpenCIButton_2
        function OpenCIButton_2Pushed(app, event)
%% Open CI  Report tab
            if app.InqIDDDReport.Value == "ID"
            
            app.Notif_Report.Value = "Please Select Inquiry ID!";
            app.Notif_Report.FontColor= "Red";                
            
            else
            
            CI=readtable("db\CI.csv");

            PIID=app.ReportT.Data.Var7(app.ReportT.Data.Var5==str2double(app.InqIDDDReport.Value));
            PIID = double(PIID);  % Convert PIID to double
            CI=CI(CI.PIID==PIID,:);
            s=size(CI);
            for i=1:s(1)
                winopen(string(CI.InvoicePath(i)));
            end

            app.Notif_Report.Value = "Done!";
            app.Notif_Report.FontColor= "Black"; 
            end                        
        end

        % Button pushed function: OpenPODButton
        function OpenPODButtonPushed(app, event)
%% Open POD  Report tab
            if app.InqIDDDReport.Value == "ID"
            
            app.Notif_Report.Value = "Please Select Inquiry ID!";
            app.Notif_Report.FontColor= "Red";                
            
            else
            
            CI=readtable("db\CI.csv");

            PIID=app.ReportT.Data.Var7(app.ReportT.Data.Var5==str2double(app.InqIDDDReport.Value));
            PIID = double(PIID);  % Convert PIID to double
            CI=CI(CI.PIID==PIID,:);
            s=size(CI);
            for i=1:s(1)
                winopen(string(CI.DeliveryApproval(i)));
            end

            app.Notif_Report.Value = "Done!";
            app.Notif_Report.FontColor= "Black"; 
            end                       
        end

        % Button pushed function: Excel_Report
        function Excel_ReportButtonPushed(app, event)
%% Export to excel Report Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                temp=app.ReportT.Data;
                temp.Properties.VariableNames=app.ReportT.ColumnName;
                writetable(temp,fullpathname);
                winopen(fullpathname);
            
            end                              
        end

        % Button pushed function: Excel_Fin
        function Excel_FinButtonPushed(app, event)
%% Export to excel Fin Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.PaymentT.Data,fullpathname);
                winopen(fullpathname);
            
            end                    
        end

        % Button pushed function: Excel_FinR_2
        function Excel_FinR_2ButtonPushed(app, event)
%% Export to excel Fin Report Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.USDT.Data,fullpathname);
                winopen(fullpathname);
            
            end                              
        end

        % Button pushed function: Excel_FinR
        function Excel_FinRButtonPushed(app, event)
%% Export to excel Fin Report Tab

            [file,path] = uiputfile('*.xlsx',"openfile") ;
            fullpathname=strcat(path,file);

            if fullpathname ~= 0
                delete(fullpathname);
                writetable(app.EuroT.Data,fullpathname);
                winopen(fullpathname);
            
            end                
        end

        % Button down function: UpdateTab
        function UpdateTabButtonDown(app, event)
            %% Startup Open PI
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');


            %Get Customer name

            s=size(PI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            PI=addvars(PI,Customer,'after','InqID');
            PI=addvars(PI,Name_Tag,'after','Customer');

            %Total 
            PI.Total=round(PI.Total,2);
            temp=PI.Total;
            PI.Total=string(PI.Total);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                PI.Total(i)=char(jf.format(temp(i)));
            end


            %Filter State=Open 
            Orows=(PI.State=="Open");
            OpenPI=PI(Orows,:);

            %Calculate Days
            PDays=days(date-OpenPI.Date);
            Valid=days(OpenPI.ValidityDate-date);
            
            vrows=(Valid<0);
            Valid=string(Valid);
            Valid(vrows,:)={'Expired'};
            
            
            %Insert Column 
            OpenPI=addvars(OpenPI,PDays,'after','Date');
            OpenPI=addvars(OpenPI,Valid,'after','ValidityDate');
            OpenPI=removevars(OpenPI,'State');
            
            
            %OpenInq Table UI
            app.OpenPITable.Data = OpenPI ;
            app.OpenPITable.ColumnName = OpenPI.Properties.VariableNames;


 %% Combo Box PIID in Open PI Tab
 
            ddown=app.OpenPITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.SelectPIIDDropDown.Items=ddown;
            app.IDOpenPIDD.Items=ddown;


            app.OpenPINotif.Value='Ready!';
            app.OpenPINotif.FontColor='Black';
 
%Reset
            app.SelectPIIDDropDown.Value = 'ID';
            app.IDOpenPIDD.Value = 'ID';

            app.ApprovedDateDatePicker.Value = NaT;
            app.ApPath.Value = '';


        end

        % Button down function: Update_Open_PITab
        function Update_Open_PITabButtonDown2(app, event)
 %% Startup Open PI
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');


            %Get Customer name

            s=size(PI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(i));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            PI=addvars(PI,Customer,'after','InqID');
            PI=addvars(PI,Name_Tag,'after','Customer');

            %Total 
            PI.Total=round(PI.Total,2);
            temp=PI.Total;
            PI.Total=string(PI.Total);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                PI.Total(i)=char(jf.format(temp(i)));
            end


            %Filter State=Open 
            Orows=(PI.State=="Open");
            OpenPI=PI(Orows,:);

            %Calculate Days
            PDays=days(date-OpenPI.Date);
            Valid=days(OpenPI.ValidityDate-date);
            
            vrows=(Valid<0);
            Valid=string(Valid);
            Valid(vrows,:)={'Expired'};
            
            
            %Insert Column 
            OpenPI=addvars(OpenPI,PDays,'after','Date');
            OpenPI=addvars(OpenPI,Valid,'after','ValidityDate');
            OpenPI=removevars(OpenPI,'State');
            
            
            %OpenInq Table UI
            app.OpenPITable.Data = OpenPI ;
            app.OpenPITable.ColumnName = OpenPI.Properties.VariableNames;


 %% Combo Box PIID in Open PI Tab
 
            ddown=app.OpenPITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.SelectPIIDDropDown.Items=ddown;
            app.IDOpenPIDD.Items=ddown;


            app.OpenPINotif.Value='Ready!';
            app.OpenPINotif.FontColor='Black';
 
%Reset
            app.SelectPIIDDropDown.Value = 'ID';
            app.IDOpenPIDD.Value = 'ID';

            app.ApprovedDateDatePicker.Value = NaT;
            app.ApPath.Value = '';

        end

        % Button down function: Update_Open_CITab
        function Update_Open_CITabButtonDown2(app, event)
            %% Startup Open PI
            app.InquiryTable_2.Data='';
            CI = readtable('db\CI.csv');
            PI = readtable('db\PI.csv');
            Inq = readtable('db\Inquiry.csv');

            %Get Customer name

            s=size(CI);
            for i=1:s(1)
            r=(Inq.InqID==PI.InqID(PI.PIID==CI.PIID(i)));
            Customer(i,1)=(Inq.Customer(r,:)); %#ok<AGROW> 
            Name_Tag(i,1)=(Inq.Name_Tag(r,:));%#ok<AGROW> 
            end
           
            %Insert Column 
            CI=addvars(CI,Customer,'after','PIID');
            CI=addvars(CI,Name_Tag,'after','Customer');

            %Total 
            CI.Total=round(CI.Total,2);
            temp=CI.Total;
            CI.Total=string(CI.Total);
            jf=java.text.DecimalFormat;
            for i=1:s(1)
                CI.Total(i)=char(jf.format(temp(i)));
            end


            %Filter State=Open 
            rows=logical((CI.State=="Complete")+(CI.State=="Partial"));
            OpenCI=CI(rows,:);

                        
            %OpenInq Table UI
            app.OpenCITable.Data = OpenCI ;
            app.OpenCITable.ColumnName = OpenCI.Properties.VariableNames;


 %% Combo Box PIID in Open PI Tab
 
            ddown=app.OpenCITable.Data(:,1);
            ddown=["ID";table2array(ddown)];
            app.SelectCIIDDropDown.Items=ddown;
            app.IDOpenCIDD.Items=ddown;


            app.OpenCINotif.Value='Ready!';
            app.OpenCINotif.FontColor='Black';
 
%Reset
            app.SelectCIIDDropDown.Value = 'ID';
            app.IDOpenCIDD.Value = 'ID';

            app.ApprovedCIDatePicker.Value = NaT;
            app.ApCIPath.Value = '';
        end

        % Button down function: Setup_CustomerTab
        function Setup_CustomerTabButtonDown(app, event)
            %% Read CSV
            %read db\Customer.csv         
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cu = readtable('db\Customer.csv',opts);

            %read db\Operator.csv
            opts2 = detectImportOptions('db\Operator.csv');
            opts2 = setvartype(opts2,{'CellPhone'},'char');
            Op = readtable('db\Operator.csv',opts2);      
           

%% UI table
            %UI.table customer tab:setup
            app.UITable_4.Data=Cu;
            app.UITable_4.ColumnName = Cu.Properties.VariableNames;
            
            %UI.table operator tab:setup
            app.UITable_5.Data = Op;
            app.UITable_5.ColumnName = Op.Properties.VariableNames;  


%% Combo Box

            %Combo Box Ac Manager in Customer & Inquiry Tabs
            %ddown=zeros(size(Op)-1);
            for i=1:size(Op)
                ddown(i,1)=Op(i,2); %#ok<AGROW> 
            end
            ddown=["Select";table2array(ddown)];
            app.AcManagerDropDown_2.Items=ddown;

%% Resat UI
            app.NameEditField_5.Value='';
            app.AddressEditField_4.Value='';
            app.TelEditField_4.Value='';
            app.WebsiteEditField_4.Value='';
            app.AttachmentEditField_5.Value='';
            app.CorresponderCellPhoneEditField_2.Value='';
            app.CorresponderEditField_2.Value='';
            app.AcManagerDropDown_2.Value="Select";

        end

        % Button down function: Setup_OperatorTab
        function Setup_OperatorTabButtonDown(app, event)
            %read db\Operator.csv
            opts2 = detectImportOptions('db\Operator.csv');
            opts2 = setvartype(opts2,{'CellPhone'},'char');
            Op = readtable('db\Operator.csv',opts2);

            %app.UserRoleDropDown.Items=["Select";UR];
            app.UserDropDown.Items=["Select";Op.Name];
            %UI.table operator tab:setup
            app.UITable_2.Data = Op;
            app.UITable_2.ColumnName = Op.Properties.VariableNames;  
            
            
            %% Resat UI
            app.NameEditField_2.Value='';
            app.CellPhoneEditField.Value='';
            app.NewPasswordEditField.Value="";
            app.UserDropDown.Value="Select";
        end

        % Button down function: Setup_CompanyTab
        function Setup_CompanyTabButtonDown(app, event)
            %read db\Company.csv
            opts3 = detectImportOptions('db\Company.csv');
            opts3 = setvartype(opts3,{'Tel'},'char');
            Comp = readtable('db\Company.csv',opts3);


            %UI.table Company tab:setup
            app.UITable_3.Data = Comp ;
            app.UITable_3.ColumnName = Comp.Properties.VariableNames;  
            %% Resat UI
            app.NameEditField_3.Value='';
            app.AddressEditField_2.Value='';
            app.TelEditField_2.Value='';
            app.WebsiteEditField_2.Value='';
            app.AttachmentEditField_2.Value='';
        end

        % Button down function: Setup_SupplierTab
        function Setup_SupplierTabButtonDown(app, event)
            
            %read db\Supplier.csv
            opts4 = detectImportOptions('db\Supplier.csv');
            opts4 = setvartype(opts4,{'Tel'},'char');
            Sup = readtable('db\Supplier.csv',opts4);


%% UI table

            %UI.table Supplier tab:setup
            app.UItableSupplier.Data = Sup ;
            app.UItableSupplier.ColumnName = Sup.Properties.VariableNames;  

%% Resat UI
            app.NameEditField_4.Value='';
            app.AddressEditField_3.Value='';
            app.TelEditField_3.Value='';
            app.WebsiteEditField_3.Value='';
            app.AttachmentEditField_3.Value='';


        end

        % Button down function: Setup_OtherTab
        function Setup_OtherTabButtonDown(app, event)
            %% Other tab Startup
            PT = readtable('db\PaymentTerms.csv','format','auto');
            C=readtable("db\Currency.csv");
            %UR=readtable("db\ACCT.csv");
            %UR=UR.Properties.VariableNames';
            %UR(1)=[];

            app.PTtable.Data=PT;
            %app.Userrole.Data=UR;
            
            app.Ctable.Data= C;
            app.Ctable.ColumnName= C.Properties.VariableNames;

            app.OtherNotif.Value = "Ready!";
            app.OtherNotif.FontColor= "Black";


            app.NewCurrency.Value="";
            app.NewPT.Value="";
            %app.NewRole_2.Value="";


        end

        % Button down function: Setup_Access_ControlTab
        function Setup_Access_ControlTabButtonDown(app, event)
            %% Access Control


                ACCT=readtable("db\ACCT.csv");
                s=size(ACCT);
              
                for i=2:s(2)
                ACCT.(string(ACCT.Properties.VariableNames(i)))=ACCT.(string(ACCT.Properties.VariableNames(i)))==1;
                end
                app.ACCTable.Data= ACCT;
                app.ACCTable.ColumnName= ACCT.Properties.VariableNames;


        end

        % Button down function: SetupTab
        function SetupTabButtonDown2(app, event)
            %% Read CSV
            %read db\Customer.csv         
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cu = readtable('db\Customer.csv',opts);

            %read db\Operator.csv
            opts2 = detectImportOptions('db\Operator.csv');
            opts2 = setvartype(opts2,{'CellPhone'},'char');
            Op = readtable('db\Operator.csv',opts2);      
            app.NotifLabel.Text = "";


%% UI table
            %UI.table customer tab:setup
            app.UITable.Data=Cu;
            app.UITable.ColumnName = Cu.Properties.VariableNames;
            
            %UI.table operator tab:setup
            app.UITable_2.Data = Op;
            app.UITable_2.ColumnName = Op.Properties.VariableNames;  


%% Combo Box

            %Combo Box Ac Manager in Customer & Inquiry Tabs
            %ddown=zeros(size(Op)-1);
            for i=1:size(Op)
                ddown(i,1)=Op(i,2); %#ok<AGROW> 
            end
            ddown=["Select";table2array(ddown)];
            app.AcManagerDropDown.Items=ddown;

%% Resat UI
            app.NameEditField.Value='';
            app.AddressEditField.Value='';
            app.TelEditField.Value='';
            app.WebsiteEditField.Value='';
            app.AttachmentEditField.Value='';
            app.CorresponderCellPhoneEditField.Value='';
            app.CorresponderEditField.Value='';
            app.AcManagerDropDown.Value="Select";
        end

        % Button pushed function: AddNewPayT
        function AddNewPayT_2ButtonPushed(app, event)
            %% Add new Payment Terms
            PT = readtable('db\PaymentTerms.csv','format','auto');
            %PT = PT(:, 2);
            NPT={app.NewPT.Value};
            %NPT = table({app.NewPT_3.Value}, 'VariableNames', PT.Properties.VariableNames);

            PT = [PT;NPT];

            app.PTtable.Data= PT;
            writetable(PT,'db\PaymentTerms.csv');

            app.OtherNotif.Value = "Done!";
            app.OtherNotif.FontColor= "Black";

            app.NewPT.Value='';

        end

        % Button pushed function: AddNewCurr
        function AddNewCurr_2ButtonPushed(app, event)
        %% New Currency
            C=readtable("db\Currency.csv");
            
            NC={app.NewCurrency_3.Value};

            C = [C;NC];

            app.Ctable_3.Data= C;
            writetable(C,'db\Currency.csv');

            app.OtherNotif_3.Value = "Done!";
            app.OtherNotif_3.FontColor= "Black";

            app.NewCurrency_3.Value='';            

        end

        % Button pushed function: AddnewOperatorButton
        function AddnewOperatorButton_2Pushed(app, event)
            %% Add new Operator

           %set table read format
            opts2 = detectImportOptions('db\Operator.csv');
            opts2 = setvartype(opts2,{'CellPhone'},'char');
            Op = readtable('db\Operator.csv',opts2);
            % Validate the Name field
            newName = app.NameEditField_6.Value;
            if isempty(app.NameEditField_6.Value)
                app.EditField_6.Value = "Please enter the username of operator.";
                app.EditField_6.FontColor = "red";
                return; % Stop execution if Name is empty
            elseif ismember(lower(newName), lower(Op.Name)) % Assuming the 'Name' column exists
                app.EditField_6.Value = "This username already exists. Please enter a different username.";
                app.EditField_6.FontColor = "red";
                return; % Stop execution if duplicate Name
            end

            % Validate the Cell Phone field (only numbers allowed)
            cellPhone = app.CellPhoneEditField_2.Value;
            if isempty(regexp(cellPhone, '^\d+$', 'once')) % Regular expression for numbers only
                app.EditField_6.Value = "The cell phone field must contain only numbers.";
                app.EditField_6.FontColor = "red";
                return; % Stop execution if invalid Cell Phone
            end

            %Generate password hash default=username(Phash)
            p=app.NameEditField_6.Value;
            sha256hasher = System.Security.Cryptography.SHA256Managed;
            sha256 = uint8(sha256hasher.ComputeHash(uint8(p)));
            temp=dec2hex(sha256);
            
            Phash="";
            for i=1:size(temp)
                for j=1:2
                    Phash=strcat(Phash,temp(i,j));
                end
            end

            NewOp={Op{end,1}+1 ...
                app.NameEditField_6.Value ...
                app.CellPhoneEditField_2.Value ...
                Phash};
            Op = [Op;NewOp];
            app.UITable_5.Data= Op;
            writetable(Op,'db\Operator.csv');
            % Update the ACCT database
            % Read the ACCT database
            ACCT = readtable('db\ACCT.csv');
            s=size(ACCT);
            c=s(2)+1;
            temp2=array2table(zeros(s(1),1));
            ACCT=addvars(ACCT,temp2.Var1);
            ACCT.Properties.VariableNames(string(ACCT.Properties.VariableNames(c)))={app.NameEditField_6.Value};
        
            % Add the new operator's username as a column
            %ACCT.(app.NameEditField_6.Value) = {''}; % Initialize column with empty values
        
            % Save the updated ACCT database
            writetable(ACCT, 'db\ACCT.csv');
            %text
            app.EditField_6.Value = "Done!";
            app.EditField_6.FontColor= "black";
            
            app.Setup_OperatorTabButtonDown;
            
        end

        % Button pushed function: ChangePasswordButton
        function ChangePasswordButtonPushed(app, event)
            Op = readtable('db\Operator.csv');

            if app.UserDropDown.Value ~= "Select"
                p=app.NewPasswordEditField.Value;
                sha256hasher = System.Security.Cryptography.SHA256Managed;
                sha256 = uint8(sha256hasher.ComputeHash(uint8(p)));
                temp=dec2hex(sha256);
                
                Phash="";
                for i=1:size(temp)
                    for j=1:2
                        Phash=strcat(Phash,temp(i,j));
                    end
                end
                row=(Op.Name==string(app.UserDropDown.Value));
                Op.Hashedpass(row,:)={Phash};
            end

            writetable(Op,'db\Operator.csv');
            app.Setup_OperatorTabButtonDown;
            %Generate password hash default=username(Phash)
        end

        % Button pushed function: SelectFileButton
        function SelectFileButton_8Pushed(app, event)
            %% select attachment add tab:Customer
            %value = app.SelectFileButton_3.Value;
            [file,path] = uigetfile('*.*',"openfile") ;
            fullpathname=strcat(path,file);
            app.AttachmentEditField_5.Value=fullpathname;   
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';       
        end

        % Button pushed function: AddnewCustomerButton
        function AddnewCustomerButton_2Pushed(app, event)
            %% Add New Customer
        % Set table read format
            opts = detectImportOptions('db\Customer.csv');
            opts = setvartype(opts,{'Tel','CorresponderCellPhone'},'char');
            Cu = readtable('db\Customer.csv',opts);
            
        % Check select in combo box
            if string(app.AcManagerDropDown_2.Value) == "Select"
                app.TrackingNumberEditField.Value = "Please select Ac manager";
                app.TrackingNumberEditField.FontColor= "Red";

            
            elseif string(app.AcManagerDropDown_2.Value) ~= "Select"
                  
                NewCu={Cu{end,1}+1 ...
                  app.NameEditField_5.Value ...
                  app.AddressEditField_4.Value ...
                  app.TelEditField_4.Value ...
                  app.WebsiteEditField_4.Value ...
                  app.AcManagerDropDown_2.Value ...
                  app.CorresponderEditField_2.Value ...
                  app.CorresponderCellPhoneEditField_2.Value ...
                  app.AttachmentEditField_5.Value};
                Cu = [Cu;NewCu];
                app.UITable_4.Data= Cu;
                writetable(Cu,'db\Customer.csv');
                %text 
                app.TrackingNumberEditField.Value = "Done!";
                app.TrackingNumberEditField.FontColor= "black";
                %clear attach field
                app.AttachmentEditField_5.Value = '';
            end
           
            app.Setup_CustomerTabButtonDown;
        end

        % Button pushed function: SelectFileButton_2
        function SelectFileButton_10Pushed(app, event)
            %% select attachment add tab:comapny
            %value = app.SelectFileButton.Value;
            [file,path] = uigetfile('*.*',"openfile") ;
            fullpathname=strcat(path,file);
            app.AttachmentEditField_7.Value=char(fullpathname);
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';    
        end

        % Button pushed function: AddnewCompanyButton
        function AddnewCompanyButton_2Pushed(app, event)
            %% Add new Company

        %set table read format
            opts3 = detectImportOptions('db\Company.csv');
            opts3 = setvartype(opts3,{'Tel'},'char');
            Co = readtable('db\Company.csv',opts3);

            NewCo={Co{end,1}+1 ...
                app.NameEditField_7.Value ...
                app.AddressEditField_5.Value ...
                app.TelEditField_5.Value ...
                app.WebsiteEditField_5.Value ...
                app.AttachmentEditField_7.Value
                };
            Co = [Co;NewCo];
            app.UITable_6.Data= Co;
            writetable(Co,'db\Company.csv');
            %text
            app.EditField_7.Value = "Done!";
            app.EditField_7.FontColor= "black";
            %%clear attach field
            app.AttachmentEditField_7.Value='';

            app.Setup_CompanyTabButtonDown;
        end

        % Button pushed function: SelectFileButton_3
        function SelectFileButton_11Pushed(app, event)
            %% select attachment add tab:Supplier
            %value = app.SelectFileButton_3.Value;
            [file,path] = uigetfile('*.*',"openfile") ;
            fullpathname=strcat(path,file);
            app.AttachmentEditField_8.Value=fullpathname;
            app.ViraEPCUIFigure.Visible = 'off';
            app.ViraEPCUIFigure.Visible = 'on';       
        end

        % Button pushed function: AddnewSupplierButton
        function AddnewSupplierButton_2Pushed(app, event)
            %% Add new Supplier

        %set table read format
            opts4 = detectImportOptions('db\Supplier.csv');
            opts4 = setvartype(opts4,{'Tel'},'char');
            Su = readtable('db\Supplier.csv',opts4);

            NewSu={Su{end,1}+1 ...
                app.NameEditField_8.Value ...
                app.AddressEditField_7.Value ...
                app.TelEditField_7.Value ...
                app.WebsiteEditField_7.Value ...
                app.AttachmentEditField_8.Value
                };
            Su = [Su;NewSu];
            app.UItableSupplier_2.Data= Su;
            writetable(Su,'db\Supplier.csv');
            %text
            app.EditField_8.Value = "Done!";
            app.EditField_8.FontColor= "black";
            %%clear attach field
            app.AttachmentEditField_8.Value='';   

            app.Setup_SupplierTabButtonDown;
        end

        % Button pushed function: SaveButton_2
        function SaveButton_3Pushed(app, event)
            writetable(app.ACCTable.Data,'db\ACCT.csv');
            app.NotifLabel.Text = "Done";
            pause(2);
            app.NotifLabel.Text = "";


            app.Setup_Access_ControlTabButtonDown
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

          progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Loading...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);
            
                    pause (1);
                    function CenterLoginPanel(app)
                        %Get the size of the app window (figure)
                        appWindowSize = app.ViraEPCUIFigure.Position(3:4); % Width and Height of the app window
                        
                        fixedPanelWidth = 300; 
                        fixedPanelHeight = 150; 
                        
                        %Calculate the position to center the panel
                        newX = (appWindowSize(1) - fixedPanelWidth) / 2;
                        newY = (appWindowSize(2) - fixedPanelHeight) / 2;
                        
                        %Update the panel's position
                        app.ViraEPCLoginPanel.Position = [newX, newY, fixedPanelWidth, fixedPanelHeight];
                    end
            
                    switchState = app.Switch.Value;
                    if strcmp(switchState, 'Log in')
                        % Show login panel
                        app.ViraEPCLoginPanel.Visible = true;
                        % Center the panel
                        CenterLoginPanel(app);                
                        app.NoticeLabel.Text = ''; % Clear any messages
                        app.PasswordEditField.Value = '';
                    else
                        % Log out logic
                        app.TabGroup.Visible = false; % Hide all tabs
                        app.ViraEPCLoginPanel.Visible = false; % Hide login panel

                        % Center the panel
                        CenterLoginPanel(app);
                        app.Switch.Value = 'Log out'; % Reset switch
                        app.NoticeLabel.Text = ''; % Clear any messages
                        app.UserLabel.Text = '';
                    end
          close(progressDlg);

        end

        % Button pushed function: Button_4
        function Button_4Pushed2(app, event)
        
          progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Loading...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);
            pause(1);
            u = lower(app.UsernameEditField_2.Value);
            p = app.PasswordEditField.Value;
            %p = app.PasswordHidden;
            % Read the access control and operator data
            ACCT = readtable("db\ACCT.csv");
            user1 = detectImportOptions('db\Operator.csv');
            user1 = setvartype(user1, {'CellPhone'}, 'char');
            user = readtable('db\Operator.csv', user1);
            
            % Call saved hashed password
            Hashedpass = user.Hashedpass(lower(user.Name) == string(u));
            %Generate password hash (Phash)
            sha256hasher = System.Security.Cryptography.SHA256Managed;
            sha256 = uint8(sha256hasher.ComputeHash(uint8(p)));
            temp=dec2hex(sha256);

            Phash="";
            for i=1:size(temp)
                for j=1:2
                    Phash=strcat(Phash,temp(i,j));
                end
            end
            
            % Verify password
            if strcmp(Hashedpass, Phash)
                % Valid login
                % Check if the password is the default password (1)
                if strcmp(p, '1')
                    % First-time login with default password
                    app.NoticeLabel.Text = "Correct, Now Please Set a New Password.";
                    app.NoticeLabel.FontColor = "Blue";
                    app.NewPassEditField.Visible = true;  % Show the new password field
                    app.NewPassLabel.Visible = true;
                    app.PasswordEditField.Visible = false;
                    app.PasswordLabel.Visible = false;
                    app.Button_4.Visible = false;
                    app.Button_5.Visible = true;
                    app.NewPassEditField.Value = "";  % Clear the new password field
                    app.UserLabel.Text = app.UsernameEditField_2.Value;  % Display logged-in user
                    app.ViraEPCLoginPanel.Visible = true;  
                    app.Switch.Value = 'Log in';  % Update switch button
                    app.TabGroup.Visible = false;  
                else
                    % Valid login with non-default password
                    app.UserLabel.Text = app.UsernameEditField_2.Value;  % Display logged-in user
                    app.ViraEPCLoginPanel.Visible = false;  % Hide login panel
                    app.Switch.Value = 'Log in';  % Update switch button
                    app.TabGroup.Visible = true;  % Show the TabGroup
                    app.NoticeLabel.Text = '';  % Clear any previous messages
                    %app.NewPassEditField.Visible = false;  % Ensure the new password field is hidden
                end

                % Set Tabs for the user based on the access control (ACCT) table
                userColumn = find(strcmpi(ACCT.Properties.VariableNames, string(u)));

                accesscol = ACCT{:, userColumn};
                % Loop through each tab and set visibility
                tabs = {'Home', 'Entry_Inquiry', 'Issue_PI', 'Issue_CI', 'Update_Open_PI', 'Update_Open_CI', ...
                        'Report', 'Delete_Inquiry', 'Financial_Payment', 'Financial_Report', ...
                        'Setup_Customer', 'Setup_Operator', 'Setup_Company', 'Setup_Supplier', ...
                        'Setup_Access_Control', 'Setup_Other', 'Setup_User_Setting'};


                for i = 1:length(tabs)
                    tabName = tabs{i};
                    if accesscol(i) == 0 %false
                        app.([tabName 'Tab']).Parent = [];  % Hide tab if no access
                    end
                    if accesscol(5) == 0 && accesscol(6) == 0 % Check both conditions for false
                        app.UpdateTab.Parent = [];  % Hide tab if no access
                    end
                    
                    if accesscol(9) == 0 && accesscol(10) == 0 % Check both conditions for false
                        app.FinancialTab.Parent = [];  % Hide tab if no access
                    end
                    
                    if accesscol(11) == 0 && accesscol(12) == 0 && accesscol(13) == 0 && ...
                       accesscol(14) == 0 && accesscol(15) == 0 && accesscol(16) == 0 && accesscol(17) == 0 % Check all conditions for false
                        app.SetupTab.Parent = [];  % Hide tab if no access
                
                    % else 
                     %   app.([tabName 'Tab']).Parent = app.TabGroup;
                    end
                end
             
                
            else
                % Invalid credentials
                app.NoticeLabel.Text = "Username or Password is incorrect.";
                app.NoticeLabel.FontColor = "Red";
                app.UsernameEditField_2.Value = "";
                app.PasswordEditField.Value = "";
            end
          close(progressDlg);
            
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
        % Get the entered username, current password, and new password
            u = app.UsernameEditField_2.Value;    
            currentPassword = app.CurrentPasswordEditField.Value;
            newPassword = app.NewPasswordEditField_2.Value;
            %newPassword = app.NewPasswordHidden;
        
            % Read the operator data
            userOptions = detectImportOptions('db\Operator.csv');
            userOptions = setvartype(userOptions, {'CellPhone'}, 'char');
            user = readtable('db\Operator.csv', userOptions);
        
            % Find the user's hashed password
            row = strcmp(lower(user.Name), lower(string(u))); % Logical index for the user
            if ~any(row)
                app.Label_2.Text = 'User not found!';
                app.Label_2.FontColor = 'red';
                return;
            end
        
            hashedPass = user.Hashedpass{row};
        
            % Generate hash of the current password (Phash)
            sha256hasher = System.Security.Cryptography.SHA256Managed;
            sha256 = uint8(sha256hasher.ComputeHash(uint8(currentPassword)));
            temp = dec2hex(sha256);
        
            currentHashedPass = "";
            for i = 1:size(temp, 1)
                for j = 1:2
                    currentHashedPass = strcat(currentHashedPass, temp(i, j));
                end
            end
        
            % Verify current password
            if ~strcmp(hashedPass, currentHashedPass)
                app.Label_2.Text = 'Wrong Current Password!';
                app.Label_2.FontColor = 'red';
                pause (2);
                app.Label_2.Text = '';
                return;
            end
        
            % Validate new password requirements
            if strlength(newPassword) < 8
                app.Label_2.Text = 'New password must be at least 8 characters!';
                app.Label_2.FontColor = 'red';
                pause (2);
                app.Label_2.Text = '';
                return;
            end
        
            % Generate hash of the new password
            sha256 = uint8(sha256hasher.ComputeHash(uint8(newPassword)));
            temp = dec2hex(sha256);
        
            newHashedPass = "";
            for i = 1:size(temp, 1)
                for j = 1:2
                    newHashedPass = strcat(newHashedPass, temp(i, j));
                end
            end
        
            % Update the password in the table
            user.Hashedpass{row} = newHashedPass;
        
            % Save the updated table back to the file
            writetable(user, 'db\Operator.csv');
        
            % Notify the user of success
            app.Label_2.Text = 'Password changed successfully!';
            app.Label_2.FontColor = 'Green';
            pause (2);
            app.Label_2.Text = '';
            % Clear the input fields
            app.UsernameEditField_2.Value = '';
            app.CurrentPasswordEditField.Value = '';
            app.NewPasswordEditField_2.Value = '';
     
        end

        % Value changed function: VisibleCheckBox
        function VisibleCheckBoxValueChanged(app, event)
            if app.VisibleCheckBox.Value
                % If the checkbox is checked, show the password in black
                app.PasswordEditField.FontColor = [0, 0, 0];
                app.NewPassEditField.FontColor = [0, 0, 0]; % Black color
            else
                % If the checkbox is unchecked, hide the password by making it white
                app.PasswordEditField.FontColor = [1, 1, 1]; % White color
                app.NewPassEditField.FontColor = [1, 1, 1];
            end

        end

        % Value changed function: VisibleCheckBox_2
        function VisibleCheckBox_2ValueChanged(app, event)
            if app.VisibleCheckBox_2.Value
                % If the checkbox is checked, show the password in black
                app.NewPasswordEditField_2.FontColor = [0, 0, 0]; % Black color
            else
                % If the checkbox is unchecked, hide the password by making it white
                app.NewPasswordEditField_2.FontColor = [1, 1, 1]; % White color
            end

        end

        % Button down function: Setup_User_SettingTab
        function Setup_User_SettingTabButtonDown(app, event)
            app.Label_2.Text = '';
        end

        % Value changed function: AutoGegerateCheckBox
        function AutoGegerateCheckBoxValueChanged(app, event)
            value = app.AutoGegerateCheckBox.Value;
            if value==0
                app.CInqID.Enable="on";
            else
                app.CInqID.Enable="off";
                app.CInqID.Value='InqID';
            end
        end

        % Button pushed function: AddLineButton_2
        function AddLineButton_2Pushed(app, event)
            %%Add Extra Item to the Inq
            if string(app.PNEditField.Value)==""
                app.Notif_Inq.Value="PN is empty";
                app.Notif_Inq.FontColor="Red";
            elseif app.QTYEditField_2.Value==0
                app.Notif_Inq.Value="QTY is zero";
                app.Notif_Inq.FontColor="Red";
            else

                if app.BrandEditField.Value==""
                    app.BrandEditField.Value='---';
                end

                if app.DesEditField.Value==""
                    app.DesEditField.Value='---';
                end
                   
                
                s=size(app.InqItemTable.Data);
                ID=s(1)+1;
                temp={ID,...
                    app.PNEditField.Value,...
                    app.QTYEditField_2.Value,...
                    app.BrandEditField.Value,...
                    app.DesEditField.Value};
                

                app.InqItemTable.Data=[table2cell(app.InqItemTable.Data);temp];
                app.InqItemTable.Data = cell2table(app.InqItemTable.Data, ...
                    'VariableNames', {'ID', 'PN', 'QTY', 'Brand', 'Description'});
               
                
                app.Notif_Inq.Value="New line added";
                app.Notif_Inq.FontColor="Black";
                pause(2);
                app.Notif_Inq.Value="";


                app.PNEditField.Value="";
                app.QTYEditField_2.Value=0;
                app.BrandEditField.Value="";
                app.DesEditField.Value="";

            end

        end

        % Value changed function: OpenInqIDDropDown
        function OpenInqIDDropDownValueChanged(app, event)
            value = app.OpenInqIDDropDown.Value;
            %% Load Items

            if  string(value) ~= "ID"
                %File Path
                tempID=str2double(value);
                InqID=tempID;
                Filename=num2str(InqID); 
                Filename= ['.\Files\' Filename '-' 'InquiryItems.csv'];
                InqItem=readtable(Filename);
                
                %Read Quantity
                app.OpenInquiryQTY.Data=InqItem.Quantity;
    
                %Read data for first 2 column
                SizeInqItem=size(InqItem);
                Temp=array2table(zeros(SizeInqItem(1),7));
                Temp.Var1=InqItem.x_;
                Temp.Var2=InqItem.PartNumber;
                %Update UI
                app.OpenInq_PQTable.Data=Temp;

                %Set Notif
                app.Notif_OpenInq.Value = "Done!";
                app.Notif_OpenInq.FontColor= "Black";
                 

            elseif  string(value) == "ID"
                %Set Notif
                app.Notif_OpenInq.Value = "Please Select Inquiry ID";
                app.Notif_OpenInq.FontColor= "Red";

            end
  
        end

        % Value changed function: PIIDDD
        function PIIDDDValueChanged(app, event)
            value = app.PIIDDD.Value;
            %% Load Items Issue Invoice Tab

            app.PIItemTable.Data=[];
            app.InItemTable.Data=[];
            app.PIIDfix.Value=str2double(app.PIIDDD.Value);

            if  string(app.PIIDDD.Value) == "ID"
                app.IssueInvoiceNotif.Value='Please Select PI ID!';
                app.IssueInvoiceNotif.FontColor='Red';

            else
                %File Path
                PIID=str2double(app.PIIDDD.Value);
                temp=app.AppPITable.Data((app.AppPITable.Data.PIID==PIID),:);
                
                opts = detectImportOptions(char(temp.ItemPath));
                opts = setvartype(opts,{'PN'},'char');
                temp = readtable(char(temp.ItemPath),opts);     
                
                %temp.Price=round(temp.Price,3);
                %Fix Possible error in Total Calc 
                temp.Total=temp.Quantity.*temp.Price;

                %Fix Sort problem
                temp= sortrows(temp,'Cline');
               
                %Delete zero total Line
                rows=(temp.Total<=0);
                temp(rows,:)=[];
                
                %Delete Extra Variables and Select Logical Column
                temp=removevars(temp,{'PNC','DiffQuantity'});
                s=size(temp);
                Select=false(s(1),1);
                temp=addvars(temp,Select,'After','Total');
    
                app.PIItemTable.Data=temp;
                app.PIItemTable.ColumnName = temp.Properties.VariableNames;
  
            end

            
        end

        % Button pushed function: AddLineButton_3
        function AddLineButton_3Pushed(app, event)

            %%Add Extra Item to the CI
            if string(app.LineNameEditField_2.Value)==""
                app.IssueInvoiceNotif.Value="Line name is empty";
                app.IssueInvoiceNotif.FontColor="Red";
            elseif app.ValueEditField_2.Value==0
                app.IssueInvoiceNotif.Value="Value is empty";
                app.IssueInvoiceNotif.FontColor="Red";
            elseif app.QTYEditField_3.Value==0
                app.IssueInvoiceNotif.Value="Quantity is empty";
                app.IssueInvoiceNotif.FontColor="Red";
            else
            
                temp=array2table(zeros(1,5));
                temp.Var1=string(temp.Var1);
                temp.Var1(1)="***";
                temp.Var2=string(temp.Var2);
                temp.Var2(1)=app.LineNameEditField_2.Value;
                temp.Var3(1)=app.QTYEditField_3.Value;
                temp.Var4(1)=app.ValueEditField_2.Value;
                temp.Var5=temp.Var4.*temp.Var3;
            
                if size(app.NewInLine.Data)==0
                    app.NewInLine.Data=temp;
                    app.IssueInvoiceNotif.Value="New line added to the Invoice";
                    app.IssueInvoiceNotif.FontColor="Black";
                else
                    app.NewInLine.Data=[app.NewInLine.Data;temp];
                    app.IssueInvoiceNotif.Value="New line added to the Invoice";
                    app.IssueInvoiceNotif.FontColor="Black";
                end
            end


        end

        % Value changing function: NewPassEditField
        function NewPassEditFieldValueChanging(app, event)

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)

                progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                    'Title', 'Loading...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);
                    pause (1);
                    newPassword = app.NewPassEditField.Value;
            
                    % Validate the new password (e.g., length, complexity)
                    if length(newPassword) < 8
                        app.NoticeLabel.Text = "Password must be at least 8 characters long.";
                        app.NoticeLabel.FontColor = "Red";
                        return;
                    end
                    
                    % Hash the new password
                    sha256hasher = System.Security.Cryptography.SHA256Managed;
                    sha256 = uint8(sha256hasher.ComputeHash(uint8(newPassword)));
                    temp = dec2hex(sha256);
                    
                    newHashedPass = "";
                    for i = 1:size(temp)
                        for j = 1:2
                            newHashedPass = strcat(newHashedPass, temp(i, j));
                        end
                    end
                    
                    % Update the user's password in the database
                    userr = readtable('db\Operator.csv');
                    userIndex = find(strcmpi(userr.Name, app.UserLabel.Text));
                    userr.Hashedpass(userIndex) = {newHashedPass};
                    writetable(userr, 'db\Operator.csv');
                    
                    % Notify the user and hide the NewPassEditField
                    app.NoticeLabel.Text = "Password updated successfully!";
                    app.NoticeLabel.FontColor = "Green";
                    app.NewPassEditField.Visible = false;
                    app.NewPassLabel.Visible = false;
                    app.NewPassEditField.Value = "";
                    app.PasswordEditField.Value = '';
                    app.PasswordEditField.Visible = true;
                    app.PasswordLabel.Visible = true;
                    pause (2);
                    app.NoticeLabel.Text = "";
                    app.ViraEPCLoginPanel.Visible = false;
                    app.TabGroup.Visible = true;
                    app.Button_5.Visible = false;
                    app.Button_4.Visible = true;

                close(progressDlg);

        end

        % Button down function: CargoTab
        function CargoTabButtonDown(app, event)
            app.ViraPurchaseTabButtonDown;
        end

        % Button down function: ViraPurchaseTab
        function ViraPurchaseTabButtonDown(app, event)
            app.ViraPurchaseUITable.Data = '';
            app.PaymentDateDatePicker.Value = NaT;
            app.DeterminethePIIDDropDown.Value = "PIID";
            app.SelecttheSourceDropDown.Value = "Source";
            PurchaseDatabase = readtable('db\Purchase.csv');
            SupplierData = readtable('db\Supplier.csv');
            
            
            % Define the priority order for Line Status
            statusOrder = {'R2P', 'Purchased', 'Partial Arrived', 'Arrived'};
            PurchaseDatabase.Line_Status = categorical(PurchaseDatabase.Line_Status, statusOrder, 'Ordinal', true);
            
            % Group by PIID, InqID, Customer, Tag, and Line to find the highest priority status for each Line
            [groupVars, PIID, InqID, Customer, Tag, Line] = findgroups(...
                PurchaseDatabase.PIID, ...
                PurchaseDatabase.InqID, ...
                PurchaseDatabase.Customer, ...
                PurchaseDatabase.Tag, ...
                PurchaseDatabase.Line);
            
            % Determine the highest priority status (minimum categorical value) for each group
            minStatus = splitapply(@min, PurchaseDatabase.Line_Status, groupVars);
            
            % Create a grouped table with the highest priority status per Line
            groupedTable = table(PIID, InqID, Customer, Tag, Line, minStatus);
            
            % Regroup by PIID, InqID, Customer, Tag to calculate totals
            [groupVars2, PIID, InqID, Customer, Tag] = findgroups(...
                groupedTable.PIID, ...
                groupedTable.InqID, ...
                groupedTable.Customer, ...
                groupedTable.Tag);
            
            % Calculate Total Lines as the maximum Line number in each group
            TotalLines = splitapply(@max, groupedTable.Line, groupVars2);
            
            % Initialize counters for each status
            TotalR2P = zeros(size(TotalLines));
            TotalPurchased = zeros(size(TotalLines));
            TotalPartialArrived = zeros(size(TotalLines));
            TotalArrived = zeros(size(TotalLines));
            
            % Loop through each group to count statuses
            for i = 1:length(TotalLines)
                groupRows = (groupVars2 == i);
                currentStatuses = groupedTable.minStatus(groupRows);
                
                TotalR2P(i) = sum(currentStatuses == 'R2P');
                TotalPurchased(i) = sum(currentStatuses == 'Purchased');
                TotalPartialArrived(i) = sum(currentStatuses == 'Partial Arrived');
                TotalArrived(i) = sum(currentStatuses == 'Arrived');
            end
            
            % Create the result table with desired columns
            resultTable = table(PIID, InqID, Customer, Tag, TotalLines, TotalR2P, TotalPurchased, TotalPartialArrived, TotalArrived, ...
                'VariableNames', {'PIID', 'InqID', 'Customer', 'Tag', 'Total Lines', 'Total Line R2P', 'Total Line Purchased', 'Total Line Partial Arrived', 'Total Line Arrived'});
            
            % Sort the result by PIID
            resultTable = sortrows(resultTable, 'PIID');
            
            % Set column names explicitly
            app.OverviewViraPurchaseUITable.ColumnName = resultTable.Properties.VariableNames;
            % Assign the result to the UITable
            app.OverviewViraPurchaseUITable.Data = resultTable;
            centerAlignStyle = uistyle('HorizontalAlignment', 'left');
            addStyle(app.OverviewViraPurchaseUITable, centerAlignStyle, 'table', '');


            app.DeterminethePIIDDropDown.Items=["PIID";unique(resultTable.PIID)];

            app.SelecttheSourceDropDown.Items=["Source";unique(SupplierData.Name)];


        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)

            % Check if PIID is selected
            if strcmp(app.DeterminethePIIDDropDown.Value, 'PIID')
                uialert(app.ViraEPCUIFigure, 'Please Determine the PIID', 'Error');
                return;
            end
        
            % Check if Source is selected
            if strcmp(app.SelecttheSourceDropDown.Value, 'Source')
                uialert(app.ViraEPCUIFigure, 'Please Select the Source Name', 'Error');
                return;
            end
        
            % File selection
            [file, path] = uigetfile('*.xlsx', 'Import the Supplier PI Excel File');
            if file == 0
                % User canceled the file selection
                return;
            end
        
            progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                'Title', 'Loading...', ...
                'Message', 'Please wait :)', ...
                'Indeterminate', true, ...
                'Cancelable', false);
        
            % Read the Excel file into a table
            SUPPIPath = fullfile(path, file);
            SUPPI = readtable(SUPPIPath);
        
            % Get selected PIID and convert to numeric
            selectedPIID = str2double(app.DeterminethePIIDDropDown.Value);
        
            % Configure CSV import with proper data types
            opts = detectImportOptions('db\Purchase.csv');
            opts = setvartype(opts, {'RD_Quantity', 'PN'}, 'char');
            PurchaseDatabase = readtable('db\Purchase.csv', opts);
        
            % Loop through each row in the SUPPI table
            for i = 1:height(SUPPI)
                % Extract current values from SUPPI
                currentPN = SUPPI.PN{i};           % Part Number
                currentQTY = SUPPI.QTY(i);         % Quantity to upload
                currentUP = SUPPI.UP(i);           % Unit Price
                currentLeadTime = SUPPI.Leadtime(i); % Lead Time
                currentSource = app.SelecttheSourceDropDown.Value;
                operatorVal = app.UsernameEditField_2.Value;
        
                % Convert currentSource to char for comparisons
                currentSourceChar = char(currentSource);
        
                % Find all base entries for the current PIID and PN
                basefilter = (PurchaseDatabase.PIID == selectedPIID) & strcmp(PurchaseDatabase.PN, currentPN);
                baseEntries = PurchaseDatabase(basefilter, :);
                if isempty(baseEntries)
                    uialert(app.ViraEPCUIFigure, sprintf('No Finding for PN %s', currentPN), 'Error');
                    continue;
                end
        
                % Branch based on whether any record for the current source exists already
                sourceMask = basefilter & strcmp(PurchaseDatabase.Source, currentSourceChar);
                if isempty(find(sourceMask, 1))
                    % Branch 1: No record for the current source
        
                    % Get unique Clines sorted
                    cline_list = sort(unique(baseEntries.Cline));
        
                    % Compute remaining needed quantity for each Cline
                    remaining_needed = zeros(size(cline_list));
                    for c = 1:numel(cline_list)
                        cline = cline_list(c);
                        total_PQuantity = sum(PurchaseDatabase.PQuantity(basefilter & (PurchaseDatabase.Cline == cline)), 'omitnan');
                        if isnan(total_PQuantity)
                            total_PQuantity = 0;
                        end
                        rdQuantity = str2double(baseEntries.RD_Quantity{find(baseEntries.Cline == cline, 1)});
                        remaining_needed(c) = max(rdQuantity - total_PQuantity, 0);
                    end
        
                    % Allocate currentQTY
                    remaining_QTY = currentQTY;
                    newEntries = table(); % To collect new entries
        
                    for c = 1:numel(cline_list)
                        if remaining_needed(c) > 0 && remaining_QTY > 0
                            alloc = min(remaining_QTY, remaining_needed(c));
                            % Create new entry for this Cline
                            base_idx = find(basefilter & (PurchaseDatabase.Cline == cline_list(c)), 1);
                            newEntry = PurchaseDatabase(base_idx, :);
                            newEntry.Source = {currentSourceChar}; % Cell array for table assignment
                            newEntry.Source_No = 1; % Will be reset later
                            newEntry.PQuantity = alloc;
                            newEntry.PUP = currentUP;
                            newEntry.Lead_Time = currentLeadTime;
                            newEntry.Operator = {operatorVal};
                            newEntry.Financial_State = {'Unpaid'};
                            newEntry.Line_Status = {'R2P'};
                            newEntries = [newEntries; newEntry];
                            remaining_QTY = remaining_QTY - alloc;
                        end
                    end
        
                    % If remaining_QTY > 0, allocate to the last Cline
                    if remaining_QTY > 0
                        last_cline = cline_list(end);
                        % Check if newEntries has any rows before searching
                        if ~isempty(newEntries)
                            existingEntryIdx = find(strcmp(newEntries.Source, currentSourceChar) & newEntries.Cline == last_cline, 1);
                            if ~isempty(existingEntryIdx)
                                % Update the existing entry
                                newEntries.PQuantity(existingEntryIdx) = newEntries.PQuantity(existingEntryIdx) + remaining_QTY;
                            else
                                % Create new entry for the last Cline
                                base_idx = find(basefilter & (PurchaseDatabase.Cline == last_cline), 1);
                                newEntry = PurchaseDatabase(base_idx, :);
                                newEntry.Source = {currentSourceChar};
                                newEntry.Source_No = 1;
                                newEntry.PQuantity = remaining_QTY;
                                newEntry.PUP = currentUP;
                                newEntry.Lead_Time = currentLeadTime;
                                newEntry.Operator = {operatorVal};
                                newEntry.Financial_State = {'Unpaid'};
                                newEntry.Line_Status = {'R2P'};
                                newEntries = [newEntries; newEntry];
                            end
                        else
                            % newEntries is empty; create a new entry
                            base_idx = find(basefilter & (PurchaseDatabase.Cline == last_cline), 1);
                            newEntry = PurchaseDatabase(base_idx, :);
                            newEntry.Source = {currentSourceChar};
                            newEntry.Source_No = 1;
                            newEntry.PQuantity = remaining_QTY;
                            newEntry.PUP = currentUP;
                            newEntry.Lead_Time = currentLeadTime;
                            newEntry.Operator = {operatorVal};
                            newEntry.Financial_State = {'Unpaid'};
                            newEntry.Line_Status = {'R2P'};
                            newEntries = [newEntries; newEntry];
                        end
                    end

                    % Append all new entries to PurchaseDatabase
                    PurchaseDatabase = [PurchaseDatabase; newEntries];
                else
                    % Branch 2: Records for the current source exist
        
                    % Get unique Clines sorted
                    cline_list = sort(unique(baseEntries.Cline));
        
                    % Compute remaining needed quantity for each Cline
                    remaining_needed = zeros(size(cline_list));
                    for c = 1:numel(cline_list)
                        cline = cline_list(c);
                        total_PQuantity = sum(PurchaseDatabase.PQuantity(basefilter & (PurchaseDatabase.Cline == cline)), 'omitnan');
                        if isnan(total_PQuantity)
                            total_PQuantity = 0;
                        end
                        rdQuantity = str2double(baseEntries.RD_Quantity{find(baseEntries.Cline == cline, 1)});
                        remaining_needed(c) = max(rdQuantity - total_PQuantity, 0);
                    end
        
                    % Allocate currentQTY
                    remaining_QTY = currentQTY;
        
                    for c = 1:numel(cline_list)
                        if remaining_needed(c) > 0 && remaining_QTY > 0
                            alloc = min(remaining_QTY, remaining_needed(c));
                            % Find existing entry for this Cline and source
                            existing_idx = find(basefilter & (PurchaseDatabase.Cline == cline_list(c)) & strcmp(PurchaseDatabase.Source, currentSourceChar), 1);
                            if ~isempty(existing_idx)
                                % Update existing entry
                                PurchaseDatabase.PQuantity(existing_idx) = PurchaseDatabase.PQuantity(existing_idx) + alloc;
                                PurchaseDatabase.PUP(existing_idx) = currentUP;
                                PurchaseDatabase.Lead_Time(existing_idx) = currentLeadTime;
                                PurchaseDatabase.Operator{existing_idx} = operatorVal;
                                PurchaseDatabase.Financial_State{existing_idx} = 'Unpaid';
                                PurchaseDatabase.Line_Status{existing_idx} = 'R2P';
                            else
                                % Create new entry
                                base_idx = find(basefilter & (PurchaseDatabase.Cline == cline_list(c)), 1);
                                newEntry = PurchaseDatabase(base_idx, :);
                                newEntry.Source = {currentSourceChar}; % Cell array for table assignment
                                % Set Source_No based on existing records
                                sourceMaskCurrent = basefilter & strcmp(PurchaseDatabase.Source, currentSourceChar);
                                existingSourceNos = PurchaseDatabase.Source_No(sourceMaskCurrent);
                                newEntry.Source_No = max(existingSourceNos) + 1;
                                newEntry.PQuantity = alloc;
                                newEntry.PUP = currentUP;
                                newEntry.Lead_Time = currentLeadTime;
                                newEntry.Operator = {operatorVal};
                                newEntry.Financial_State = {'Unpaid'};
                                newEntry.Line_Status = {'R2P'};
                                PurchaseDatabase = [PurchaseDatabase; newEntry];
                            end
                            remaining_QTY = remaining_QTY - alloc;
                            if remaining_QTY <= 0
                                break;
                            end
                        end
                    end
        
                    % If remaining_QTY > 0, allocate to the last Cline
                    if remaining_QTY > 0
                        last_cline = cline_list(end);
                        existing_idx = find(basefilter & (PurchaseDatabase.Cline == last_cline) & strcmp(PurchaseDatabase.Source, currentSourceChar), 1);
                        if ~isempty(existing_idx)
                            PurchaseDatabase.PQuantity(existing_idx) = PurchaseDatabase.PQuantity(existing_idx) + remaining_QTY;
                            PurchaseDatabase.PUP(existing_idx) = currentUP;
                            PurchaseDatabase.Lead_Time(existing_idx) = currentLeadTime;
                            PurchaseDatabase.Operator{existing_idx} = operatorVal;
                            PurchaseDatabase.Financial_State{existing_idx} = 'Unpaid';
                            PurchaseDatabase.Line_Status{existing_idx} = 'R2P';
                        else
                            base_idx = find(basefilter & (PurchaseDatabase.Cline == last_cline), 1);
                            newEntry = PurchaseDatabase(base_idx, :);
                            newEntry.Source = {currentSourceChar}; % Cell array for table assignment
                            sourceMaskCurrent = basefilter & strcmp(PurchaseDatabase.Source, currentSourceChar);
                            existingSourceNos = PurchaseDatabase.Source_No(sourceMaskCurrent);
                            newEntry.Source_No = max(existingSourceNos) + 1;
                            newEntry.PQuantity = remaining_QTY;
                            newEntry.PUP = currentUP;
                            newEntry.Lead_Time = currentLeadTime;
                            newEntry.Operator = {operatorVal};
                            newEntry.Financial_State = {'Unpaid'};
                            newEntry.Line_Status = {'R2P'};
                            PurchaseDatabase = [PurchaseDatabase; newEntry];
                        end
                    end
                end
            end
        
            % Remove rows with empty or zero PQuantity for each processed PN
            processedPNs = unique(SUPPI.PN);
            toRemove = false(height(PurchaseDatabase), 1);
            for p = 1:numel(processedPNs)
                currentPN = processedPNs{p};
                pnMask = (PurchaseDatabase.PIID == selectedPIID) & strcmp(PurchaseDatabase.PN, currentPN);
                clines = unique(PurchaseDatabase.Cline(pnMask));
                for c = 1:numel(clines)
                    cline = clines(c);
                    clineMask = pnMask & (PurchaseDatabase.Cline == cline);
                    hasSource = any(~cellfun('isempty', PurchaseDatabase.Source(clineMask)));
                    if hasSource
                        noSourceAndEmptyPQty = clineMask & cellfun('isempty', PurchaseDatabase.Source) & ...
                                              (isnan(PurchaseDatabase.PQuantity) | PurchaseDatabase.PQuantity == 0);
                        toRemove(noSourceAndEmptyPQty) = true;
                    end
                end
            end
            PurchaseDatabase(toRemove, :) = [];
        
            % Reset Source_No numbering based on Cline per PN and per Source
            for p = 1:numel(processedPNs)
                currentPN = processedPNs{p};
                pnMask = (PurchaseDatabase.PIID == selectedPIID) & strcmp(PurchaseDatabase.PN, currentPN);
    
                % Get all unique Clines for the current PN
                clines = unique(PurchaseDatabase.Cline(pnMask));
                
                for c = 1:numel(clines)
                    cline = clines(c);
                    clineMask = pnMask & (PurchaseDatabase.Cline == cline);
                    
                    % Get unique non-empty sources for this Cline
                    allSources = PurchaseDatabase.Source(clineMask);
                    nonEmptyMask = ~cellfun('isempty', allSources);
                    uniqueSources = unique(allSources(nonEmptyMask));
                    
                    if ~isempty(uniqueSources)
                        % Determine order of sources by their first occurrence in this Cline
                        firstAppearanceOrder = zeros(numel(uniqueSources), 1);
                        for s = 1:numel(uniqueSources)
                            sourceEntries = find(clineMask & strcmp(PurchaseDatabase.Source, uniqueSources{s}));
                            firstAppearanceOrder(s) = sourceEntries(1); % Index of first occurrence
                        end
                        [~, sortedOrder] = sort(firstAppearanceOrder); % Sort by first appearance
                        
                        % Assign Source_No based on sorted order
                        for s = 1:numel(sortedOrder)
                            currSource = uniqueSources{sortedOrder(s)};
                            sourceIndices = clineMask & strcmp(PurchaseDatabase.Source, currSource);
                            PurchaseDatabase.Source_No(sourceIndices) = s; % Assign per Cline
                        end
                    end
                end
            end            


                % 
            %     % Get unique non-empty sources for the current PN
            %     allSources = PurchaseDatabase.Source(pnMask);
            %     nonEmptyMask = ~cellfun('isempty', allSources); % Identify non-empty sources
            %     uniqueSources = unique(allSources(nonEmptyMask)); % Only non-empty unique sources
            % 
            %     % Initialize Source_No to NaN for all rows of this PN
            %     PurchaseDatabase.Source_No(pnMask) = NaN;
            % 
            %     if ~isempty(uniqueSources)
            %         sourceMinC = zeros(numel(uniqueSources), 1);
            %         for s = 1:numel(uniqueSources)
            %             sourceMask = pnMask & strcmp(PurchaseDatabase.Source, uniqueSources{s});
            %             sourceMinC(s) = min(PurchaseDatabase.Cline(sourceMask));
            %         end
            %         % Sort the sources based on the minimum Cline value
            %         [~, order] = sort(sourceMinC);
            %         for s = 1:numel(uniqueSources)
            %             currSource = uniqueSources{order(s)};
            %             sourceIndices = pnMask & strcmp(PurchaseDatabase.Source, currSource);
            %             % Assign the same Source_No for all records with this source
            %             PurchaseDatabase.Source_No(sourceIndices) = s;
            %         end
            %     end
            % end
            % 
            % Save updated data
            writetable(PurchaseDatabase, 'db\Purchase.csv');
        
            % Refresh UI table
            app.ViraPurchaseTabButtonDown;
        
            close(progressDlg);

        end

        % Button pushed function: UploadBSButton
        function UploadBSButtonPushed(app, event)
            
            % Check if PIID is selected
            if strcmp(app.DeterminethePIIDDropDown.Value, 'PIID')
                uialert(app.ViraEPCUIFigure, 'Please Determine the PIID', 'Error');
                return;
            end
        
            % Check if Source is selected
            if strcmp(app.SelecttheSourceDropDown.Value, 'Source')
                uialert(app.ViraEPCUIFigure, 'Please Select the Source Name', 'Error');
                return;
            end
                    
            % Check if Date is selected
            if isnat(app.PaymentDateDatePicker.Value)==1
                uialert(app.ViraEPCUIFigure, 'Please Select Payment Date!', 'Error');
                return;
            end
                    
                  
            [file2, path2] = uigetfile('*.pdf', 'Upload the Payment Bank Slip');
            if file2 == 0
                % User canceled the file selection
                return;
            end

            progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
                'Title', 'Loading...', ...
                'Message', 'Please wait :)', ...
                'Indeterminate', true, ...
                'Cancelable', false);
            
                % Read purchase data
                purchasePath = fullfile('db', 'Purchase.csv');
                if ~exist(purchasePath, 'file')
                    error('Purchase.csv not found');
                end
                data = readtable(purchasePath, 'TextType', 'string');
                
                % Get selected values
                selectedPIID = str2double(app.DeterminethePIIDDropDown.Value);
                selectedSource = app.SelecttheSourceDropDown.Value;
                
                % Find matching rows
                matchingRows = (data.PIID == selectedPIID) & (data.Source == selectedSource);
                if ~any(matchingRows)
                    uialert(app.ViraEPCUIFigure, 'No matching records found!', 'Error');
                    return;
                end
                
                % Process each matching row
                sourceFilePath = fullfile(path2, file2);
                filesDir = fullfile(pwd, 'Files');
                
                for idx = find(matchingRows)'
                    % Update status fields
                    if data.Line_Status(idx) == "R2P"
                        data.Line_Status(idx) = "Purchased";
                    end
                    if data.Financial_State(idx) == "Unpaid"
                        data.Financial_State(idx) = "Paid";
                    end
                    
                    % Update payment info
                    data.PDate(idx) = app.PaymentDateDatePicker.Value;
                    data.Payer(idx) = app.UsernameEditField_2.Value;
                    
                    % Generate filename
                    inqID = data.InqID(idx);
                    % Generate filename
                    sanitizedSource = regexprep(selectedSource, '[^a-zA-Z0-9]', '_');  % Replace special chars with underscores
                    filename = sprintf('%d-BankSlip-%d-%s.pdf', selectedPIID, inqID, sanitizedSource);

                    % Create directory if needed
                    if ~exist(filesDir, 'dir')
                        mkdir(filesDir);
                    end

                    % Copy bank slip
                    destinationPath = fullfile(filesDir, filename);
                    copyfile(sourceFilePath, destinationPath);

                    % Update bank slip path
                    data.Bank_Slip(idx) = fullfile('.', 'Files', filename);
                end
                
                % Save updated data
                writetable(data, purchasePath);
      
            % Refresh UI table
            app.ViraPurchaseTabButtonDown;
        
            close(progressDlg);
            uialert(app.ViraEPCUIFigure, 'Bank slip uploaded successfully!', 'Success', 'Icon', 'success');

        
        end

        % Value changed function: DeterminethePIIDDropDown
        function DeterminethePIIDDropDownValueChanged(app, event)

            value = app.DeterminethePIIDDropDown.Value;
           progressDlg = uiprogressdlg(app.ViraEPCUIFigure, ...
            'Title', 'Loading...', ...
            'Message', 'Please wait :)', ...
            'Indeterminate', true, ...
            'Cancelable', false);
       
            % Value changed function: DeterminethePIIDDropDown
            opts = detectImportOptions('db\Purchase.csv');
            opts = setvartype(opts, 'RD_Quantity', 'char');  % Handle comma values
            PurchaseDatabase = readtable('db\Purchase.csv', opts);            % Read and filter purchase data

            PurchaseDatabase = readtable('db\Purchase.csv');
            filteredData = PurchaseDatabase(PurchaseDatabase.PIID == str2double(value), :);
            
            % Create output table with required columns
            outputVars = {
                'Line', 'Cline', 'PN', 'Quoted_PN', 'UP2C',...
                'RD_Quantity', 'Operator', 'Line_Status',...
                'Source_No', 'Source', 'PQuantity', 'PUP',...
                'Lead_Time', 'Financial_State', 'Payer', 'PDate', 'Bank_Slip'
            };
            
            resultTable = filteredData(:, outputVars);
            
            % Rename columns to match UI display
            resultTable.Properties.VariableNames = {
                'Line', 'Cline', 'PN', 'Quoted PN', 'UP2C',...
                'Required Delivery Quantity', 'Operator', 'Line State',...
                'Source No.', 'Source', 'PQuantity', 'PUP',...
                'Lead Time', 'Financial State', 'Payer', 'PDate', 'Bank Slip'
            };
            
            % Sort by Line and Source No.
            resultTable = sortrows(resultTable, {'Line', 'Source No.'});
            
            % Format numeric columns
            % resultTable.PN = cellstr(num2str(resultTable.PN, '%.5E'));
            resultTable.('Required Delivery Quantity') = strrep(string(resultTable.('Required Delivery Quantity')), '_', ',');
            

            % Set column names explicitly
            app.ViraPurchaseUITable.ColumnName = resultTable.Properties.VariableNames;
            % Assign the result to the UITable
            app.ViraPurchaseUITable.Data = resultTable;
            centerAlignStyle = uistyle('HorizontalAlignment', 'left');
            addStyle(app.ViraPurchaseUITable, centerAlignStyle, 'table', '');


         close(progressDlg);   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ViraEPCUIFigure = uifigure('Visible', 'off');
            app.ViraEPCUIFigure.Name = 'Vira-EPC';
            app.ViraEPCUIFigure.Icon = fullfile(pathToMLAPP, 'PNG', 'Logo.png');
            app.GridLayout = uigridlayout(app.ViraEPCUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GridLayout2 = uigridlayout(app.HomeTab);
            app.WelcomeLabel = uilabel(app.GridLayout2);
            app.WelcomeLabel.Text = 'Welcome to Vira App 2.0';
            app.Entry_InquiryTab = uitab(app.TabGroup);
            app.Entry_InquiryTab.Title = 'Entry Inquiry';
            app.Entry_InquiryTab.ButtonDownFcn = createCallbackFcn(app, @InquiryTabButtonDown, true);
            app.InqGrid = uigridlayout(app.Entry_InquiryTab);
            app.InquiryItemFilePath = uieditfield(app.InqGrid, 'text');
            app.Notif_Inq = uieditfield(app.InqGrid, 'text');
            app.Notif_Inq.Editable = 'off';
            app.InquiryTable = uitable(app.InqGrid);
            app.InquiryTable.ColumnName = {'ID'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InquiryTable.RowName = {};
            app.InquiryTable.ColumnSortable = true;
            app.CustomerDropDownLabel = uilabel(app.InqGrid);
            app.CustomerDropDownLabel.Text = 'Customer';
            app.CustomerDropDown_Inq = uidropdown(app.InqGrid);
            app.DateDatePickerLabel = uilabel(app.InqGrid);
            app.DateDatePickerLabel.Text = 'Date';
            app.Date_Inq = uidatepicker(app.InqGrid);
            app.Date_Inq.DisplayFormat = 'dd.MM.uuuu';
            app.ReadInquiryItemfileButton = uibutton(app.InqGrid, 'push');
            app.ReadInquiryItemfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadInquiryItemfileButtonPushed, true);
            app.ReadInquiryItemfileButton.Text = 'Read Inquiry Item file';
            app.AttachmentEditField_5Label = uilabel(app.InqGrid);
            app.AttachmentEditField_5Label.Text = 'Attachment';
            app.AttachmentEditField_Inq = uieditfield(app.InqGrid, 'text');
            app.AttachButtonInq = uibutton(app.InqGrid, 'push');
            app.AttachButtonInq.ButtonPushedFcn = createCallbackFcn(app, @AttachButtonInqPushed, true);
            app.AttachButtonInq.Text = 'Select File';
            app.SaveButton = uibutton(app.InqGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PleaseCheckthePreviewCheckBox = uicheckbox(app.InqGrid);
            app.PleaseCheckthePreviewCheckBox.Text = ' Please Check the Preview !';
            app.InqItemTable = uitable(app.InqGrid);
            app.InqItemTable.ColumnName = {'#'; 'Part Number'; 'Quantity'; 'Brand'; 'Description'};
            app.InqItemTable.RowName = {};
            app.InqItemTable.ColumnSortable = true;
            app.InqItemTable.ColumnEditable = [false false true false false];
            app.NameTagEditFieldLabel = uilabel(app.InqGrid);
            app.NameTagEditFieldLabel.Text = 'Name/Tag';
            app.NameTagEditField = uieditfield(app.InqGrid, 'text');
            app.ShowAllButton = uibutton(app.InqGrid, 'push');
            app.ShowAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllButtonPushed, true);
            app.ShowAllButton.Text = 'Show All';
            app.ResetButton = uibutton(app.InqGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @InquiryTabButtonDown, true);
            app.ResetButton.Text = 'Reset';
            app.Button = uibutton(app.InqGrid, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ExcelInq, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Button.Text = '';
            app.OpenAttachmentButton = uibutton(app.InqGrid, 'push');
            app.OpenAttachmentButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAttachmentButtonPushed, true);
            app.OpenAttachmentButton.Text = 'Open Attachment';
            app.InqID_DD = uidropdown(app.InqGrid);
            app.InqID_DD.Items = {'ID'};
            app.InqID_DD.Value = 'ID';
            app.OpenInqItemsButton = uibutton(app.InqGrid, 'push');
            app.OpenInqItemsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInqItemsButtonPushed, true);
            app.OpenInqItemsButton.Text = 'Open Inq. Items';
            app.AutoGegerateCheckBox = uicheckbox(app.InqGrid);
            app.AutoGegerateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoGegerateCheckBoxValueChanged, true);
            app.AutoGegerateCheckBox.Text = 'Auto Gegerate';
            app.AutoGegerateCheckBox.Value = true;
            app.CInqID = uidropdown(app.InqGrid);
            app.CInqID.Items = {'InqID'};
            app.CInqID.Value = 'InqID';
            app.GridLayout19 = uigridlayout(app.InqGrid);
            app.PNEditFieldLabel = uilabel(app.GridLayout19);
            app.PNEditFieldLabel.Text = 'PN';
            app.PNEditField = uieditfield(app.GridLayout19, 'text');
            app.QTYEditField_2Label = uilabel(app.GridLayout19);
            app.QTYEditField_2Label.Text = 'QTY';
            app.QTYEditField_2 = uieditfield(app.GridLayout19, 'numeric');
            app.BrandEditFieldLabel = uilabel(app.GridLayout19);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.BrandEditField = uieditfield(app.GridLayout19, 'text');
            app.DesEditFieldLabel = uilabel(app.GridLayout19);
            app.DesEditFieldLabel.Text = 'Des.';
            app.DesEditField = uieditfield(app.GridLayout19, 'text');
            app.AddLineButton_2 = uibutton(app.InqGrid, 'push');
            app.AddLineButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddLineButton_2Pushed, true);
            app.AddLineButton_2.Text = 'Add Line';
            app.Issue_PITab = uitab(app.TabGroup);
            app.Issue_PITab.Title = 'Issue PI';
            app.Issue_PITab.ButtonDownFcn = createCallbackFcn(app, @OpenInquiryTabButtonDown, true);
            app.GridOpenInq = uigridlayout(app.Issue_PITab);
            app.OpenInquiryQTY = uitable(app.GridOpenInq);
            app.OpenInquiryQTY.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OpenInquiryQTY.RowName = {};
            app.OpenInqTable = uitable(app.GridOpenInq);
            app.OpenInqTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OpenInqTable.RowName = {};
            app.OpenInqTable.ColumnSortable = true;
            app.Notif_OpenInq = uieditfield(app.GridOpenInq, 'text');
            app.Notif_OpenInq.Editable = 'off';
            app.CloseButton = uibutton(app.GridOpenInq, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenInq_PQTable = uitable(app.GridOpenInq);
            app.OpenInq_PQTable.ColumnName = {'Cline'; 'PN'; 'PNC'; 'Quantity'; 'Diff Quantity'; 'Price'; 'Total'};
            app.OpenInq_PQTable.RowName = {};
            app.OpenInq_PQTable.ColumnSortable = true;
            app.OpenInq_PQTable.ColumnEditable = [false true false true false true false];
            app.IssueDateDatePickerLabel = uilabel(app.GridOpenInq);
            app.IssueDateDatePickerLabel.Text = 'Issue Date';
            app.IssueDateDatePicker = uidatepicker(app.GridOpenInq);
            app.CompanyDropDownLabel = uilabel(app.GridOpenInq);
            app.CompanyDropDownLabel.Text = 'Company';
            app.CompanyDropDown = uidropdown(app.GridOpenInq);
            app.CompanyDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.CompanyDropDown.Value = 'Select';
            app.PaymenttermsDropDownLabel = uilabel(app.GridOpenInq);
            app.PaymenttermsDropDownLabel.Text = 'Payment terms';
            app.PaymenttermsDropDown = uidropdown(app.GridOpenInq);
            app.PaymenttermsDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.PaymenttermsDropDown.Value = 'Select';
            app.IssuePIButton = uibutton(app.GridOpenInq, 'push');
            app.IssuePIButton.ButtonPushedFcn = createCallbackFcn(app, @IssuePIButtonPushed, true);
            app.IssuePIButton.Text = 'Issue PI';
            app.LeadtimeWeeksEditFieldLabel = uilabel(app.GridOpenInq);
            app.LeadtimeWeeksEditFieldLabel.Text = 'Lead time (Weeks)';
            app.LeadtimeWeeksEditField = uieditfield(app.GridOpenInq, 'numeric');
            app.OpenInqIDDropDownLabel = uilabel(app.GridOpenInq);
            app.OpenInqIDDropDownLabel.Text = ' Open Inq ID';
            app.OpenInqIDDropDown = uidropdown(app.GridOpenInq);
            app.OpenInqIDDropDown.Items = {'Select'};
            app.OpenInqIDDropDown.ValueChangedFcn = createCallbackFcn(app, @OpenInqIDDropDownValueChanged, true);
            app.OpenInqIDDropDown.Value = 'Select';
            app.ImportPQlistButton = uibutton(app.GridOpenInq, 'push');
            app.ImportPQlistButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPQlistButtonPushed, true);
            app.ImportPQlistButton.Text = 'Import P.Q. list';
            app.IncotermsEditFieldLabel = uilabel(app.GridOpenInq);
            app.IncotermsEditFieldLabel.Text = 'Incoterms';
            app.IncotermsEditField = uieditfield(app.GridOpenInq, 'text');
            app.TotalEditFieldLabel = uilabel(app.GridOpenInq);
            app.TotalEditFieldLabel.Text = 'Total';
            app.OpenInqTotal = uieditfield(app.GridOpenInq, 'numeric');
            app.OpenInqTotal.ValueDisplayFormat = '%.2f';
            app.CurrencyDropDownLabel = uilabel(app.GridOpenInq);
            app.CurrencyDropDownLabel.Text = 'Currency';
            app.OpenInqCurrncyDropDown = uidropdown(app.GridOpenInq);
            app.OpenInqCurrncyDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.OpenInqCurrncyDropDown.Value = 'Select';
            app.ReCalcTotalButton = uibutton(app.GridOpenInq, 'push');
            app.ReCalcTotalButton.ButtonPushedFcn = createCallbackFcn(app, @ReCalcTotalButtonPushed, true);
            app.ReCalcTotalButton.Text = 'Re Calc. Total';
            app.ValidityDatePickerLabel = uilabel(app.GridOpenInq);
            app.ValidityDatePickerLabel.Text = 'Validity';
            app.ValidityDatePicker = uidatepicker(app.GridOpenInq);
            app.ShowallButton = uibutton(app.GridOpenInq, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Text = 'Show all';
            app.ResetButton_2 = uibutton(app.GridOpenInq, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenInquiryTabButtonDown, true);
            app.ResetButton_2.Text = 'Reset';
            app.Button_2 = uibutton(app.GridOpenInq, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @OpenInqExcel, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Button_2.Text = '';
            app.OpenAttachmentButton_2 = uibutton(app.GridOpenInq, 'push');
            app.OpenAttachmentButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenAttachmentOpen, true);
            app.OpenAttachmentButton_2.Text = 'Open Attachment';
            app.OpenInqItemsButton_2 = uibutton(app.GridOpenInq, 'push');
            app.OpenInqItemsButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenInqItemsOpen, true);
            app.OpenInqItemsButton_2.Text = 'Open Inq. Items';
            app.InqID_DDInq = uidropdown(app.GridOpenInq);
            app.InqID_DDInq.Items = {'ID'};
            app.InqID_DDInq.Value = 'ID';
            app.TextArea = uitextarea(app.GridOpenInq);
            app.LineNameEditFieldLabel = uilabel(app.GridOpenInq);
            app.LineNameEditFieldLabel.Text = 'Line Name';
            app.LineNameEditField = uieditfield(app.GridOpenInq, 'text');
            app.ValueEditFieldLabel = uilabel(app.GridOpenInq);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.GridOpenInq, 'numeric');
            app.AddLineButton = uibutton(app.GridOpenInq, 'push');
            app.AddLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddLineButtonPushed, true);
            app.AddLineButton.Text = 'Add Line';
            app.QTYEditFieldLabel = uilabel(app.GridOpenInq);
            app.QTYEditFieldLabel.Text = 'QTY';
            app.QTYEditField = uieditfield(app.GridOpenInq, 'numeric');
            app.ZeroLinesCheckBox = uicheckbox(app.GridOpenInq);
            app.ZeroLinesCheckBox.Text = 'Zero Lines';
            app.CargoTab = uitab(app.TabGroup);
            app.CargoTab.Title = 'Cargo';
            app.CargoTab.ButtonDownFcn = createCallbackFcn(app, @CargoTabButtonDown, true);
            app.GridLayout21 = uigridlayout(app.CargoTab);
            app.TabGroup4 = uitabgroup(app.GridLayout21);
            app.ViraPurchaseTab = uitab(app.TabGroup4);
            app.ViraPurchaseTab.Title = 'Vira Purchase';
            app.ViraPurchaseTab.ButtonDownFcn = createCallbackFcn(app, @ViraPurchaseTabButtonDown, true);
            app.GridLayout27 = uigridlayout(app.ViraPurchaseTab);
            app.OverviewViraPurchaseUITable = uitable(app.GridLayout27);
            app.OverviewViraPurchaseUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OverviewViraPurchaseUITable.RowName = {};
            app.SelecttheInqIDLabel = uilabel(app.GridLayout27);
            app.SelecttheInqIDLabel.Text = 'Determine the PIID';
            app.DeterminethePIIDDropDown = uidropdown(app.GridLayout27);
            app.DeterminethePIIDDropDown.Items = {'PIID'};
            app.DeterminethePIIDDropDown.ValueChangedFcn = createCallbackFcn(app, @DeterminethePIIDDropDownValueChanged, true);
            app.DeterminethePIIDDropDown.Value = 'PIID';
            app.UploadButton = uibutton(app.GridLayout27, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tooltip = {'Excel'};
            app.UploadButton.Text = 'Upload';
            app.ViraPurchaseUITable = uitable(app.GridLayout27);
            app.ViraPurchaseUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ViraPurchaseUITable.RowName = {};
            app.SelecttheSourceDropDownLabel = uilabel(app.GridLayout27);
            app.SelecttheSourceDropDownLabel.Text = 'Select the Source';
            app.SelecttheSourceDropDown = uidropdown(app.GridLayout27);
            app.SelecttheSourceDropDown.Items = {'Source'};
            app.SelecttheSourceDropDown.Value = 'Source';
            app.PurchaseUploadSupplierPILabel = uilabel(app.GridLayout27);
            app.PurchaseUploadSupplierPILabel.Text = 'Supplier PI';
            app.PaymentDateDatePickerLabel = uilabel(app.GridLayout27);
            app.PaymentDateDatePickerLabel.Text = 'Payment Date';
            app.PaymentDateDatePicker = uidatepicker(app.GridLayout27);
            app.BankSlipLabel = uilabel(app.GridLayout27);
            app.BankSlipLabel.Text = 'Bank Slip';
            app.UploadBSButton = uibutton(app.GridLayout27, 'push');
            app.UploadBSButton.ButtonPushedFcn = createCallbackFcn(app, @UploadBSButtonPushed, true);
            app.UploadBSButton.Tooltip = {'PDF'};
            app.UploadBSButton.Text = 'Upload';
            app.UpdateandPackingTab = uitab(app.TabGroup4);
            app.UpdateandPackingTab.Title = 'Update and Packing';
            app.GridLayout28 = uigridlayout(app.UpdateandPackingTab);
            app.DetailPackingUITable = uitable(app.GridLayout28);
            app.DetailPackingUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DetailPackingUITable.RowName = {};
            app.SelectthePIIDLabel = uilabel(app.GridLayout28);
            app.SelectthePIIDLabel.Text = 'Select the PIID';
            app.UpdateSelectthePIIDDropDown = uidropdown(app.GridLayout28);
            app.SelecttheSourceDropDown_2Label = uilabel(app.GridLayout28);
            app.SelecttheSourceDropDown_2Label.Text = 'Select the Source';
            app.UpdateSelecttheSourceDropDown = uidropdown(app.GridLayout28);
            app.ArrivedDateDatePickerLabel = uilabel(app.GridLayout28);
            app.ArrivedDateDatePickerLabel.Text = 'Arrived Date';
            app.ArrivedDateDatePicker = uidatepicker(app.GridLayout28);
            app.TrackingNumberEditFieldLabel = uilabel(app.GridLayout28);
            app.TrackingNumberEditFieldLabel.Text = 'Tracking Number';
            app.TrackingNumberEditField = uieditfield(app.GridLayout28, 'text');
            app.Upload_SPLButton = uibutton(app.GridLayout28, 'push');
            app.Upload_SPLButton.Tooltip = {'Please Upload its Supplier Packing List in Excel Format.'};
            app.Upload_SPLButton.Text = 'Upload';
            app.PackingListUITable = uitable(app.GridLayout28);
            app.PackingListUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PackingListUITable.RowName = {};
            app.AddWdataButton = uibutton(app.GridLayout28, 'push');
            app.AddWdataButton.Tooltip = {'Excel'};
            app.AddWdataButton.Text = 'Add';
            app.WarehouseDataLabel = uilabel(app.GridLayout28);
            app.WarehouseDataLabel.Text = 'Warehouse Data';
            app.Extract_PLButton = uibutton(app.GridLayout28, 'push');
            app.Extract_PLButton.Tooltip = {'Excel'};
            app.Extract_PLButton.Text = 'Extract';
            app.PackingListLabel = uilabel(app.GridLayout28);
            app.PackingListLabel.Text = 'Packing List';
            app.SupplierPLLabel = uilabel(app.GridLayout28);
            app.SupplierPLLabel.Text = 'Supplier PL';
            app.Issue_CITab = uitab(app.TabGroup);
            app.Issue_CITab.Title = 'Issue CI';
            app.Issue_CITab.ButtonDownFcn = createCallbackFcn(app, @Issue_CITabButtonDown, true);
            app.IssueInvoice = uigridlayout(app.Issue_CITab);
            app.PIIDfix = uieditfield(app.IssueInvoice, 'numeric');
            app.AppPITable = uitable(app.IssueInvoice);
            app.AppPITable.ColumnName = {'PI ID'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AppPITable.RowName = {};
            app.AppPITable.ColumnSortable = true;
            app.IssueInvoiceNotif = uieditfield(app.IssueInvoice, 'text');
            app.IssueInvoiceNotif.Editable = 'off';
            app.PIID2DD = uidropdown(app.IssueInvoice);
            app.PIID2DD.Items = {'ID'};
            app.PIID2DD.Value = 'ID';
            app.OpenAppPI = uibutton(app.IssueInvoice, 'push');
            app.OpenAppPI.ButtonPushedFcn = createCallbackFcn(app, @OpenAppPIButtonPushed, true);
            app.OpenAppPI.Text = 'Open PI';
            app.AppPIExcel = uibutton(app.IssueInvoice, 'push');
            app.AppPIExcel.ButtonPushedFcn = createCallbackFcn(app, @AppPIExcelButtonPushed, true);
            app.AppPIExcel.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.AppPIExcel.Text = '';
            app.PIIDDD = uidropdown(app.IssueInvoice);
            app.PIIDDD.Items = {'ID'};
            app.PIIDDD.ValueChangedFcn = createCallbackFcn(app, @PIIDDDValueChanged, true);
            app.PIIDDD.Value = 'ID';
            app.PIItemTable = uitable(app.IssueInvoice);
            app.PIItemTable.ColumnName = {'Cline'; 'PN'; 'Quantity'; 'Price'; 'Total'; 'Select'};
            app.PIItemTable.RowName = {};
            app.PIItemTable.ColumnEditable = [false true true true false true];
            app.addonetoI = uibutton(app.IssueInvoice, 'push');
            app.addonetoI.ButtonPushedFcn = createCallbackFcn(app, @addonetoIButtonPushed, true);
            app.addonetoI.Text = '>';
            app.addalltoI = uibutton(app.IssueInvoice, 'push');
            app.addalltoI.ButtonPushedFcn = createCallbackFcn(app, @addalltoIButtonPushed, true);
            app.addalltoI.Text = '>>';
            app.ResetInitem = uibutton(app.IssueInvoice, 'push');
            app.ResetInitem.ButtonPushedFcn = createCallbackFcn(app, @ResetInitemButtonPushed, true);
            app.ResetInitem.Text = 'Reset';
            app.DelonetoI = uibutton(app.IssueInvoice, 'push');
            app.DelonetoI.ButtonPushedFcn = createCallbackFcn(app, @DelonetoIButtonPushed, true);
            app.DelonetoI.Text = '<';
            app.DelalltoI = uibutton(app.IssueInvoice, 'push');
            app.DelalltoI.ButtonPushedFcn = createCallbackFcn(app, @DelalltoIButtonPushed, true);
            app.DelalltoI.Text = '<<';
            app.InItemTable = uitable(app.IssueInvoice);
            app.InItemTable.ColumnName = {'Select'; 'Cline'; 'PN'; 'Quantity'; 'Price'; 'Total'};
            app.InItemTable.RowName = {};
            app.InItemTable.ColumnEditable = [true false false true true false];
            app.DateDatePickerLabel_2 = uilabel(app.IssueInvoice);
            app.DateDatePickerLabel_2.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.IssueInvoice);
            app.IssueInvoceButton = uibutton(app.IssueInvoice, 'push');
            app.IssueInvoceButton.ButtonPushedFcn = createCallbackFcn(app, @IssueInvoceButtonPushed, true);
            app.IssueInvoceButton.Text = 'Issue Invoce';
            app.OpenAppPI_2 = uibutton(app.IssueInvoice, 'push');
            app.OpenAppPI_2.ButtonPushedFcn = createCallbackFcn(app, @OpenAppPI_2ButtonPushed, true);
            app.OpenAppPI_2.Text = 'Open PO';
            app.NoteLabel = uilabel(app.IssueInvoice);
            app.NoteLabel.Text = 'Note:';
            app.NoteEditField = uieditfield(app.IssueInvoice, 'text');
            app.PITableLabel = uilabel(app.IssueInvoice);
            app.PITableLabel.Text = 'PI Table';
            app.Label = uilabel(app.IssueInvoice);
            app.Label.Text = '';
            app.TotalApprovedPILabel = uilabel(app.IssueInvoice);
            app.TotalApprovedPILabel.Text = 'Total Approved PI:';
            app.NewInLine = uitable(app.IssueInvoice);
            app.NewInLine.ColumnName = {'#'; 'Name'; 'QTY'; 'Value'; 'Total'};
            app.NewInLine.RowName = {};
            app.GridLayout20 = uigridlayout(app.IssueInvoice);
            app.LineNameEditFieldLabel_2 = uilabel(app.GridLayout20);
            app.LineNameEditFieldLabel_2.Text = 'Line Name';
            app.LineNameEditField_2 = uieditfield(app.GridLayout20, 'text');
            app.ValueEditFieldLabel_2 = uilabel(app.GridLayout20);
            app.ValueEditFieldLabel_2.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.GridLayout20, 'numeric');
            app.QTYEditFieldLabel_2 = uilabel(app.GridLayout20);
            app.QTYEditFieldLabel_2.Text = 'QTY';
            app.QTYEditField_3 = uieditfield(app.GridLayout20, 'numeric');
            app.AddLineButton_3 = uibutton(app.IssueInvoice, 'push');
            app.AddLineButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddLineButton_3Pushed, true);
            app.AddLineButton_3.Text = 'Add Line';
            app.UpdateTab = uitab(app.TabGroup);
            app.UpdateTab.Title = 'Update';
            app.UpdateTab.ButtonDownFcn = createCallbackFcn(app, @UpdateTabButtonDown, true);
            app.GridLayout22 = uigridlayout(app.UpdateTab);
            app.UpdateSubTabs = uitabgroup(app.GridLayout22);
            app.Update_Open_PITab = uitab(app.UpdateSubTabs);
            app.Update_Open_PITab.Title = 'Open PI';
            app.Update_Open_PITab.ButtonDownFcn = createCallbackFcn(app, @Update_Open_PITabButtonDown2, true);
            app.OpenPIGrid = uigridlayout(app.Update_Open_PITab);
            app.ApPath = uieditfield(app.OpenPIGrid, 'text');
            app.OpenPITable = uitable(app.OpenPIGrid);
            app.OpenPITable.ColumnName = {'PI ID'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OpenPITable.RowName = {};
            app.OpenPITable.ColumnSortable = true;
            app.OpenPINotif = uieditfield(app.OpenPIGrid, 'text');
            app.OpenPINotif.Editable = 'off';
            app.OpenPICloseButton = uibutton(app.OpenPIGrid, 'push');
            app.OpenPICloseButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPICloseButtonPushed, true);
            app.OpenPICloseButton.Text = 'Declined By Customer';
            app.SelectPIIDDropDownLabel = uilabel(app.OpenPIGrid);
            app.SelectPIIDDropDownLabel.Text = 'Select PI ID';
            app.SelectPIIDDropDown = uidropdown(app.OpenPIGrid);
            app.SelectPIIDDropDown.Items = {'Select'};
            app.SelectPIIDDropDown.Value = 'Select';
            app.ApprovedByCustomerButton = uibutton(app.OpenPIGrid, 'push');
            app.ApprovedByCustomerButton.ButtonPushedFcn = createCallbackFcn(app, @ApprovedByCustomerButtonPushed, true);
            app.ApprovedByCustomerButton.Text = 'Approved By Customer';
            app.ApprovedDateDatePickerLabel = uilabel(app.OpenPIGrid);
            app.ApprovedDateDatePickerLabel.Text = 'Approved Date';
            app.ApprovedDateDatePicker = uidatepicker(app.OpenPIGrid);
            app.AttachPOButton = uibutton(app.OpenPIGrid, 'push');
            app.AttachPOButton.ButtonPushedFcn = createCallbackFcn(app, @AttachPOButtonPushed, true);
            app.AttachPOButton.Text = 'Attach PO';
            app.OpenPIShowallButton = uibutton(app.OpenPIGrid, 'push');
            app.OpenPIShowallButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPIShowallButtonPushed, true);
            app.OpenPIShowallButton.Text = 'Show all';
            app.ResetButton_3 = uibutton(app.OpenPIGrid, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenPITabButtonDown, true);
            app.ResetButton_3.Text = 'Reset';
            app.IDOpenPIDD = uidropdown(app.OpenPIGrid);
            app.IDOpenPIDD.Items = {'ID'};
            app.IDOpenPIDD.Value = 'ID';
            app.OpenPIButton_3 = uibutton(app.OpenPIGrid, 'push');
            app.OpenPIButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenPIButtonPushed, true);
            app.OpenPIButton_3.Text = 'Open PI';
            app.OpenPIItemButton = uibutton(app.OpenPIGrid, 'push');
            app.OpenPIItemButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPIItemButtonPushed, true);
            app.OpenPIItemButton.Text = 'Open PI Item';
            app.Button_6 = uibutton(app.OpenPIGrid, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Button_6.Text = '';
            app.Update_Open_CITab = uitab(app.UpdateSubTabs);
            app.Update_Open_CITab.Title = 'Open CI';
            app.Update_Open_CITab.ButtonDownFcn = createCallbackFcn(app, @Update_Open_CITabButtonDown2, true);
            app.OpenCIGrid = uigridlayout(app.Update_Open_CITab);
            app.ApCIPath = uieditfield(app.OpenCIGrid, 'text');
            app.OpenCITable = uitable(app.OpenCIGrid);
            app.OpenCITable.ColumnName = {'CIID'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OpenCITable.RowName = {};
            app.OpenCITable.ColumnSortable = true;
            app.OpenCINotif = uieditfield(app.OpenCIGrid, 'text');
            app.OpenCINotif.Editable = 'off';
            app.SelectCIIDDropDownLabel = uilabel(app.OpenCIGrid);
            app.SelectCIIDDropDownLabel.Text = 'Select CI ID';
            app.SelectCIIDDropDown = uidropdown(app.OpenCIGrid);
            app.SelectCIIDDropDown.Items = {'Select'};
            app.SelectCIIDDropDown.Value = 'Select';
            app.ApprovedCIByCustomerButton = uibutton(app.OpenCIGrid, 'push');
            app.ApprovedCIByCustomerButton.ButtonPushedFcn = createCallbackFcn(app, @ApprovedCIByCustomerButtonPushed, true);
            app.ApprovedCIByCustomerButton.Text = 'Approved By Customer';
            app.ApprovedDateDatePicker_2Label = uilabel(app.OpenCIGrid);
            app.ApprovedDateDatePicker_2Label.Text = 'Approved Date';
            app.ApprovedCIDatePicker = uidatepicker(app.OpenCIGrid);
            app.AttachCIButton = uibutton(app.OpenCIGrid, 'push');
            app.AttachCIButton.ButtonPushedFcn = createCallbackFcn(app, @AttachCIButtonPushed, true);
            app.AttachCIButton.Text = 'Attach POD';
            app.IDOpenCIDD = uidropdown(app.OpenCIGrid);
            app.IDOpenCIDD.Items = {'ID'};
            app.IDOpenCIDD.Value = 'ID';
            app.OpenCIButton_3 = uibutton(app.OpenCIGrid, 'push');
            app.OpenCIButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenCIButtonPushed, true);
            app.OpenCIButton_3.Text = 'Open CI';
            app.OCIExcel = uibutton(app.OpenCIGrid, 'push');
            app.OCIExcel.ButtonPushedFcn = createCallbackFcn(app, @OCIExcelButtonPushed, true);
            app.OCIExcel.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.OCIExcel.Text = '';
            app.OpenPICIButton = uibutton(app.OpenCIGrid, 'push');
            app.OpenPICIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPICIButtonPushed, true);
            app.OpenPICIButton.Text = 'Open PI';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.ReportTab.ButtonDownFcn = createCallbackFcn(app, @ReportTabButtonDown, true);
            app.ReportGrid = uigridlayout(app.ReportTab);
            app.Notif_Report = uieditfield(app.ReportGrid, 'text');
            app.Notif_Report.Editable = 'off';
            app.ShowAllReport = uibutton(app.ReportGrid, 'push');
            app.ShowAllReport.ButtonPushedFcn = createCallbackFcn(app, @ShowAllReportButtonPushed, true);
            app.ShowAllReport.Text = 'Show All';
            app.Excel_Report = uibutton(app.ReportGrid, 'push');
            app.Excel_Report.ButtonPushedFcn = createCallbackFcn(app, @Excel_ReportButtonPushed, true);
            app.Excel_Report.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Excel_Report.Text = '';
            app.InqIDDDReport = uidropdown(app.ReportGrid);
            app.InqIDDDReport.Items = {'ID'};
            app.InqIDDDReport.Value = 'ID';
            app.OpenInqItemsButton_3 = uibutton(app.ReportGrid, 'push');
            app.OpenInqItemsButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenInqItemsButton_3Pushed, true);
            app.OpenInqItemsButton_3.Text = 'Open Inq. Items';
            app.CompaniesLabel = uilabel(app.ReportGrid);
            app.CompaniesLabel.Text = 'Companies';
            app.FilterReport = uibutton(app.ReportGrid, 'push');
            app.FilterReport.ButtonPushedFcn = createCallbackFcn(app, @FilterReportButtonPushed, true);
            app.FilterReport.Text = 'Filter';
            app.OpenPOButton = uibutton(app.ReportGrid, 'push');
            app.OpenPOButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPOButtonPushed, true);
            app.OpenPOButton.Text = 'Open PO';
            app.OpenPIButton_2 = uibutton(app.ReportGrid, 'push');
            app.OpenPIButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenPIButton_2Pushed, true);
            app.OpenPIButton_2.Text = 'Open PI';
            app.OpenPODButton = uibutton(app.ReportGrid, 'push');
            app.OpenPODButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPODButtonPushed, true);
            app.OpenPODButton.Text = 'Open POD';
            app.OpenButton = uibutton(app.ReportGrid, 'state');
            app.OpenButton.Text = 'Open';
            app.PIDeclinedButton = uibutton(app.ReportGrid, 'state');
            app.PIDeclinedButton.Text = 'PI Declined';
            app.PIIssuedButton = uibutton(app.ReportGrid, 'state');
            app.PIIssuedButton.Text = 'PI Issued';
            app.PIApprovedButton = uibutton(app.ReportGrid, 'state');
            app.PIApprovedButton.Text = 'PI Approved';
            app.CIIssuedButton = uibutton(app.ReportGrid, 'state');
            app.CIIssuedButton.Text = 'CI Issued';
            app.PartialCIIssuedButton = uibutton(app.ReportGrid, 'state');
            app.PartialCIIssuedButton.Text = 'Partial CI Issued';
            app.PartialDeliveredButton = uibutton(app.ReportGrid, 'state');
            app.PartialDeliveredButton.Text = 'Partial Delivered';
            app.DeliveredButton = uibutton(app.ReportGrid, 'state');
            app.DeliveredButton.Text = 'Delivered';
            app.SelectStatesLabel = uilabel(app.ReportGrid);
            app.SelectStatesLabel.Text = 'Select States';
            app.TotalPIValueLabel = uilabel(app.ReportGrid);
            app.TotalPIValueLabel.Text = 'Total PI Value:';
            app.ReportTablebasedonInquiryLabel = uilabel(app.ReportGrid);
            app.ReportTablebasedonInquiryLabel.Text = 'Report Table based on Inquiry';
            app.CuDDRe = uidropdown(app.ReportGrid);
            app.CuDDRe.Items = {'Select'};
            app.CuDDRe.Value = 'Select';
            app.OpenCIButton_2 = uibutton(app.ReportGrid, 'push');
            app.OpenCIButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenCIButton_2Pushed, true);
            app.OpenCIButton_2.Text = 'Open CI';
            app.TotalCIValueLabel = uilabel(app.ReportGrid);
            app.TotalCIValueLabel.Text = 'Total CI Value:';
            app.ReportT = uitable(app.ReportGrid);
            app.ReportT.ColumnName = {'Customer'; 'Tag'; 'State'; 'Total PI'; 'Total CI'; 'CI #'; 'InqID'; 'I-Date'; 'PIID'; 'PI-Date'; 'A-Date'; 'Leadtime'};
            app.ReportT.RowName = {};
            app.ReportT.ColumnSortable = true;
            app.Delete_InquiryTab = uitab(app.TabGroup);
            app.Delete_InquiryTab.Title = 'Delete Inquiry';
            app.Delete_InquiryTab.ButtonDownFcn = createCallbackFcn(app, @Delete_InquiryTabButtonDown, true);
            app.DeleteINq = uigridlayout(app.Delete_InquiryTab);
            app.NotifDel = uieditfield(app.DeleteINq, 'text');
            app.NotifDel.Editable = 'off';
            app.InquiryTable_2 = uitable(app.DeleteINq);
            app.InquiryTable_2.ColumnName = {'ID'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InquiryTable_2.RowName = {};
            app.InquiryTable_2.ColumnSortable = true;
            app.DeleteButton = uibutton(app.DeleteINq, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.NameTagEditField_2Label = uilabel(app.DeleteINq);
            app.NameTagEditField_2Label.Text = 'Name/Tag';
            app.NameTagDel = uieditfield(app.DeleteINq, 'text');
            app.IDEditFieldLabel = uilabel(app.DeleteINq);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDDel = uieditfield(app.DeleteINq, 'text');
            app.FinancialTab = uitab(app.TabGroup);
            app.FinancialTab.Title = 'Financial';
            app.FinancialTab.ButtonDownFcn = createCallbackFcn(app, @FinancialTabButtonDown, true);
            app.GridLayout23 = uigridlayout(app.FinancialTab);
            app.TabGroup3 = uitabgroup(app.GridLayout23);
            app.Financial_PaymentTab = uitab(app.TabGroup3);
            app.Financial_PaymentTab.Title = 'Customer Payment';
            app.Financial_PaymentTab.ButtonDownFcn = createCallbackFcn(app, @Financial_PaymentTabButtonDown, true);
            app.GridLayout5 = uigridlayout(app.Financial_PaymentTab);
            app.PaymentT = uitable(app.GridLayout5);
            app.PaymentT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PaymentT.RowName = {};
            app.AttachFin = uieditfield(app.GridLayout5, 'text');
            app.CustomerDropDownLabel_2 = uilabel(app.GridLayout5);
            app.CustomerDropDownLabel_2.Text = 'Customer';
            app.CustomerDD = uidropdown(app.GridLayout5);
            app.CustomerDD.Items = {'Select'};
            app.CustomerDD.Value = 'Select';
            app.CurrencyDropDownLabel_2 = uilabel(app.GridLayout5);
            app.CurrencyDropDownLabel_2.Text = 'Currency';
            app.CurrencyDD = uidropdown(app.GridLayout5);
            app.CurrencyDD.Items = {'Select'};
            app.CurrencyDD.Value = 'Select';
            app.DateDatePicker_2Label = uilabel(app.GridLayout5);
            app.DateDatePicker_2Label.Text = 'Date';
            app.DateFin = uidatepicker(app.GridLayout5);
            app.ValueEditField_2Label = uilabel(app.GridLayout5);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueFin = uieditfield(app.GridLayout5, 'numeric');
            app.ValueFin.ValueDisplayFormat = '%5.2f';
            app.NoteEditField_2Label = uilabel(app.GridLayout5);
            app.NoteEditField_2Label.Text = 'Note';
            app.NoteFin = uieditfield(app.GridLayout5, 'text');
            app.Excel_Fin = uibutton(app.GridLayout5, 'push');
            app.Excel_Fin.ButtonPushedFcn = createCallbackFcn(app, @Excel_FinButtonPushed, true);
            app.Excel_Fin.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Excel_Fin.Text = '';
            app.AttachButtonFin = uibutton(app.GridLayout5, 'push');
            app.AttachButtonFin.ButtonPushedFcn = createCallbackFcn(app, @AttachButtonFinPushed, true);
            app.AttachButtonFin.Text = 'Attach File';
            app.AddButton = uibutton(app.GridLayout5, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.Notif_Fin = uieditfield(app.GridLayout5, 'text');
            app.Notif_Fin.Editable = 'off';
            app.CustomerPaymentTableLabel = uilabel(app.GridLayout5);
            app.CustomerPaymentTableLabel.Text = 'Customer Payment Table';
            app.Financial_ReportTab = uitab(app.TabGroup3);
            app.Financial_ReportTab.Title = 'Report';
            app.Financial_ReportTab.ButtonDownFcn = createCallbackFcn(app, @Financial_ReportTabButtonDown, true);
            app.GridLayout5_2 = uigridlayout(app.Financial_ReportTab);
            app.Notif_FinR = uieditfield(app.GridLayout5_2, 'text');
            app.Notif_FinR.Editable = 'off';
            app.CustomerDropDownLabel_3 = uilabel(app.GridLayout5_2);
            app.CustomerDropDownLabel_3.Text = 'Customer';
            app.CustomerDDFinR = uidropdown(app.GridLayout5_2);
            app.CustomerDDFinR.Items = {'Select'};
            app.CustomerDDFinR.Value = 'Select';
            app.USDT = uitable(app.GridLayout5_2);
            app.USDT.ColumnName = {'#'; 'Customer'; 'Date'; 'Note'; 'Deposite'; 'Withdraw'; 'Balance'};
            app.USDT.RowName = {};
            app.USDLabel = uilabel(app.GridLayout5_2);
            app.USDLabel.Text = 'USD';
            app.Excel_FinR = uibutton(app.GridLayout5_2, 'push');
            app.Excel_FinR.ButtonPushedFcn = createCallbackFcn(app, @Excel_FinRButtonPushed, true);
            app.Excel_FinR.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Excel_FinR.Text = '';
            app.FilterButton = uibutton(app.GridLayout5_2, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EuroT = uitable(app.GridLayout5_2);
            app.EuroT.ColumnName = {'#'; 'Customer'; 'Date'; 'Note'; 'Deposite'; 'Withdraw'; 'Balance'};
            app.EuroT.RowName = {};
            app.EuroLabel = uilabel(app.GridLayout5_2);
            app.EuroLabel.Text = 'Euro';
            app.Excel_FinR_2 = uibutton(app.GridLayout5_2, 'push');
            app.Excel_FinR_2.ButtonPushedFcn = createCallbackFcn(app, @Excel_FinR_2ButtonPushed, true);
            app.Excel_FinR_2.Icon = fullfile(pathToMLAPP, 'PNG', 'Excel.png');
            app.Excel_FinR_2.Text = '';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SetupTab.ButtonDownFcn = createCallbackFcn(app, @SetupTabButtonDown2, true);
            app.GridLayout24 = uigridlayout(app.SetupTab);
            app.TabGroup2 = uitabgroup(app.GridLayout24);
            app.Setup_CustomerTab = uitab(app.TabGroup2);
            app.Setup_CustomerTab.Title = 'Customer';
            app.Setup_CustomerTab.ButtonDownFcn = createCallbackFcn(app, @Setup_CustomerTabButtonDown, true);
            app.GridLayout4 = uigridlayout(app.Setup_CustomerTab);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NameEditFieldLabel = uilabel(app.GridLayout4);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout4, 'text');
            app.AddressEditFieldLabel = uilabel(app.GridLayout4);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.GridLayout4, 'text');
            app.WebsiteEditFieldLabel = uilabel(app.GridLayout4);
            app.WebsiteEditFieldLabel.Text = 'Website';
            app.WebsiteEditField = uieditfield(app.GridLayout4, 'text');
            app.TelEditFieldLabel = uilabel(app.GridLayout4);
            app.TelEditFieldLabel.Text = 'Tel';
            app.TelEditField = uieditfield(app.GridLayout4, 'text');
            app.AcManagerDropDownLabel = uilabel(app.GridLayout4);
            app.AcManagerDropDownLabel.Text = 'Ac. Manager';
            app.AcManagerDropDown = uidropdown(app.GridLayout4);
            app.CorresponderEditFieldLabel = uilabel(app.GridLayout4);
            app.CorresponderEditFieldLabel.Text = 'Corresponder';
            app.CorresponderEditField = uieditfield(app.GridLayout4, 'text');
            app.CorresponderCellPhoneEditFieldLabel = uilabel(app.GridLayout4);
            app.CorresponderCellPhoneEditFieldLabel.Text = {'Corresponder'; 'Cell Phone'};
            app.CorresponderCellPhoneEditField = uieditfield(app.GridLayout4, 'text');
            app.AttachmentEditField = uieditfield(app.GridLayout4, 'text');
            app.AttachmentEditFieldLabel = uilabel(app.GridLayout4);
            app.AttachmentEditFieldLabel.Text = 'Attachment';
            app.AddnewCustomerButton = uibutton(app.GridLayout4, 'push');
            app.AddnewCustomerButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewCustomerButton_2Pushed, true);
            app.AddnewCustomerButton.Text = 'Add new Customer';
            app.EditField = uieditfield(app.GridLayout4, 'text');
            app.EditField.Editable = 'off';
            app.SelectFileButton = uibutton(app.GridLayout4, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_8Pushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.Setup_OperatorTab = uitab(app.TabGroup2);
            app.Setup_OperatorTab.Title = 'Operator';
            app.Setup_OperatorTab.ButtonDownFcn = createCallbackFcn(app, @Setup_OperatorTabButtonDown, true);
            app.GridLayout4_2 = uigridlayout(app.Setup_OperatorTab);
            app.UITable_2 = uitable(app.GridLayout4_2);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.NameEditField_2Label = uilabel(app.GridLayout4_2);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.GridLayout4_2, 'text');
            app.NameEditField_2.Tooltip = {'Username'};
            app.CellPhoneEditFieldLabel = uilabel(app.GridLayout4_2);
            app.CellPhoneEditFieldLabel.Text = 'Cell Phone';
            app.CellPhoneEditField = uieditfield(app.GridLayout4_2, 'text');
            app.AddnewOperatorButton = uibutton(app.GridLayout4_2, 'push');
            app.AddnewOperatorButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewOperatorButton_2Pushed, true);
            app.AddnewOperatorButton.Text = 'Add new Operator';
            app.EditField_2 = uieditfield(app.GridLayout4_2, 'text');
            app.EditField_2.Editable = 'off';
            app.UserdefaultpasswordusernameLabel = uilabel(app.GridLayout4_2);
            app.UserdefaultpasswordusernameLabel.Text = 'User default password = username';
            app.UserDropDownLabel = uilabel(app.GridLayout4_2);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.GridLayout4_2);
            app.UserDropDown.Items = {'Select'};
            app.UserDropDown.Value = 'Select';
            app.NewPasswordEditFieldLabel = uilabel(app.GridLayout4_2);
            app.NewPasswordEditFieldLabel.Text = 'New Password';
            app.NewPasswordEditField = uieditfield(app.GridLayout4_2, 'text');
            app.ChangePasswordButton = uibutton(app.GridLayout4_2, 'push');
            app.ChangePasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePasswordButtonPushed, true);
            app.ChangePasswordButton.Text = 'Change Password';
            app.Setup_CompanyTab = uitab(app.TabGroup2);
            app.Setup_CompanyTab.Title = 'Company';
            app.Setup_CompanyTab.ButtonDownFcn = createCallbackFcn(app, @Setup_CompanyTabButtonDown, true);
            app.GridLayout4_3 = uigridlayout(app.Setup_CompanyTab);
            app.UITable_3 = uitable(app.GridLayout4_3);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.NameEditField_3Label = uilabel(app.GridLayout4_3);
            app.NameEditField_3Label.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.GridLayout4_3, 'text');
            app.AttachmentEditField_2 = uieditfield(app.GridLayout4_3, 'text');
            app.AttachmentEditField_2Label = uilabel(app.GridLayout4_3);
            app.AttachmentEditField_2Label.Text = 'Attachment';
            app.AddnewCompanyButton = uibutton(app.GridLayout4_3, 'push');
            app.AddnewCompanyButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewCompanyButton_2Pushed, true);
            app.AddnewCompanyButton.Text = 'Add new Company';
            app.EditField_3 = uieditfield(app.GridLayout4_3, 'text');
            app.EditField_3.Editable = 'off';
            app.AddressEditField_2Label = uilabel(app.GridLayout4_3);
            app.AddressEditField_2Label.Text = 'Address';
            app.AddressEditField_2 = uieditfield(app.GridLayout4_3, 'text');
            app.TelEditField_2Label = uilabel(app.GridLayout4_3);
            app.TelEditField_2Label.Text = 'Tel';
            app.TelEditField_2 = uieditfield(app.GridLayout4_3, 'text');
            app.WebsiteEditField_2Label = uilabel(app.GridLayout4_3);
            app.WebsiteEditField_2Label.Text = 'Website';
            app.WebsiteEditField_2 = uieditfield(app.GridLayout4_3, 'text');
            app.SelectFileButton_2 = uibutton(app.GridLayout4_3, 'push');
            app.SelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_10Pushed, true);
            app.SelectFileButton_2.Text = 'Select File';
            app.Setup_SupplierTab = uitab(app.TabGroup2);
            app.Setup_SupplierTab.Title = 'Supplier';
            app.Setup_SupplierTab.ButtonDownFcn = createCallbackFcn(app, @Setup_SupplierTabButtonDown, true);
            app.GridLayout4_4 = uigridlayout(app.Setup_SupplierTab);
            app.UItableSupplier = uitable(app.GridLayout4_4);
            app.UItableSupplier.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UItableSupplier.RowName = {};
            app.NameEditField_4Label = uilabel(app.GridLayout4_4);
            app.NameEditField_4Label.Text = 'Name';
            app.NameEditField_4 = uieditfield(app.GridLayout4_4, 'text');
            app.AttachmentEditField_3 = uieditfield(app.GridLayout4_4, 'text');
            app.AttachmentEditField_3Label = uilabel(app.GridLayout4_4);
            app.AttachmentEditField_3Label.Text = 'Attachment';
            app.AddnewSupplierButton = uibutton(app.GridLayout4_4, 'push');
            app.AddnewSupplierButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewSupplierButton_2Pushed, true);
            app.AddnewSupplierButton.Text = 'Add new Supplier';
            app.EditField_4 = uieditfield(app.GridLayout4_4, 'text');
            app.EditField_4.Editable = 'off';
            app.AddressEditField_3Label = uilabel(app.GridLayout4_4);
            app.AddressEditField_3Label.Text = 'Address';
            app.AddressEditField_3 = uieditfield(app.GridLayout4_4, 'text');
            app.TelEditField_3Label = uilabel(app.GridLayout4_4);
            app.TelEditField_3Label.Text = 'Tel';
            app.TelEditField_3 = uieditfield(app.GridLayout4_4, 'text');
            app.WebsiteEditField_3Label = uilabel(app.GridLayout4_4);
            app.WebsiteEditField_3Label.Text = 'Website';
            app.WebsiteEditField_3 = uieditfield(app.GridLayout4_4, 'text');
            app.SelectFileButton_3 = uibutton(app.GridLayout4_4, 'push');
            app.SelectFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_11Pushed, true);
            app.SelectFileButton_3.Text = 'Select File';
            app.Setup_Access_ControlTab = uitab(app.TabGroup2);
            app.Setup_Access_ControlTab.Title = 'Access Control';
            app.Setup_Access_ControlTab.ButtonDownFcn = createCallbackFcn(app, @Setup_Access_ControlTabButtonDown, true);
            app.GridLayout7 = uigridlayout(app.Setup_Access_ControlTab);
            app.ACCTable = uitable(app.GridLayout7);
            app.ACCTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ACCTable.RowName = {};
            app.ACCTable.ColumnEditable = true;
            app.SaveButton_2 = uibutton(app.GridLayout7, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.NotifLabel = uilabel(app.GridLayout7);
            app.NotifLabel.Text = 'Notif.';
            app.Setup_User_SettingTab = uitab(app.TabGroup2);
            app.Setup_User_SettingTab.Title = 'User Setting';
            app.Setup_User_SettingTab.ButtonDownFcn = createCallbackFcn(app, @Setup_User_SettingTabButtonDown, true);
            app.GridLayout25 = uigridlayout(app.Setup_User_SettingTab);
            app.Panel = uipanel(app.GridLayout25);
            app.GridLayout12 = uigridlayout(app.Panel);
            app.ChangeButton = uibutton(app.GridLayout12, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '';
            app.CurrentPasswordEditFieldLabel = uilabel(app.GridLayout12);
            app.CurrentPasswordEditFieldLabel.Text = 'Current Password';
            app.CurrentPasswordEditField = uieditfield(app.GridLayout12, 'text');
            app.NewPasswordEditField_2Label = uilabel(app.GridLayout12);
            app.NewPasswordEditField_2Label.Text = 'New Password';
            app.NewPasswordEditField_2 = uieditfield(app.GridLayout12, 'text');
            app.VisibleCheckBox_2 = uicheckbox(app.GridLayout12);
            app.VisibleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VisibleCheckBox_2ValueChanged, true);
            app.VisibleCheckBox_2.Text = 'Visible';
            app.Setup_OtherTab = uitab(app.TabGroup2);
            app.Setup_OtherTab.Title = 'Other';
            app.Setup_OtherTab.ButtonDownFcn = createCallbackFcn(app, @Setup_OtherTabButtonDown, true);
            app.OtherGrid = uigridlayout(app.Setup_OtherTab);
            app.OtherNotif = uieditfield(app.OtherGrid, 'text');
            app.OtherNotif.Editable = 'off';
            app.PTtable = uitable(app.OtherGrid);
            app.PTtable.ColumnName = {'Payment Terms'};
            app.PTtable.RowName = {};
            app.Ctable = uitable(app.OtherGrid);
            app.Ctable.ColumnName = {'Currency'};
            app.Ctable.RowName = {};
            app.NewPT = uieditfield(app.OtherGrid, 'text');
            app.NewCurrency = uieditfield(app.OtherGrid, 'text');
            app.AddNewPayT = uibutton(app.OtherGrid, 'push');
            app.AddNewPayT.ButtonPushedFcn = createCallbackFcn(app, @AddNewPayT_2ButtonPushed, true);
            app.AddNewPayT.Text = 'Add New';
            app.AddNewCurr = uibutton(app.OtherGrid, 'push');
            app.AddNewCurr.ButtonPushedFcn = createCallbackFcn(app, @AddNewCurr_2ButtonPushed, true);
            app.AddNewCurr.Text = 'Add New';
            app.GridLayout18 = uigridlayout(app.GridLayout);
            app.NewYorkLabel = uilabel(app.GridLayout18);
            app.NewYorkLabel.Text = 'New York';
            app.LondonLabel = uilabel(app.GridLayout18);
            app.LondonLabel.Text = 'London';
            app.BerlinLabel = uilabel(app.GridLayout18);
            app.BerlinLabel.Text = 'Berlin';
            app.IstanbulLabel = uilabel(app.GridLayout18);
            app.IstanbulLabel.Text = 'Istanbul';
            app.DubaiLabel = uilabel(app.GridLayout18);
            app.DubaiLabel.Text = 'Dubai';
            app.BeijingLabel = uilabel(app.GridLayout18);
            app.BeijingLabel.Text = 'Beijing';
            app.CaliforniaLabel = uilabel(app.GridLayout18);
            app.CaliforniaLabel.Text = 'California';
            app.CATimeLabel = uilabel(app.GridLayout18);
            app.CATimeLabel.Text = 'CA Time';
            app.NYTimeLabel = uilabel(app.GridLayout18);
            app.NYTimeLabel.Text = 'NY Time';
            app.LTimeLabel = uilabel(app.GridLayout18);
            app.LTimeLabel.Text = 'L Time';
            app.BTimeLabel = uilabel(app.GridLayout18);
            app.BTimeLabel.Text = 'B Time';
            app.ITimeLabel = uilabel(app.GridLayout18);
            app.ITimeLabel.Text = 'I Time';
            app.DTimeLabel = uilabel(app.GridLayout18);
            app.DTimeLabel.Text = 'D Time';
            app.ChTimeLabel = uilabel(app.GridLayout18);
            app.ChTimeLabel.Text = 'Ch Time';
            app.VIRAEPCLabel = uilabel(app.GridLayout);
            app.VIRAEPCLabel.Text = 'VIRA E.P.C.';
            app.GridLayout17 = uigridlayout(app.GridLayout);
            app.UserLabel = uilabel(app.GridLayout17);
            app.UserLabel.Text = 'User';
            app.Switch = uiswitch(app.GridLayout17, 'slider');
            app.Switch.Items = {'Log out', 'Log in'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Log out';
            app.ViraEPCLoginPanel = uipanel(app.ViraEPCUIFigure);
            app.ViraEPCLoginPanel.Title = 'Vira E.P.C. Log in Panel';
            app.GridLayout11 = uigridlayout(app.ViraEPCLoginPanel);
            app.UsernameEditField_2Label = uilabel(app.GridLayout11);
            app.UsernameEditField_2Label.Text = 'Username';
            app.UsernameEditField_2 = uieditfield(app.GridLayout11, 'text');
            app.PasswordEditField = uieditfield(app.GridLayout11, 'text');
            app.VisibleCheckBox = uicheckbox(app.GridLayout11);
            app.VisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisibleCheckBoxValueChanged, true);
            app.VisibleCheckBox.Text = 'Visible';
            app.Button_4 = uibutton(app.GridLayout11, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'PNG', 'login.png');
            app.Button_4.Text = '';
            app.NoticeLabel = uilabel(app.GridLayout11);
            app.NoticeLabel.Text = 'Notice';
            app.NewPassEditField = uieditfield(app.GridLayout11, 'text');
            app.NewPassEditField.ValueChangingFcn = createCallbackFcn(app, @NewPassEditFieldValueChanging, true);
            app.PasswordLabel = uilabel(app.GridLayout11);
            app.PasswordLabel.Text = 'Password';
            app.NewPassLabel = uilabel(app.GridLayout11);
            app.NewPassLabel.Text = 'New Pass.';
            app.Button_5 = uibutton(app.GridLayout11, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'PNG', 'login.png');
            app.Button_5.Text = '';
            % Show the figure after all components are created
            app.ViraEPCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViraApp_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViraEPCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViraEPCUIFigure)
        end
    end
end